classdef ResponseFamilies < matlab.apps.AppBase

  % Properties that correspond to app components
  properties (Access = public)
    container                       matlab.ui.Figure
    ImportMenu                      matlab.ui.container.Menu
    LoadDataMenu                    matlab.ui.container.Menu
    CalibrationMenu                 matlab.ui.container.Menu
    StimulusMenu                    matlab.ui.container.Menu
    NeutralDensityMenu              matlab.ui.container.Menu
    ExportMenu                      matlab.ui.container.Menu
    PlotsMenu                       matlab.ui.container.Menu
    DataMenu                        matlab.ui.container.Menu
    IrisDataMenu                    matlab.ui.container.Menu
    SessionMenu                     matlab.ui.container.Menu
    PackageMenu                     matlab.ui.container.Menu
    OtherMenu                       matlab.ui.container.Menu
    View                            matlab.ui.container.Menu
    PropertiesMenu                  matlab.ui.container.Menu
    NotesMenu                       matlab.ui.container.Menu
    FileInfoMenu                    matlab.ui.container.Menu
    DatumsMenu                      matlab.ui.container.Menu
    ResetMenu                       matlab.ui.container.Menu
    EditMenu                        matlab.ui.container.Menu
    PreferencesMenu                 matlab.ui.container.Menu
    PeakDetectionMenu               matlab.ui.container.Menu
    ForceDrawMenu                   matlab.ui.container.Menu
    GridLayout                      matlab.ui.container.GridLayout
    ControlPanel                    matlab.ui.container.Panel
    ControlTabs                     matlab.ui.container.TabGroup
    AggregatesTab                   matlab.ui.container.Tab
    AggregatesGrids                 matlab.ui.container.GridLayout
    GroupBySelect                   matlab.ui.control.ListBox
    GroupFilter                     matlab.ui.control.EditField
    BaselineCheckBox                matlab.ui.control.CheckBox
    PtsLabel                        matlab.ui.control.Label
    PtsEditField                    matlab.ui.control.NumericEditField
    OffsetLabel                     matlab.ui.control.Label
    OffsetEditField                 matlab.ui.control.NumericEditField
    BaselineTypeLabel               matlab.ui.control.Label
    BaselineTypeDropDown            matlab.ui.control.DropDown
    FilterCheckBox                  matlab.ui.control.CheckBox
    FilterOrderLabel                matlab.ui.control.Label
    FilterOrder                     matlab.ui.control.NumericEditField
    FilterTypeDropDownLabel         matlab.ui.control.Label
    FilterTypeDropDown              matlab.ui.control.DropDown
    HzLabel                         matlab.ui.control.Label
    FilterFrequencies               matlab.ui.control.EditField
    StatisticDropDownLabel          matlab.ui.control.Label
    StatisticDropDown               matlab.ui.control.DropDown
    StatisticsFunctionString        matlab.ui.control.EditField
    ScaleEditFieldLabel             matlab.ui.control.Label
    ScaleEditField                  matlab.ui.control.NumericEditField
    DeviceDropDown                  matlab.ui.control.DropDown
    BaselineFirstCheckBox           matlab.ui.control.CheckBox
    BaselineRefDropDown             matlab.ui.control.DropDown
    RefTraceLabel                   matlab.ui.control.Label
    GroupsButton                    matlab.ui.control.Button
    CalibrationTab                  matlab.ui.container.Tab
    CalibrationGrids                matlab.ui.container.GridLayout
    StimPropLabel                   matlab.ui.control.Label
    StimPropDropDown                matlab.ui.control.DropDown
    BackgroundPropertyLabel         matlab.ui.control.Label
    BGPropDropDown                  matlab.ui.control.DropDown
    StimulusCalibrationTable        matlab.ui.control.Table
    StimTableLabel                  matlab.ui.control.Label
    StimCalIntensityDropDown        matlab.ui.control.DropDown
    StimCalConversionDropDown       matlab.ui.control.DropDown
    CellTypeLabel                   matlab.ui.control.Label
    CellTypeDropDown                matlab.ui.control.DropDown
    SpectralTemplateLabel           matlab.ui.control.Label
    SpectralTemplateDropDown        matlab.ui.control.DropDown
    SpectralTemplateTable           matlab.ui.control.Table
    NDTab                           matlab.ui.container.Tab
    NDLayoutGrids                   matlab.ui.container.GridLayout
    ExtraStimulusODEditFieldLabel   matlab.ui.control.Label
    ExtraStimulusODEditField        matlab.ui.control.NumericEditField
    NdCalibrationTable              matlab.ui.control.Table
    NdCalIdentityDropDown           matlab.ui.control.DropDown
    NdCalConversionDropDown         matlab.ui.control.DropDown
    GetNDValueFromPropertyCheckBox  matlab.ui.control.CheckBox
    StimNDPropertyDropDownLabel     matlab.ui.control.Label
    StimNDPropertyDropDown          matlab.ui.control.DropDown
    StimulusNDsTextAreaLabel        matlab.ui.control.Label
    StimulusNDsTextArea             matlab.ui.control.TextArea
    BGNDsTextAreaLabel              matlab.ui.control.Label
    BGNDsTextArea                   matlab.ui.control.TextArea
    BGNDPropertyDropDownLabel       matlab.ui.control.Label
    BGNDPropertyDropDown            matlab.ui.control.DropDown
    ExtraBackgroundODEditFieldLabel  matlab.ui.control.Label
    ExtraBackgroundODEditField      matlab.ui.control.NumericEditField
    CommonPathODEditFieldLabel      matlab.ui.control.Label
    CommonPathODEditField           matlab.ui.control.NumericEditField
    PathNDPropertiesLabel           matlab.ui.control.Label
    PathNDPropertiesListBox         matlab.ui.control.ListBox
    FitTab                          matlab.ui.container.Tab
    FitGrids                        matlab.ui.container.GridLayout
    EquationbxLabel                 matlab.ui.control.Label
    FitEquation                     matlab.ui.control.EditField
    ParameterEstimatesLabel         matlab.ui.control.Label
    EstimateTable                   matlab.ui.control.Table
    FitCoefficientsLabel            matlab.ui.control.Label
    ParameterCalculation            matlab.ui.control.Table
    UpdateFitButton                 matlab.ui.control.Button
    TimeDropDownLabel               matlab.ui.control.Label
    FitStimulusTimeIdDropDown       matlab.ui.control.DropDown
    AmplitudeDropDownLabel          matlab.ui.control.Label
    FitStimulusAmplitudeDropDown    matlab.ui.control.DropDown
    RebinSpinner                    matlab.ui.control.Spinner
    RebinCheckBox                   matlab.ui.control.CheckBox
    MethodDropDownLabel             matlab.ui.control.Label
    MethodDropDown                  matlab.ui.control.DropDown
    NResponsesLabel                 matlab.ui.control.Label
    FitNormalizedCheckBox           matlab.ui.control.CheckBox
    IntensityTypeDropDownLabel      matlab.ui.control.Label
    IntensityTypeDropDown           matlab.ui.control.DropDown
    EditGroupsButton                matlab.ui.control.Button
    DisplayTab                      matlab.ui.container.Tab
    DisplayGrids                    matlab.ui.container.GridLayout
    DataAxesButton                  matlab.ui.control.Button
    FitAxesButton                   matlab.ui.control.Button
    AdjustWindowPanel               matlab.ui.container.Panel
    AdjustWindowGrids               matlab.ui.container.GridLayout
    AnalysisWindowStartSliderLabel  matlab.ui.control.Label
    AnalysisWindowStartSlider       matlab.ui.control.Slider
    AnalysisWindowEndSliderLabel    matlab.ui.control.Label
    AnalysisWindowEndSlider         matlab.ui.control.Slider
    AxesLabelsPanel                 matlab.ui.container.Panel
    AxesLabelsLayout                matlab.ui.container.GridLayout
    DataAxisPanel                   matlab.ui.container.Panel
    DataAxisGrids                   matlab.ui.container.GridLayout
    DataXStringLabel                matlab.ui.control.Label
    DataXString                     matlab.ui.control.EditField
    DataYStringLabel                matlab.ui.control.Label
    DataYString                     matlab.ui.control.EditField
    FitAxisPanel                    matlab.ui.container.Panel
    FitAxisGrids                    matlab.ui.container.GridLayout
    FitXStringLabel                 matlab.ui.control.Label
    FitXString                      matlab.ui.control.EditField
    FitYStringLabel                 matlab.ui.control.Label
    FitYString                      matlab.ui.control.EditField
    StimulusPropertiesPanel         matlab.ui.container.Panel
    StimPropLayoutGrid              matlab.ui.container.GridLayout
    StimulusStartPropertyLabel      matlab.ui.control.Label
    StimPropStartAfterDropDown      matlab.ui.control.DropDown
    secLabel                        matlab.ui.control.Label
    StimPropStartAfterEditField     matlab.ui.control.NumericEditField
    DirectionDropDownLabel          matlab.ui.control.Label
    StimPropDirectionDropDown       matlab.ui.control.DropDown
    DisplayPanel                    matlab.ui.container.Panel
    DisplayTabs                     matlab.ui.container.TabGroup
    ViewData                        matlab.ui.container.Tab
    ViewResponseFamily              matlab.ui.container.Tab
    IRAxes                          matlab.ui.control.UIAxes
    CurrentFileNameMenu             matlab.ui.container.Menu
  end

  % Properties that correspond to apps with auto-reflow
  properties (Access = private)
    onePanelWidth = 576;
  end

  properties (Access = public)
    
  end
  
  properties (Access = private)
    DataAxes % using graphics axes rather than uiaxes... it just looks better
    data IrisData % IrisData, set during startup
    ledCalibration table
    ndCalibration table
    intensityResponseData struct
    requiresFitUpdate = true
    InputRoot
    OutputRoot = ''
    dataFile
    window
    analysisBoxLeft
    analysisBoxRight
    stimArrowPatch
    propFigure
    noteFigure
    infoFigure
    SMOOTH_SPAN = 0.01
    PEAK_THREASHOLD = 0.85
    PEAK_RANGE_WIDTH = 10
    COMPUTE_CI = true
    SHOW_DATUMS = true
    PHOTON_DENSITY_UNITS = sprintf('(photons \x03BCm^{-2} sec^{-1})')
    Settings
  end
  
  properties (Dependent = true)
    hasWindow % determine if web window exists
    hasData % Determine if data is set
    hasLedCalibration % Determines if calibration is available for stimulus
    hasNdCalibration % Determines if calibration is available for ND
    hasIRData % determines if intensity response data has been computed
    collectingArea % Get the collecting area depending on the cell type
    wavelength % Get the wavelength of the current stimulus
    absorptionTemplate % get the template function based on the lmax
    ndMap
    selectedDevice
    lightDeviceNames
    timeID
    amplitudeID
    fitEstimates
    fitEqn
    totalOD
    stimulusCalibrationCurve
    groupIds
    baselineParams
    scaleParams
    aggParams
    filterParams
    xUnitsData
    yUnitsData
    xUnitsFit
    yUnitsFit
    AnalysisWindow
  end
  
  methods
    
    function tf = get.hasWindow(app)
      tf = ~isempty(app.window);
    end
    
    function tf = get.hasData(app)
      tf = ~isempty(app.data);
    end
    
    function tf = get.hasLedCalibration(app)
      tf = ~isempty(app.ledCalibration);
    end
    
    function tf = get.hasNdCalibration(app)
      tf = ~isempty(app.ndCalibration);
    end
    
    function tf = get.hasIRData(app)
      tf = false;
      if ~app.hasData, return; end
      if ~app.hasLedCalibration, return; end
      tf = ~isempty(fieldnames(app.intensityResponseData));
    end
    
    function tf = isNdProp(app,name)
      if ~app.hasNdCalibration
        tf = false;
        return;
      end
      if ~isstring(name), name = string(name); end
      tf = false(numel(name),1);
      for n = 1:numel(name)
        tf(n) = ismember(name(n),app.ndCalibration.(app.NdCalIdentityDropDown.Value));
      end
    end
    
    function ca = get.collectingArea(app)
      ca = str2double(app.SpectralTemplateTable.Data{2,2});
    end
    
    function L = get.wavelength(app)
      % wavelength property returns array nDatums x 2 where column 1 is stimulus
      % and column 2 is background
      if ~app.hasData, return; end
      specsTable = app.data.Specs.Table;
      propnames = specsTable.Properties.VariableNames;
      % start with stimulus, then choose background
      stimID = app.StimPropDropDown.Value;
      stimLabels = matlab.lang.makeValidName( ...
        strcat({'stimulus_'},specsTable.(stimID),{'_Wavelength'}) ...
        );
      if ~ismember(stimLabels,propnames)
        stimLabels = regexprep(specsTable.(stimID),'[^0-9]','');
      end
      
      %bg
      bgID = app.BGPropDropDown.Value;
      if strcmpi(bgID,'none')
        bgLabels = utilities.rep({'0'},height(specsTable));
      else
        bgLabels = matlab.lang.makeValidName( ...
          strcat({'device_'},specsTable.(bgID),{'_Wavelength'}) ...
          );
        if ~ismember(bgLabels,propnames)
          bgLabels = regexprep(specsTable.(bgID),'[^0-9]','');
        end
      end
      
      
      % for now we will assume every datum may have a different wavelength
      L = zeros(app.data.nDatums,2);
      for d = 1:app.data.nDatums
        thisStim = stimLabels{d};
        thisBG = bgLabels{d};
        try
          L(d,1) = str2double(specsTable.(thisStim)(d));
        catch
          L(d,1) = str2double(thisStim);
        end
        try
          L(d,2) = str2double(specsTable.(thisBG)(d));
        catch
          L(d,2) = str2double(thisBG);
        end
        
      end
      
    end
    
    function map = get.ndMap(app)
      % map the stimulus and background nd to measured values based on
      % wavelength
      % the resulting map will have 2 keys, 'stimulus', and 'background', each
      % of thse will be a map that has keys for wavelength (numeric). The
      % values of the inner maps will be the sum total of any associated NDs
      % Measure ID
      map = containers.Map();
      if ~app.hasNdCalibration, return; end
      map('stimulus') = app.createWavelengthMapFromTextArea(app.StimulusNDsTextArea);
      map('background') = app.createWavelengthMapFromTextArea(app.BGNDsTextArea);
      
    end
    
    function dev = get.selectedDevice(app)
      if ~app.hasData, return; end
      dev = app.DeviceDropDown.Value;
    end
    
    function devs = get.lightDeviceNames(app)
      %LIGHTDEVICES returns a string array of device names for each datum [stim,bg]
      if ~app.hasData, return; end
      stimProp = app.StimPropDropDown.Value;
      bgProp = app.BGPropDropDown.Value;
      specs = app.data.Specs.Table;
      
      if strcmp(bgProp,'none')
        bgString = utilities.rep("",height(specs));
      else
        bgString = string(specs.(bgProp));
      end
      
      devs = [string(specs.(stimProp)),bgString];
    end
    
    function id = get.timeID(app)
      if ~app.hasData, return; end
      id = app.FitStimulusTimeIdDropDown.Value;
    end
    
    function id = get.amplitudeID(app)
      if ~app.hasData, return; end
      id = app.FitStimulusAmplitudeDropDown.Value;
    end
    
    function eTab = get.fitEstimates(app)
      eTab = app.EstimateTable.Data;
      eTab = sortrows(eTab,'Param');
    end
    
    function eqn = get.fitEqn(app)
      eqn = str2func(['@(b,x)',app.FitEquation.Value]);
    end
    
    function datumODs = get.totalOD(app)
      % optical density attenuation is calculated from the sum of all the
      % optical densities. These become the negative exponent of base 10, which
      % will be used in creating the calibration curve. These also depend on
      % wavelength, etc. Here, we calculate the total OD for each the stimulus
      % and the background, assuming that we have these two on at one time.
      % Should a third value be needed, this approach will likely not work.
      if ~app.hasData, return; end
      % spec table for gathering from the property values
      specs = app.data.Specs;
      fromProp = app.GetNDValueFromPropertyCheckBox.Value;
      stimPropVal = app.StimNDPropertyDropDown.Value;
      bgPropVal = app.BGNDPropertyDropDown.Value;
      
      % nd map, if present
      map = app.ndMap;
      if ~isempty(map)
        stimMap = app.ndMap('stimulus');
        bgMap = app.ndMap('background');
      else
        stimMap = containers.Map();
        bgMap = containers.Map();
      end
      
      
      datumLambdas = app.wavelength;
      datumODs = zeros(size(datumLambdas));
      for d = 1:size(datumLambdas,1)
        thisL = datumLambdas(d,:);
        thisPropCell = specs.Datums{d};
        % for each datum, collect from:
        %   ndMap (if ~isempty)
        if stimMap.isKey(thisL(1))
          datumODs(d,1) = datumODs(d,1)+stimMap(thisL(1));
        end
        if bgMap.isKey(thisL(2))
          datumODs(d,2) = datumODs(d,2)+bgMap(thisL(2));
        end
        %   StimulusNDPropertyDropDown (if GetNDValueFromPropertyCheckBox)
        if fromProp
          if ~strcmpi(stimPropVal,'none')
            stimPropEntry = thisPropCell{ismember(thisPropCell(:,1),stimPropVal),2};
            if ~isnumeric(stimPropEntry)
              % determine if it is a label in the ndcal
              stimPropEntry = regexprep(stimPropEntry,'[^\.\w,]','');
              if all(app.isNdProp(strsplit(stimPropEntry,',')))
                stimPropEntry = strsplit(stimPropEntry,',');
                stimPropEntry = app.getNdPropEntry(thisL(1), stimPropEntry);
              else
                % assume the value is not an entry in the nd table
                % but is a value, like 'totalND' => '4.3,5.0' or
                % 'device_LED405nm_ExtraND' => '["4.0","1.2"]'
                % then want to remove any characters that are not
                % numbers, periods or commas:
                strRepr = regexprep(stimPropEntry,'[^\.0-9,]','');
                % convert to num array
                numRepr = eval(['[',strRepr,']']);
                if ~isnumeric(numRepr)
                  % check if is a nd propname
                  areNDProps = app.isNdProp(numRepr);
                  if any(areNDProps)
                    numRepr = app.getNdPropEntry(thisL(1),numRepr(areNDProps));
                  else
                    % try to convert to a number anyway
                    numRepr = str2num(numRepr);%#ok
                  end
                end
                % sum the collective
                stimPropEntry = sum(numRepr);
              end
            end
            % sum the values into the datumsOD
            datumODs(d,1) = sum([datumODs(d,1);stimPropEntry(:)]);
          end
          if ~strcmpi(bgPropVal,'none')
            bgPropEntry = thisPropCell{ismember(thisPropCell(:,1),bgPropVal),2};
            if ~isnumeric(bgPropEntry)
              % determine if it is a label in the ndcal
              bgPropEntry = regexprep(bgPropEntry,'[^\.\w,]','');
              if app.isNdProp(bgPropEntry)
                bgPropEntry = app.ndCalibration.(app.NdCalConversionDropDown.Value)( ...
                  app.ndCalibration.Wavelength==thisL(1) & ...
                  ismember(app.ndCalibration.(app.NdCalIdentityDropDown.Value),bgPropEntry) ...
                  );
              else
                % assume the value is not an entry in the nd table
                % but is a value, like 'totalND' => '4.3,5.0' or
                % 'device_LED405nm_ExtraND' => '["4.0","1.2"]'
                % then want to remove any characters that are not
                % numbers, periods or commas:
                strRepr = regexprep(bgPropEntry,'[^\.0-9,]','');
                % convert to num array
                numRepr = eval(['[',strRepr,']']);
                if ~isnumeric(numRepr)
                  % check if is a nd propname
                  areNDProps = app.isNdProp(numRepr);
                  if any(areNDProps)
                    numRepr = app.getNdPropEntry(thisL(1),numRepr(areNDProps));
                  else
                    % try to convert to a number anyway
                    numRepr = str2num(numRepr);%#ok
                  end
                end
                % sum the collective
                bgPropEntry = sum(numRepr);
              end
            end
            datumODs(d,2) = sum([datumODs(d,2);bgPropEntry(:)]);
          end
        end
        % If any selections in common path ND device selection box
        
        %   ExtraODAttenuationEditField
        datumODs(d,1) = datumODs(d,1) + ...
            app.ExtraStimulusODEditField.Value + ...
            app.CommonPathODEditField.Value;
        datumODs(d,2) = datumODs(d,2) + ...
            app.ExtraBackgroundODEditField.Value + ...
            app.CommonPathODEditField.Value;
      end
      
    end
    
    function calFcnMap = get.stimulusCalibrationCurve(app)
      %STIMULUSCALIBRATIONCURVE returns map with wavelengths as keys and
      %functions as values. Function will have the form @(x,t,OD) where t is
      %the stimulus duration and OD is optical density. Here, collecting are
      %and lambda attenuation are already factored in.
      if ~app.hasLedCalibration, return; end
      lambdas = unique(app.ledCalibration.Wavelength);
      calFcnMap = containers.Map(lambdas,repmat({@(x)x},numel(lambdas),1));
      absSpect = app.absorptionTemplate;
      cArea = app.collectingArea;
      for lm = lambdas'
        lmFact  = absSpect(lm);
        X = app.ledCalibration.(app.StimCalIntensityDropDown.Value)(app.ledCalibration.Wavelength == lm);
        % we expect that y is photons s^-1 um^-2
        y = app.ledCalibration.(app.StimCalConversionDropDown.Value)(app.ledCalibration.Wavelength == lm);
        b1 = X\y; %so b1x+0 = y
        switch app.IntensityTypeDropDown.Value
          case 'Isomerizations'
            lmFcnStr = sprintf('@(x,t,OD)x.*(%.4f*%.3f*%.4f).*t.*10^(-3).*10.^(-OD)',b1,cArea,lmFact(2));
          case 'Photon Density'
            lmFcnStr = sprintf('@(x,t,OD)x.*%.4f.*10.^(-OD)',b1);
        end
        calFcnMap(lm) = str2func(lmFcnStr);
      end
    end
    
    function idList = get.groupIds(app)
      selectedInds = find(ismember(app.GroupBySelect.ItemsData,app.GroupBySelect.Value));
      idList = app.GroupBySelect.Items(selectedInds); %#ok
    end
    
    
    function tmp = get.absorptionTemplate(app)
      import iris.modules.*;
      paramTable = app.SpectralTemplateTable.Data;
      lmax = str2num(paramTable{1,2}); %#ok hard coded for now?
      templateFx = ResponseFamilies.templateFactory(lmax);
      % generates a template function that accepts stimulus wavelengths sL
      tmp = @(sL) templateFx(sL);
    end
    
    function params = get.baselineParams(app)
      
      if app.BaselineCheckBox.Value
        if contains(app.BaselineTypeDropDown.Value,'Fit')
          regionString = char(regexp(app.BaselineTypeDropDown.Value, '(?<=\()\w+(?=\))','match','once'));
        else
          regionString = app.BaselineTypeDropDown.Value;
        end
      else
        regionString = 'None';
      end
      if strcmp(app.BaselineRefDropDown.Value,'Separate')
        refDatum = 0;
      else
        refDatum = str2double(regexprep(app.BaselineRefDropDown.Value,'[^0-9]',''));
      end
      params = { ...
        'baselineRegion', regionString, ...
        'numBaselinePoints', app.PtsEditField.Value, ...
        'baselineOffsetPoints', app.OffsetEditField.Value, ...
        'noFitWarning', true, ...
        'baselineReference', refDatum ...
        };
    end
    
    function params = get.scaleParams(app)
      params = { ...
        'scaleFactor', app.ScaleEditField.Value ...
        };
    end
    
    function params = get.aggParams(app)
      params = { ...
        'groupBy', app.groupIds, ...
        'devices', app.selectedDevice, ...
        'statistic', str2func(app.StatisticsFunctionString.Value), ...
        'baselineFirst', app.BaselineFirstCheckBox.Value ...
        };
    end
    
    function params = get.filterParams(app)
      params = { ...
        'type', lower(app.FilterTypeDropDown.Value), ...
        'frequencies', str2num(app.FilterFrequencies.Value), ...
        'order', app.FilterOrder.Value, ...
        'devices', app.selectedDevice ...
        };%#ok
    end
    
    function str = get.yUnitsData(app)
      str = '';
      if ~app.hasData, return; end
      deviceIdx = ismember(app.data.AvailableDevices,app.selectedDevice);
      d = app.data.Data;
      units = app.data.uniqueContents(d(1).units(deviceIdx));
      if ~isscalar(units)
        str = ['(',strjoin({units.y},' | '),')'];
      else
        str = ['(',units.y,')'];
      end
    end
    
    function str = get.xUnitsData(app)
      str = '';
      if ~app.hasData, return; end
      deviceIdx = ismember(app.data.AvailableDevices,app.selectedDevice);
      d = app.data.Data;
      units = app.data.uniqueContents(d(1).units(deviceIdx));
      if ~isscalar(units)
        str = ['(',strjoin({units.x},' | '),')'];
      else
        str = ['(',units.x,')'];
      end
    end
    
    function str = get.yUnitsFit(app)
      if app.FitNormalizedCheckBox.Value
        str = '(norm)';
      else
        if strcmp(app.MethodDropDown.Value,'Dot Product')
          str = sprintf('(%s^2)',regexprep(app.yUnitsData,'[\(\)]*',''));
        else
          str = app.yUnitsData;
        end
      end
    end
    
    function str = get.xUnitsFit(app)
      if strcmpi(app.IntensityTypeDropDown.Value,'Photon Density')
        str = app.PHOTON_DENSITY_UNITS;
        return
      end
      switch app.CellTypeDropDown.Value
        case 'Mouse Rod'
          str= '(R^*rod^{-1})';
        case 'Mouse Cone'
          str = '(P^*cone^{-1})';
        otherwise
          if app.collectingArea == 1
            str = sprintf('(photons \x03BCm^{-2})');
          else
            str = '(P^*cell^{-1})';
          end
      end
    end
    
    function range = get.AnalysisWindow(app)
      if ~app.hasData,return;end
      xRange = app.DataAxes.XLim;
      xStart = app.AnalysisWindowStartSlider.Value/100 * diff(xRange)+xRange(1);
      xEnd = app.AnalysisWindowEndSlider.Value/100 * diff(xRange)+xRange(1);
      range = [xStart,xEnd];
    end
    
    function val = getNdPropEntry(app,lambda,name)
      if ~app.hasNdCalibration, return; end
      if ~isstring(name), name = string(name); end
      val = zeros(numel(name),1);
      for n = 1:numel(name)
        val(n) = app.ndCalibration.(app.NdCalConversionDropDown.Value)( ...
          app.ndCalibration.Wavelength==lambda & ...
          ismember(app.ndCalibration.(app.NdCalIdentityDropDown.Value),name(n)) ...
          );
      end
    end
    
    function setStimCalibration(app,calibrationTable)
      app.ledCalibration = calibrationTable;
      if isempty(calibrationTable)
        app.StimulusCalibrationTable.Data = [];
        status = 'off';
      else
        app.StimulusCalibrationTable.Data = app.ledCalibration;
        app.StimulusCalibrationTable.ColumnName = app.ledCalibration.Properties.VariableNames;
        status = 'on';
      end
      
      % enable associated props
      app.StimulusCalibrationTable.Enable = status;
      app.StimCalIntensityDropDown.Enable = status;
      app.StimCalConversionDropDown.Enable = status;
      
      if strcmp(status,'on')
        app.StimCalIntensityDropDown.Items = app.StimulusCalibrationTable.ColumnName;
        app.StimCalIntensityDropDown.Value = app.StimCalIntensityDropDown.Items(2);
        app.StimCalConversionDropDown.Items = app.StimulusCalibrationTable.ColumnName;
        app.StimCalConversionDropDown.Value = app.StimCalConversionDropDown.Items(3);
      else
        app.StimCalIntensityDropDown.Items = {'Intensity'};
        app.StimCalIntensityDropDown.Value = 'Intensity';
        app.StimCalConversionDropDown.Items = {'Calibration'};
        app.StimCalConversionDropDown.Value = 'Calibration';
      end
      app.requiresFitUpdate = true;
    end
    
    function setNDCalibration(app,calibrationTable)
      app.ndCalibration = calibrationTable;
      if isempty(calibrationTable)
        app.NdCalibrationTable.Data = [];
        status = 'off';
      else
        app.NdCalibrationTable.Data = app.ndCalibration;
        app.NdCalibrationTable.ColumnName = app.ndCalibration.Properties.VariableNames;
        status = 'on';
      end
      
      % enable the ND options
      app.NdCalibrationTable.Enable = status;
      app.NdCalIdentityDropDown.Enable = status;
      app.NdCalConversionDropDown.Enable = status;
      app.StimulusNDsTextArea.Enable = status;
      app.BGNDsTextArea.Enable = status;
      app.StimulusNDsTextAreaLabel.Enable = status;
      app.BGNDsTextAreaLabel.Enable = status;
      % fill in best guesses
      if strcmp(status,'on')
        app.NdCalIdentityDropDown.Items = app.NdCalibrationTable.ColumnName;
        app.NdCalIdentityDropDown.Value = app.NdCalIdentityDropDown.Items(3);
        app.NdCalConversionDropDown.Items = app.NdCalibrationTable.ColumnName;
        app.NdCalConversionDropDown.Value = app.NdCalConversionDropDown.Items(2);
      else
        app.NdCalIdentityDropDown.Items = {'ND Identity'};
        app.NdCalIdentityDropDown.Value = 'ND Identity';
        app.NdCalConversionDropDown.Items = {'ND Values'};
        app.NdCalConversionDropDown.Value = 'ND Values';
      end
      
      app.requiresFitUpdate = true;
    end
    
    function setData(app,Data)
      if nargin < 2, error("Data must be a file or an IrisData object"); end
      if isempty(Data)
        app.ImportData([]); %import will reenter here to parse
        return
      else
        if ~isa(Data,'IrisData')
          try
            S = load(Data,'-mat');
            app.dataFile = Data;
            Data = S.iData;
          catch
            warning('Could not load data file.');
            app.ImportData([]);
          end
        else
          app.dataFile = Data.Files{1};
        end
        app.data = Data;
        app.parse();
        pause(0.01);
      end
    end
    
  end
  
  methods (Access = private)
    
    function parse(app)
      % parse the loaded data file
      specs = app.data.Specs;
      propNames = sort(specs.Table.Properties.VariableNames);
      % remove prop filter
      app.GroupFilter.Value = '';
      % set aggregation properties/options
      app.GroupBySelect.Items = [{'none'},propNames];
      app.GroupBySelect.ItemsData = [{'none'},propNames];
      app.GroupBySelect.Value = {'none'};
      
      app.BaselineRefDropDown.Value = {'Separate'};
      
      app.DeviceDropDown.Items = app.data.AvailableDevices;
      
      app.StimPropDropDown.Items = propNames;
      hasLed1 = ismember(lower(propNames),'led1');
      if any(hasLed1)
        app.StimPropDropDown.Value = propNames{hasLed1};
      else
        hasLed = ismember(lower(propNames),'led');
        if any(hasLed)
          app.StimPropDropDown.Value = propNames{hasLed};
        end
      end
      
      app.BGPropDropDown.Items = [{'none'},propNames];
      hasLed2 = ismember(lower(propNames),'led2');
      if any(hasLed2)
        app.BGPropDropDown.Value = propNames(hasLed2);
      end
      % parameterize the default equation
      app.parameterizeFitEqn();
      
      % fill in the items for other prop selectors
      app.FitStimulusTimeIdDropDown.Items = propNames;
      app.FitStimulusAmplitudeDropDown.Items = propNames;
      app.StimNDPropertyDropDown.Items = [{'none'},propNames];
      app.BGNDPropertyDropDown.Items = [{'none'},propNames];
      app.StimPropStartAfterDropDown.Items = [{'none','custom'},propNames];
      
      if ~ismember(app.PathNDPropertiesListBox.Value,[{'none'},propNames])
          app.PathNDPropertiesListBox.Value = {'none'};
      end
      app.PathNDPropertiesListBox.Items = [{'none'},propNames];
      
      app.requiresFitUpdate = true;
      
      % update the file name
      if isempty(app.dataFile)
        [app.InputRoot,app.CurrentFileNameMenu.Text] = fileparts(app.data.Files(1));
      else
        [app.InputRoot,app.CurrentFileNameMenu.Text] = fileparts(app.dataFile);
      end
      app.CurrentFileNameMenu.Visible = 'on';
      
      app.DisplayTabs.SelectedTab = app.ViewData;
      app.AnalysisWindowStartSlider.Value = 0;
      app.AnalysisWindowEndSlider.Value = 100;
      
      % load cached settings from previous session
      app.loadSettings();
      
      % plot the data
      app.plotData();
      
      % finally, activate the aggregates tab because this is the first
      % step in the analysis process
      pause(0.01);%flush
      app.ControlTabs.SelectedTab = app.AggregatesTab;
    end
    
    function parameterizeFitEqn(app)
      eqStr = app.FitEquation.Value;
      % find parameters:
      paramList = unique(regexp(regexprep(eqStr,'[()]',''),'b\d+','match'));
      % populate the parameter table
      existingTable = app.EstimateTable.Data;
      paramTable = array2table(strings(numel(paramList),3),'VariableNames',["Param","Name","Estimate"]);
      paramTable.Param = paramList(:);
      paramTable.Estimate = ones(numel(paramList),1);
      if ~isempty(existingTable)
        % filter out anything not in paramList and
        % merge the existing names and estimates into the new parameter table
        if height(paramTable) <= height(existingTable)
          % shrinking
          paramTable = join( ...
            existingTable(ismember(existingTable.Param,paramList),:), ...
            paramTable, ...
            'Keys','Param', ...
            'keepOneCopy', {'Name','Estimate'} ...
            );
        else
          % growing, we only want to merge existing values
          paramTable(ismember(paramTable.Param,existingTable.Param),:) = join( ...
            existingTable, ...
            paramTable(ismember(paramTable.Param,existingTable.Param),:), ...
            'Keys','Param', ...
            'keepOneCopy', {'Name','Estimate'} ...
            );
        end
        
      end
      % assign parameter label to empty Name columns
      emptyNames = strcmpi(paramTable.Name,"");
      if any(emptyNames)
        paramTable.Name(emptyNames) = paramTable.Param(emptyNames);
      end
      app.EstimateTable.Data = paramTable;
      % populate the fit results table
      calcTable = table(paramTable.Param, nan(numel(paramList),1,"double"),'variablenames',["Param","Result"]);
      app.ParameterCalculation.Data = calcTable;
    end
    
    function plotData(app,force)
      if nargin < 2, force = false; end
      % don't plot if not visible
      selectedTab = app.DisplayTabs.SelectedTab;
      if ~isequal(selectedTab,app.ViewData)
        if ~force, return; end
        app.DisplayTabs.SelectedTab = app.ViewData;
        pause(0.05);%50ms
      end
      % set the baseline reference based on the first/last checkbox
      app.updateBaselineRefs();
      
      % delete the lines
      delete(findall(app.DataAxes,'Type', 'Line'));
      
      pltD = app.data;
      if app.FilterCheckBox.Value
        pltD = pltD.Filter(app.filterParams{:});
      end
      % baselien and scale
      if app.BaselineFirstCheckBox.Value
        blParams = app.baselineParams;
      else
        blParams = {};
      end
      % note that the IrisData.plot() method sets x limits
      % plot the datums in a very transparent grey
      pltD.plot( ...
        'axes', app.DataAxes, ...
        'colorized', false, ...
        'opacity', 0.2, ...
        'interactive',false, ...
        'axesLabels',false, ...
        'devices', app.DeviceDropDown.Value, ...
        app.scaleParams{:}, ...
        blParams{:} ...
        );
      % plot the aggregates
      aggs = pltD.Aggregate( ...
        app.aggParams{:}, ...
        app.baselineParams{:}, ...
        app.scaleParams{:} ...
        );
      aggs.plot( ...
        'axes', app.DataAxes, ...
        'colorized', true, ...
        'opacity',0.95, ...
        'interactive',false, ...
        'axesLabels',false, ...
        'computeXLimitBy','Axes', ...
        'computeYLimitBy', 'Axes' ...
        );
      
      % update axes labels
      xString = app.DataXString.Value;
      yString = app.DataYString.Value;
      
      app.DataAxes.XLabel.String = [xString,' ',app.xUnitsData];
      app.DataAxes.YLabel.String = [yString,' ',app.yUnitsData];
      
      % set the value for the rebinning spinner
      app.RebinSpinner.Value = aggs.nDatums;
      
      % update the sliders
      app.updateWindowLines([]);
      % update the stimulus arrow
      app.drawStimulusArrow();
    end
    
    function updateBaselineRefs(app)
      v = app.BaselineRefDropDown.Value;
      if app.BaselineFirstCheckBox.Value
        items = [{'Separate'},sprintfc('Datum %d',1:app.data.nDatums)];
        if ~ismember(v,items)
          v = {'Separate'};
        end
      else
        g = app.data.getGroupBy(app.groupIds);
        nAggs = height(g.Table);
        items = [{'Separate'},sprintfc('Aggregate %d',1:nAggs)];
        if ~ismember(v,items)
          v = {'Separate'};
        end
      end
      app.BaselineRefDropDown.Value = v;
      app.BaselineRefDropDown.Items = items;
    end
    
    function drawStimulusArrow(app)
      doUpdate = ~(isempty(app.stimArrowPatch) || ~app.stimArrowPatch.isvalid());
      arrowAtX = app.StimPropStartAfterEditField.Value;
      if ~arrowAtX
        if doUpdate
          delete(app.stimArrowPatch);
        end
        return
      end
      
      % define arrow height as a percent of current axes limits
      width_p = 0.01; % 1 percent
      width_d = width_p * diff(app.DataAxes.XLim);
      
      % we expect to draw the arrow away from the response direction so
      % rougly within 3% of the boundary
      height_p = 0.0325; % 3.25 percent
      v_offset_p = 0.015; % 1.5 percent
      height_d = height_p * diff(app.DataAxes.YLim);
      v_offset_d = v_offset_p * diff(app.DataAxes.YLim);
      y_mid_d = mean(app.DataAxes.YLim);
      
      % determine Y location by finding the ydomain of the region
      % comprised of width_d
      xSearch = width_d./[-2,2] + arrowAtX;
      yDom = nan(1,2);
      yMean = nan(1);
      for ch = 1:numel(app.DataAxes.Children)
        if isa(app.DataAxes.Children(ch),'matlab.graphics.primitive.Patch')
          continue
        end
        try %#ok<TRYNC>
          thisX = app.DataAxes.Children(ch).XData;
          inds = thisX >= xSearch(1) & thisX <= xSearch(2);
          thisY = app.DataAxes.Children(ch).YData(inds);
          yDom = app.data.domain([yDom(:);thisY(:)]);
          yMean = nanmean([yMean;thisY(:)]);
        end
      end
      switch app.StimPropDirectionDropDown.Value
        case 'Up'
          isArrowUp = true;
        case 'Down'
          isArrowUp = false;
        otherwise
          isArrowUp = yMean >= y_mid_d;
      end
      
      arrowDir = 2*(isArrowUp)-1; % + is up, - is down
      if isArrowUp
        yStart = yDom(2);
      else
        yStart = yDom(1);
      end
      % build counter clockwise from the point closest to the data
      arrowAtY = yStart + v_offset_d * arrowDir;
      verts = [ ...
        arrowAtX, arrowAtY; ...
        xSearch(1), arrowAtY + height_d * arrowDir; ...
        xSearch(2), arrowAtY + height_d * arrowDir ...
        ];
      if doUpdate
        app.stimArrowPatch.Vertices = verts;
      else
        app.stimArrowPatch = patch( ...
          app.DataAxes, ...
          'XData',verts(:,1), 'YData', verts(:,2), 'ZData', 1:3, ...
          'FaceColor', [0 0 0], ...
          'EdgeColor', [0 0 0], ...
          'FaceAlpha', 1 ...
          );
      end
    end
    
    function tryPlotFits(app)
      
      % don't plot if not visible
      selectedTab = app.DisplayTabs.SelectedTab;
      if isequal(selectedTab,app.ViewData), return; end
      if ~app.hasIRData, return; end
      if app.requiresFitUpdate, return; end
      delete(app.IRAxes.Children);
      % plot from the IRData
      [x,sIdx] = sort(app.intensityResponseData.Intensities);
      if app.FitNormalizedCheckBox.Value
        y = app.intensityResponseData.Responses.Normalized(sIdx,:);
      else
        y = app.intensityResponseData.Responses.Dots(sIdx,:);
      end
      if app.COMPUTE_CI
        flX = x([1:end,end:-1:1]);
        flY = [y(:,2);y(end:-1:1,3)];
        hf = fill( ...
          app.IRAxes, ...
          flX, ...
          flY, ...
          [50,151,219]./255, ...
          'facealpha',0.25, ...
          'EdgeAlpha',0 ...
          ); %#ok
      end
      
      % plot the fit
      fxn = str2func(app.intensityResponseData.FitEquation);
      b = app.intensityResponseData.FitParameters.Estimate;
      xDom = quantile(x,[0,1]);
      
      if strcmpi(app.IRAxes.XScale,'log')
        intX = logspace(log10(xDom(1)),log10(xDom(2)),200);
      else
        intX = linspace(xDom(1),xDom(2),200);
      end
      
      intY = fxn(b,intX);
      fl = line( ...
        app.IRAxes, ...
        'XData',intX, ...
        'YData',intY, ...
        'linewidth',3, ...
        'linestyle', '-', ...
        'color',[232,76,61]./255 ...
        ); %#ok
      % plot the markers on top
      hl = line( ...
        app.IRAxes, ...
        'xdata',x, ...
        'ydata',y(:,1), ...
        'linestyle','none', ...
        'marker','.', ...
        'markersize',25, ...
        'markerfacecolor', [6,6,6]./255, ...
        'markeredgecolor', [6,6,6]./255 ...
        );%#ok
      nBins = app.RebinSpinner.Value;
      if nBins < numel(x)
        bins = discretize(log10(x),nBins);
        binNames = unique(bins,'stable');
        
        [newX,newY] = deal( zeros(nBins,1) );
        for i = 1:nBins
          b = binNames(i);
          locs = bins == b;
          thisX = mean(x(locs));
          thisY = mean(y(locs,1));
          % store
          newX(b) = thisX;
          newY(b) = thisY;
        end
        rbL = line( ...
          app.IRAxes, ...
          'xdata',newX, ...
          'ydata',newY, ...
          'linestyle','none', ...
          'marker','.', ...
          'markersize',35, ...
          'markerfacecolor', [167, 194, 98]./255, ...
          'markeredgecolor', [167, 194, 98]./255 ...
          );%#ok
      elseif nBins > numel(x)
        app.RebinSpinner.Value = numel(x);
      end
      
      % update axes labels
      xString = app.FitXString.Value;
      yString = app.FitYString.Value;
      
      app.IRAxes.XLabel.String = [xString,' ',app.xUnitsFit];
      app.IRAxes.YLabel.String = [yString,' ',app.yUnitsFit];
      % colors for later
      %carmine pink: (232,76,61)
      %tufts blue: (50,151,219)
      %brightgrey: (236,240,241)
      %cola: 56, 49, 39
      %middle green yellow: (167, 194, 98)
      %floral white: (251, 250, 241)
      %Rajah: (248, 185, 96)
      %Carmine: (161, 4, 18)
    end
    
    function computeIntensityResponse(app)
      % init
      import iris.modules.ResponseFamilies;
      cal = app.stimulusCalibrationCurve;
      odArray = app.totalOD;
      wv = app.wavelength;
      
      % get the data matrix
      dataMatrix = app.data.Filter(  ...
        'devices' ,app.selectedDevice, ...
        app.filterParams{:} ...
        ).Scale( ...
        app.scaleParams{:} ...
        ).Baseline(  ...
        'devices' ,app.selectedDevice, ...
        app.baselineParams{:} ...
        ).getDataMatrix( ...
        'devices' ,app.selectedDevice ...
        );
      specs = app.data.Specs;
      % get grouping information for groupBy
      groups = app.data.determineGroups(specs.Table(:,app.groupIds),app.data.InclusionList);
      
      groupingTable = specs.Table(app.data.InclusionList,:);
      
      ledNames = app.lightDeviceNames;
      
      xRange = app.AnalysisWindow;
      
      switch app.MethodDropDown.Value
        case 'Dot Product'
          % calculate template for dots
          % excludes excluded epochs
          dotTemplate = nanmean(dataMatrix.y{1},2);
          dotTemplate(isnan(dotTemplate)) = 0;
          % compute dot products for all epochs
          dotFx = @(x)x.*dotTemplate';
          dotsObj= app.data.Filter( ...
            app.filterParams{:} ...
            ).Scale( ...
            app.scaleParams{:} ...
            ).Aggregate( ...
            'groupby','none', ...
            app.baselineParams{:}, ...
            'statistic',dotFx ...
            );
          % compute averaged dot products (with 95% ci)
          dotsMat = dotsObj.getDataMatrix('device',app.selectedDevice);
          xForRange = dotsMat.x{1}(:,find(~any(isnan(dotsMat.x{1})),1,'first'));
          inds = (xForRange >= xRange(1)) & (xForRange <= xRange(2));
          
          % sum the multiplications to produce dot-products
          dots = abs(sum(dotsMat.y{1}(inds,:),1,'omitnan'));
        case 'Peak Detection'
          % collect the filtered,baselined data, do not aggregate.
          dataMat = app.data.Filter( ...
            app.filterParams{:} ...
            ).Scale( ...
            app.scaleParams{:} ...
            ).Baseline( ...
            app.baselineParams{:} ...
            ).getDataMatrix( ...
            'device', app.selectedDevice, ...
            'respectInclusion', false ...
            );
          xForRange = dataMat.x{1}(:,find(~any(isnan(dataMat.x{1})),1,'first'));
          inds = (xForRange >= xRange(1)) & (xForRange <= xRange(2));
          
          N = app.data.nDatums;
          inclusions = app.data.InclusionList;
          % create variable dots that hold the peak values for each
          dots = nan(1,app.data.nDatums);
          for n = 1:N
            if ~inclusions(n), continue; end
            thisFs = str2double(specs.Table.sampleRate{n});% in Hz
            thisY = dataMat.y{1}(inds,n);
            if app.SMOOTH_SPAN > 0
              smoothWindow = min([ceil(thisFs * app.SMOOTH_SPAN),floor(sum(inds)/2)]);
              thisY = smooth(xForRange(inds),thisY,smoothWindow,'rloess');
            end
            maxVal = utilities.AbsMax(thisY);
            [~,loc] = findpeaks( ...
              abs(thisY), ...
              'MinPeakHeight', abs(maxVal) * app.PEAK_THREASHOLD, ...
              'NPeaks', 1 ...
              );
            % compute average around the peak;
            nShift = ceil(app.PEAK_RANGE_WIDTH/2);
            avgRange = loc + (-nShift:nShift);
            
            % make sure we don't exceed boundaries
            avgRange(avgRange < 1) = 1;
            avgRange(avgRange > length(thisY)) = length(thisY);
            avgRange = unique(avgRange);
            
            % collect the middle value of the range
            dots(n) = nanmean(thisY(avgRange,:));
          end
          dots(~inclusions) = [];
      end
      
      
      ints = zeros(numel(dots),1);
      
      groupsDots = zeros(height(groups.Table),3); % mean, 2.5, 97.5
      groupsIntensities = zeros(height(groups.Table),1);
      groupsBackgrounds = zeros(height(groups.Table),1);
      groupWarns = false(height(groups.Table),1);
      groupStat = str2func(app.StatisticsFunctionString.Value);
      for g = 1:height(groups.Table)
        thisG = groups.Table.SingularMap(g);
        thisGroupIdx = groups.Singular == thisG;
        thisGroupLocs = find(thisGroupIdx);
        thisN = sum(thisGroupIdx);
        % use the selected aggregate method from app.StatisticDropDown
        %thisDotBar = mean(dots(thisGroupIdx));
        thisDot = dots(thisGroupIdx);
        thisDot = thisDot(~isnan(thisDot));
        thisDot = thisDot(:);
        
        thisDotBar = groupStat(thisDot);
        if app.COMPUTE_CI
          thisDotCI = ResponseFamilies.bootstrapCI(thisDot,groupStat);
        else
          thisDotCI = thisDotBar(:,[1,1]);
        end
        groupsDots(g,1) = thisDotBar;
        groupsDots(g,2:3) = thisDotCI;
        % get time and amplitudes
        thisGTimes = groupingTable.(app.timeID)(thisGroupIdx);
        thisGTime = str2double(thisGTimes);
        if any(isnan(thisGTime))
          % thisGTimes might be something like '10,10,10,10,...'
          thisGTime = cellfun(@str2num,thisGTimes,'UniformOutput', false);
          if any(~cellfun(@isscalar,thisGTime))
            % is repeated array (*assumption)
            thisGTime = cellfun(@(t)t(thisG),thisGTime);
          else
            thisGTime = [thisGTime{:}];
          end
        end
        
        thisGAmps = str2double(groupingTable.(app.amplitudeID)(thisGroupIdx));
        thisLightName = ledNames(thisGroupIdx,:);
        thisGLam = wv(thisGroupIdx,:);
        thisGOD = odArray(thisGroupIdx,:);
        intensities = zeros(thisN,1); % e.g. R*/rod
        backgrounds = zeros(thisN,1); %e.g. R* rod^-1 s^-1
        warnInds = false(thisN,1);
        for n = 1:thisN
          thisCal = cal(thisGLam(n,1));
          intensities(n) = thisCal(thisGAmps(n),thisGTime(n),thisGOD(n,1));
          try
            thisBGCal = cal(thisGLam(n,2));
            % create the backgorund name
            thisBGName = matlab.lang.makeValidName(sprintf('device_%s_value',thisLightName(n,2)));
            bgVal = str2double(groupingTable.(thisBGName)(thisGroupLocs(n)));
            backgrounds(n) = thisBGCal(bgVal,1e3,thisGOD(n,2));
          catch
            warnInds(n) = true;
          end
        end
        groupWarns(g) = any(warnInds);
        groupsIntensities(g) = nanmean(intensities);
        groupsBackgrounds(g) = nanmean(backgrounds);
        intensities(isnan(intensities)) = nanmean(intensities);
        ints(thisGroupIdx) = intensities;
      end
      if any(groupWarns), fprintf(2,'Could not find background values for some datums.\n'); end
      % sort by intensities
      [groupsIntensities,sIdx] = sort(groupsIntensities);
      groupsBackgrounds = groupsBackgrounds(sIdx);
      groupsDots = groupsDots(sIdx,:);
      [ints,sIdx] = sort(ints);
      dots = dots(sIdx);
      
      % compute the normalization
      % we expect that the normalization should be between (0,1]
      normDots = abs(groupsDots/utilities.AbsMax(groupsDots(:,1)));
      
      if app.FitNormalizedCheckBox.Value
        fitDots = normDots(:,1);
      else
        fitDots = groupsDots(:,1);
      end
      % calculate fit:
      b0 = app.fitEstimates.Estimate;
      parTable = app.EstimateTable.Data;
      
      tbs = ver;
      tbs = {tbs.Name}';
      % prefer optimization toolbox
      if ismember('Optimization Toolbox', tbs)
        fitcoefs = lsqcurvefit( ...
          app.fitEqn, ...
          b0, ...
          groupsIntensities, ...
          fitDots, ...
          [],[], ... %leave lower/upper bounds empty
          optimoptions('lsqcurvefit','Display', 'off','MaxIterations',2000) ...
          );
      elseif ismember('Curve Fitting Toolbox', tbs)
        eqStr = regexprep(char(app.fitEqn),'@\(b,x\)', '');
        for en = 1:numel(parTable.Name)
          eqStr = regexprep(eqStr,sprintf('b\\(%d\\)',en),parTable.Name(en));
        end
        % remove .* or ./ or .^
        eqStr = regexprep(eqStr,'\.(?=[\^/\\\*])','');
        fitQ = fittype(eqStr);
        fitcoefs = fit( ...
          groupsIntensities, ...
          fitDots, ...
          fitQ, ...
          'startpoint', b0, ...
          'display', 'off', ...
          'maxiter', 2000 ...
          );
        fitcoefs = coeffvalues(fitcoefs);
      else
        error("Response Families module requires either 'optimization toolbox' or 'curve fitting toolbox'.");
      end
      
      % sometimes bad fits cause coefs to be complex.
      if ~isreal(fitcoefs)
        fitcoefs = real(fitcoefs);
      end
      
      calTable = app.ParameterCalculation.Data;
      calTable.Result = round(fitcoefs(:),3,'significant');
      app.ParameterCalculation.Data = calTable;
      % override parameter table
      parTable.Estimate = fitcoefs(:);
      
      Sir = struct();
      Sir.FitParameters = parTable;
      Sir.Intensities = groupsIntensities;
      Sir.Responses = struct();
      Sir.Responses.Dots = real(groupsDots);
      Sir.Responses.Normalized = real(normDots);
      Sir.FitEquation = char(app.fitEqn);
      Sir.BackgroundIntensities = groupsBackgrounds;
      Sir.Raw = struct('Intensity',ints,'Response',dots);
      
      app.intensityResponseData = Sir;
      % allow plotting
      app.requiresFitUpdate = false;
      app.tryPlotFits();
    end
    
    function map = createWavelengthMapFromTextArea(app,src)
      % nd table relationships
      measureVar = app.NdCalConversionDropDown.Value;
      idVar = app.NdCalIdentityDropDown.Value;
      calWavelengths = unique(app.ndCalibration.Wavelength);
      
      map = containers.Map(calWavelengths,zeros(numel(calWavelengths),1));
      % stimulus
      value = src.Value;
      splitValues = cellfun(@(elem)strtrim(strsplit(elem,'=>')),value,'UniformOutput',false);
      for i = 1:numel(splitValues)
        thisEntry = splitValues{i};
        thisID = thisEntry{1};
        % id locations
        idTest = ismember(app.ndCalibration.(idVar), thisID);
        % lambda locations
        if numel(thisEntry) > 1
          thisLambda = thisEntry{2}; % char array
          lambdas = str2double(strtrim(strsplit(thisLambda,',')));
        else
          lambdas = calWavelengths;
        end
        lambdaTest = ismember(app.ndCalibration.Wavelength,lambdas);
        % subset the table
        thisTable = app.ndCalibration(all([idTest(:),lambdaTest(:)],2),:);
        for h = 1:height(thisTable)
          map(thisTable.Wavelength(h)) = map(thisTable.Wavelength(h)) + thisTable.(measureVar)(h);
        end
      end
    end
    
  end
  
  methods (Static)
    
    function tFx = templateFactory(maxLambda)
      % generate a function handle to allow a wavelength array to be entered in
      % order to produce the absorption fraction of a a given Lambda_max.
      tFx = @(WL) templateFunction(WL,maxLambda);
      
      function template = templateFunction(L,lmax)
        A = 69.7;
        B = 28;
        b = 0.922;
        C = -14.9;
        c = 1.104;
        D = 0.674;
        
        OD = 1; % scale of max peak
        
        y = zeros(length(L),length(lmax));
        
        for i = 1:length(lmax)
          lambdamax = lmax(i);
          
          x = lambdamax./L;
          a = 0.8795+0.0459.*exp(-((lambdamax-300).^2)./11940);
          
          A_expr = exp(A.*(a-x));
          B_expr = exp(B.*(b-x));
          C_expr = exp(C.*(c-x));
          
          Salpha = OD./(A_expr+B_expr+C_expr+D);
          
          bbeta = -40.5+0.195.*lambdamax;
          Lmbeta = 189+0.315.*lambdamax;
          Abeta = 0.26.*OD;
          
          Sbeta = Abeta.*exp(-((L-Lmbeta)./bbeta).^2);
          
          y(:,i) = Salpha(:) + Sbeta(:);
        end
        resultMax = max(y,[],2);
        resultMax(resultMax > OD) = OD;
        resultMax(resultMax < 0) = 0;
        template = [L(:),resultMax(:)];
      end
    end
    
    function ci = bootstrapCI(data,stat,level,B)
      if nargin < 4, B=10000; end
      if nargin < 3, level = 0.95; end
      if nargin < 2, stat = 'mean'; end
      N = length(data);
      try
        samps = data(randi(N,N,B));
      catch
        error('Could not compute 95% CI, consider turning CI off.');
      end
      if ~isa(stat,'function_handle')
        stat = str2func(stat);
      end
      boots = stat(samps);
      probs = sort((1-level)/2 + [level,0]);
      ci = quantile(boots,probs);
    end
    
    function h = notifyUser(inputText,name)
      if nargin < 2, name = 'Notification'; end
      s0 = get(groot,'MonitorPositions');
      s0 = s0(s0(:,1) == 1,3:4);
      % start with a big figure and resize based on text
      dims = [800,800];
      dPos = [(s0-dims)./2,dims];
      % create the figure
      h = figure( ...
        'units','pixels', ...
        'position',dPos, ...
        'Visible','off', ...
        'Color', [1,1,1], ...
        'NumberTitle', 'off', ...
        'MenuBar', 'none', ...
        'Toolbar', 'none', ...
        'DockControls', 'off', ...
        'Name', name, ...
        'Interruptible', 'off', ...
        'CloseRequestFcn', @(s,e)fprintf('Please wait.\n'), ...
        'windowstyle','modal' ...
        );
      ax = axes(h, ...
        'Units','pixels', ...
        'Position',[0,0,dims], ...
        'Visible','off' ...
        );
      tx = text(ax, ...
        0.5,0.5, ...
        inputText, ...
        'FontName','Times New Roman', ...
        'FontWeight',"bold", ...
        'HorizontalAlignment',"center",...
        "VerticalAlignment","middle", ...
        "FontSize",16 ...
        );
      
      %determine the size of the text box
      tx.Units = 'pixels';
      pause(0.001);
      exPx = tx.Extent(3:4);
      % create new dims
      dims = round(exPx,0) + [180,60];
      dPos = [(s0-dims)./2,dims];
      %reposition the figure
      h.Position = dPos;
      % reposition the text
      txPos = [dims./2,0];
      tx.Position = txPos;
      % make the figure visible
      h.Visible = "on";
    end
    
    function doCopyUITableCell(source,event)
      try
          ids = event.Indices;
          nSelections = size(ids,1);
          merged = cell(nSelections,1);
          for sel = 1:nSelections
            merged{sel} = source.Data{ids(sel,1),ids(sel,2)};
          end
          stringified = utilities.unknownCell2Str(merged,';',false);
          clipboard('copy',stringified);
        catch x
          fprintf('Copy failed for reason:\n "%s"\n',x.message);
      end
    end
    
  end
  
  % Custom Callbacks
  methods (Access = private)
    
    function onWindowStartChanged(app,event)
      if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
        app.DisplayTabs.SelectedTab = app.ViewData;
      end
      xRange = app.DataAxes.XLim;
      xStart = event.Value/100 * diff(xRange)+xRange(1);
      app.analysisBoxLeft.XData(3:4) = xStart([1,1]);
    end
    
    function onWindowEndChanged(app,event)
      if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
        app.DisplayTabs.SelectedTab = app.ViewData;
      end
      xRange = app.DataAxes.XLim;
      xEnd = event.Value/100 * diff(xRange)+xRange(1);
      app.analysisBoxRight.XData(1:2) = xEnd([1,1]);
    end
    
    function updateWindowLines(app,~)
      if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
        app.DisplayTabs.SelectedTab = app.ViewData;
      end
      xRange = app.DataAxes.XLim;
      xStart = app.AnalysisWindowStartSlider.Value/100 * diff(xRange)+xRange(1);
      xEnd = app.AnalysisWindowEndSlider.Value/100 * diff(xRange)+xRange(1);
      yRange = app.DataAxes.YLim;
      
      app.analysisBoxLeft.XData = [xRange([1,1]),xStart([1,1])];
      app.analysisBoxLeft.YData = yRange([1,2,2,1]);
      app.analysisBoxRight.XData = [xEnd([1,1]),xRange([2,2])];
      app.analysisBoxRight.YData = yRange([1,2,2,1]);
      
      app.requiresFitUpdate = true;
    end
    
    function updateStimMarker(app,event)
      % if None or Custom, set the edit field to 0 and enable
      isCustom = strcmp(event.Value,{'none','custom'});
      if any(isCustom)
        app.StimPropStartAfterEditField.Value = 0;
        if isCustom(2)
          app.StimPropStartAfterEditField.Editable = 'on';
        else
          app.StimPropStartAfterEditField.Editable = 'off';
        end
        app.plotData();
        return
      end
      % not custom or none, so let's turn off editable mode.
      app.StimPropStartAfterEditField.Editable = 'off';
      % Collect the first value under the selected property
      specs = app.data.Specs;
      newVal = specs.Table.(event.Value){1};
      newVal = str2double(newVal);
      % if the selected value isn't a numeric, then reset the property and
      % exit
      if isnan(newVal)
        app.StimPropStartAfterDropDown.Value = 'none';
        app.StimPropStartAfterEditField.Value = 0;
        app.plotData();
        return;
      end
      
      % convert to seconds from ms
      newVal = newVal*1e-3;
      oldVal = app.StimPropStartAfterEditField.Value;
      % set the edit field
      try
        app.StimPropStartAfterEditField.Value = newVal;
      catch
        app.StimPropStartAfterEditField.Value = oldVal;
        return
      end
      app.plotData();
    end
    
    function updateStimMarkerValue(app,event)
      if ~app.hasData
        app.StimPropStartAfterEditField = event.PreviousValue;
        return
      end
      app.drawStimulusArrow();
    end
    
    function loadSettings(app)
      storedProps = [ ...
        "SMOOTH_SPAN", "PEAK_THREASHOLD", "PEAK_RANGE_WIDTH", ...
        "SHOW_DATUMS", "COMPUTE_CI", "PHOTON_DENSITY_UNITS" ...
        ];
      propDefaults = { 0.01, 0.85, 10, true, true, sprintf('(photons \x03BCm^{-2} sec^{-1})') };
      for p = 1:numel(storedProps)
        prop = storedProps(p);
        app.(prop) = app.getSetting(prop,propDefaults{p});
      end
      
      % aggregates tab
      app.BaselineCheckBox.Value = app.getSetting('doBaseline', true);
      app.PtsEditField.Value = app.getSetting('numBaselinePts', 1000);
      app.OffsetEditField.Value = app.getSetting('numOffsetPts',0);
      app.BaselineTypeDropDown.Value = app.getSetting('baselineType','Start');
      app.BaselineFirstCheckBox.Value = app.getSetting('baselineFirst',true);
      app.FilterCheckBox.Value = app.getSetting('doFilter', true);
      app.FilterFrequencies.Value = app.getSetting('filterFrequencies', '30');
      app.FilterOrder.Value = app.getSetting('filterOrder',4);
      app.FilterTypeDropDown.Value = app.getSetting('filterType', 'Lowpass');
      app.StatisticDropDown.Value = app.getSetting('statisticType', 'Mean');
      app.StatisticsFunctionString.Value = app.getSetting('statisticFcn','@(x)mean(x,1,''omitnan'')');
      app.StatisticDropDownValueChanged([]);
      % calibration tables
      app.setStimCalibration(app.getSetting('stimCalibration',table()));
      app.setNDCalibration(app.getSetting('ndCalibration', table()));
      % fit equation
      app.FitEquation.Value = app.getSetting('fitEquation', '1./(1+(b(1)./x).^b(2))');
      defaultPtable = table({'b1';'b2'},["k";"n"],ones(2,1),'VariableNames',["Param","Name","Estimate"]);
      app.EstimateTable.Data = app.getSetting('fitEstimateTable', defaultPtable);
      app.parameterizeFitEqn();
      % calibration tab selections
      app.CellTypeDropDown.Value = app.getSetting('cellType','Mouse Rod');
      app.SpectralTemplateDropDown.Value = app.getSetting('spectralTemplate','Rhodopsin');
      templateTableData = app.getSetting('spectralTemplateTableData', {'max λ','503';'Collecting Area','0.5'});
      app.SpectralTemplateTable.Data(:,2) = templateTableData(:,2);
      % update parameter table
      app.onSetCellType([]);
      app.onSpectralTemplateChanged([]);
      % fit method
      app.MethodDropDown.Value = app.getSetting('fitMethod','Dot Product');
      app.FitNormalizedCheckBox.Value = app.getSetting('fitNormalized',true);
      app.IntensityTypeDropDown.Value = app.getSetting('intensityType','Isomerizations');
      % labels strings
      app.DataXString.Value = app.getSetting('dataXString','X');
      app.DataYString.Value = app.getSetting('dataYString','Y');
      app.FitXString.Value = app.getSetting('fitXString','Intensity');
      app.FitYString.Value = app.getSetting('fitYString','Response');
      % time arrow value
      app.StimPropDirectionDropDown.Value = app.getSetting('arrowDirection','Auto');
      % previous figure position
      s0 = get(groot,'MonitorPositions');
      s0 = s0(s0(:,1) == 1,3:4);
      dims = [1002,510];
      drawnow();
      app.container.Position = app.getSetting('Position', [(s0-dims)./2,dims]);
      pause(0.1);
      app.updateAppLayout([]);
      
      
    end
    
    function saveSettings(app)
      storedProps = [ ...
        "SMOOTH_SPAN", "PEAK_THREASHOLD", "PEAK_RANGE_WIDTH", ...
        "SHOW_DATUMS", "COMPUTE_CI", "PHOTON_DENSITY_UNITS" ...
        ];
      for p = 1:numel(storedProps)
        prop = storedProps(p);
        app.Settings(prop) = app.(prop);
      end
      app.Settings('Position') = app.container.Position;
      app.Settings('doBaseline') = app.BaselineCheckBox.Value;
      app.Settings('numBaselinePts') = app.PtsEditField.Value;
      app.Settings('numOffsetPts') = app.OffsetEditField.Value;
      app.Settings('baselineType') = app.BaselineTypeDropDown.Value;
      app.Settings('baselineFirst') = app.BaselineFirstCheckBox.Value;
      app.Settings('doFilter') = app.FilterCheckBox.Value;
      app.Settings('filterFrequencies') = app.FilterFrequencies.Value;
      app.Settings('filterOrder') = app.FilterOrder.Value;
      app.Settings('filterType') = app.FilterTypeDropDown.Value;
      app.Settings('statisticType') = app.StatisticDropDown.Value;
      app.Settings('statisticFcn') = app.StatisticsFunctionString.Value;
      app.Settings('stimCalibration') = app.ledCalibration;
      app.Settings('ndCalibration') = app.ndCalibration;
      app.Settings('fitEquation') = app.FitEquation.Value;
      app.Settings('cellType') = app.CellTypeDropDown.Value;
      app.Settings('spectralTemplate') = app.SpectralTemplateDropDown.Value;
      app.Settings('spectralTemplateTableData') = app.SpectralTemplateTable.Data;
      app.Settings('fitEstimateTable') = app.EstimateTable.Data;
      app.Settings('fitMethod') = app.MethodDropDown.Value;
      app.Settings('fitNormalized') = app.FitNormalizedCheckBox.Value;
      app.Settings('dataXString') = app.DataXString.Value;
      app.Settings('dataYString') = app.DataYString.Value;
      app.Settings('fitXString') = app.FitXString.Value;
      app.Settings('fitYString') = app.FitYString.Value;
      app.Settings('intensityType') = app.IntensityTypeDropDown.Value;
      app.Settings('arrowDirection') = app.StimPropDirectionDropDown.Value;
      % save to the preference
      className = class(app);
      keyName = matlab.lang.makeValidName(className);
      setpref('iris',keyName,app.Settings);
    end
    
    function value = getSetting(app,key,default)
      if nargin < 3, default = []; end
      if app.Settings.isKey(key)
        value = app.Settings(key);
      else
        app.Settings(key) = default;
        value = default;
      end
    end
    
  end
  

  % Callbacks that handle component events
  methods (Access = private)

    % Code that executes after component creation
    function startupFcn(app, Data)
      if nargin < 2
        Data = [];
      end
      if isempty(Data)
          app.dataFile = '';
      end
      pause(0.2);
      % set up settings map
      %className = class(app);
      className = 'iris.modules.ResponseFamilies';
      keyName = matlab.lang.makeValidName(className);
      
      app.Settings = getpref('iris',keyName,containers.Map());
      if ~isa(app.Settings,'containers.map')
          app.Settings = containers.Map();
      end
      % set some tags for elements that appdesigner does not allow setting in
      % the designer
      app.DataXString.Tag = 'Data:X';
      app.DataYString.Tag = 'Data:Y';
      app.FitXString.Tag = 'Fit:X';
      app.FitYString.Tag = 'Fit:Y';
      app.FilterFrequencies.Tag = 'Frequencies';
      app.IrisDataMenu.Tag = 'IrisData';
      app.SessionMenu.Tag = 'Session';
      app.OtherMenu.Tag = 'Other';
      
      % Set the spectral table to rhodopsin
      app.SpectralTemplateDropDown.Value = 'Rhodopsin';
      app.SpectralTemplateTable.Data = {sprintf('max \x03BB'),'503'; 'Collecting Area', '0.5'};
      
      % Set some table features for the Parameters
      app.SpectralTemplateTable.ColumnFormat = {'char','char'};
      
      % Create DataAxes
      app.DataAxes = axes(app.ViewData,'units', 'pixels');
      app.DataAxes.XLabel.String = 'X';
      app.DataAxes.YLabel.String = 'Y';
      app.DataAxes.FontName = 'Times New Roman';
      app.DataAxes.BusyAction = 'cancel';
      app.DataAxes.Interruptible = 'off';
      app.DataAxes.ActivePositionProperty = 'outerposition';
      app.DataAxes.OuterPosition = app.ViewData.InnerPosition;
      app.DataAxes.OuterPosition = app.ViewData.InnerPosition - app.DataAxes.TightInset.*[0.75,0,-1,0.25] + [0,0,app.DataAxes.TightInset(1),0];
      app.DataAxes.NextPlot = 'add';
      app.DataAxes.SortMethod = 'childorder';
      app.DataAxes.Layer = 'top';
      
      % Create the analysis box areas
      app.analysisBoxLeft = fill( ...
        app.DataAxes, ...
        app.DataAxes.XLim([1,1,1,1]), ...
        app.DataAxes.YLim([1,2,2,1]), ...
        [161, 4, 18]./255, ...
        'EdgeAlpha', 0, ...
        'FaceColor', [161, 4, 18]./255, ...
        'FaceAlpha',0.8 ...
        );
      app.analysisBoxRight = fill( ...
        app.DataAxes, ...
        app.DataAxes.XLim([2,2,2,2]), ...
        app.DataAxes.YLim([1,2,2,1]), ...
        [161, 4, 18]./255, ...
        'EdgeAlpha', 0, ...
        'FaceColor', [161, 4, 18]./255, ...
        'FaceAlpha',0.8 ...
        );
      
      % register the callbacks for the window changing properties
      app.AnalysisWindowStartSlider.ValueChangingFcn = app.createCallbackFcn(@onWindowStartChanged, true);
      app.AnalysisWindowStartSlider.ValueChangedFcn = app.createCallbackFcn(@updateWindowLines, true);
      app.AnalysisWindowEndSlider.ValueChangingFcn = app.createCallbackFcn(@onWindowEndChanged, true);
      app.AnalysisWindowEndSlider.ValueChangedFcn = app.createCallbackFcn(@updateWindowLines, true);
      app.StimPropStartAfterDropDown.ValueChangedFcn = app.createCallbackFcn(@updateStimMarker,true);
      app.StimPropStartAfterEditField.ValueChangedFcn = app.createCallbackFcn(@updateStimMarkerValue,true);
      
      % parse the data file for relevant properties and ui elements
      app.setData(Data);
      app.window = [];
      %{
      % This overcomes some "infinite" loop in MATLAB, which appears to be a workaround for "g1658467".
      % The consequences of doing this are unclear.
      % See also MATLAB\R20###\toolbox\matlab\uitools\uicomponents\components\...
      %            +matlab\+ui\+internal\+controller\FigureController.m\flushCoalescer()
      % See  https://gist.github.com/Dev-iL/398a38ae03c6ef9ebf935d46884ce74d
      SOO = warning('off','MATLAB:structOnObject');
      SJO = warning('off','MATLAB:ui:javaframe:PropertyToBeRemoved');
      struct(struct(struct(app.container).Controller).PeerModelInfo).Synchronizer.setCoalescerMaxDelay(1);
      pause(0.01);
      warning(SOO);
      % load the web window if mlapptools is present
      loc = which('mlapptools');
      if isempty(loc)
        app.window = [];
        return
      end
      iter = 0;
      ITER_MAX = 10;
      delay = 0.2; %200ms
      while true
        try
          app.window = mlapptools.getWebWindow(app.container);
        catch
          if iter > ITER_MAX
            app.window = [];
            warning(SJO);
            return
          end
          iter = iter+1;
          pause(delay);
          continue
        end
        break
      end
      warning(SJO);
      %}
    end

    % Changes arrangement of the app based on UIFigure width
    function updateAppLayout(app, event)
            currentFigureWidth = app.container.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {501, 501};
                app.GridLayout.ColumnWidth = {'1x'};
                app.DisplayPanel.Layout.Row = 2;
                app.DisplayPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {265, '1x'};
                app.DisplayPanel.Layout.Row = 1;
                app.DisplayPanel.Layout.Column = 2;
            end
    end

    % Menu selected function: StimulusMenu
    function ImportStimulus(app, event)
      [b,a] = uigetfile( ...
        {'*.mat','MATLAB Table';'*.csv;*.tsv;*.txt;*.xls;*.xslx;','Delimited File'}, ...
        'Select Stimulus Calibration File', ...
        fullfile(app.InputRoot,'*.mat') ...
        );
      if all(~a)
        warning('RESPONSEFAMILIES:CALIBRATIONFILENOTVALID','A valid IrisData file is requried.');
        return;
      end
      if app.hasWindow
        app.window.bringToFront();
      else
        try %#ok<TRYNC>
          figure(app.container);
        end
      end
      fileName = fullfile(a,b);
      [~,~,ext] = fileparts(b);
      switch lower(ext)
        case '.mat'
          % import as a mat table
          S = load(fileName,'-mat');
          fn = fieldnames(S);
          calibrationTable = S.(fn{1});
        otherwise
          % readtable
          error('Cannot read Delimited files just yet. Best to save your calibration table as a MATLAB table object.');
      end
      
      % set the table
      app.setStimCalibration(calibrationTable);
      %{
            app.ledCalibration = calibrationTable;
            app.StimulusCalibrationTable.Data = app.ledCalibration;
            app.StimulusCalibrationTable.ColumnName = app.ledCalibration.Properties.VariableNames;
            % enable associated props
            app.StimulusCalibrationTable.Enable = 'on';
            app.StimCalIntensityDropDown.Enable = 'on';
            app.StimCalConversionDropDown.Enable = 'on';
            
            app.StimCalIntensityDropDown.Items = app.StimulusCalibrationTable.ColumnName;
            app.StimCalIntensityDropDown.Value = app.StimCalIntensityDropDown.Items(2);
            app.StimCalConversionDropDown.Items = app.StimulusCalibrationTable.ColumnName;
            app.StimCalConversionDropDown.Value = app.StimCalConversionDropDown.Items(3);
            
            app.requiresFitUpdate = true;
      %}
    end

    % Menu selected function: NeutralDensityMenu
    function ImportND(app, event)
      [b,a] = uigetfile( ...
        {'*.mat','MATLAB Table';'*.csv;*.tsv;*.txt;*.xls;*.xslx;','Delimited File'}, ...
        'Select ND Calibration File', ...
        fullfile(app.InputRoot,'*.mat') ...
        );
      if all(~a)
        warning('RESPONSEFAMILIES:CALIBRATIONFILENOTVALID','A valid IrisData file is requried.');
        return;
      end
      if app.hasWindow
        app.window.bringToFront();
      else
        try %#ok<TRYNC>
          figure(app.container);
        end
      end
      fileName = fullfile(a,b);
      [~,~,ext] = fileparts(b);
      switch lower(ext)
        case '.mat'
          % import as a mat table
          S = load(fileName,'-mat');
          fn = fieldnames(S);
          calTable = S.(fn{1});
          if ~isa(calTable,'table')
            error("ND calibration table must be a table object.");
          end
          
        otherwise
          % readtable
          %error('Cannot read Delimited files just yet. Best to save your calibration table as a MATLAB table object.');
          try
            calTable = readtable(fileName);
          catch ME
            error('Cannot read ND Table "%s"\n with error: "%s".',fileName,ME.message);
          end
      end
      % set the calibration table
      app.setNDCalibration(calTable);
      %{
            app.NdCalibrationTable = calTable;
            app.NdCalibrationTable.Data = app.ndCalibration;
            app.NdCalibrationTable.ColumnName = app.ndCalibration.Properties.VariableNames;
            % enable the ND options
            app.NdCalibrationTable.Enable = 'on';
            app.NdCalIdentityDropDown.Enable = 'on';
            app.NdCalConversionDropDown.Enable = 'on';
            app.StimulusNDsTextArea.Enable = 'on';
            app.BGNDsTextArea.Enable = 'on';
            app.StimulusNDsTextAreaLabel.Enable = 'on';
            app.BGNDsTextAreaLabel.Enable = 'on';
            % fill in best guesses
            app.NdCalIdentityDropDown.Items = app.NdCalibrationTable.ColumnName;
            app.NdCalIdentityDropDown.Value = app.NdCalIdentityDropDown.Items(3);
            app.NdCalConversionDropDown.Items = app.NdCalibrationTable.ColumnName;
            app.NdCalConversionDropDown.Value = app.NdCalConversionDropDown.Items(2);
            
            app.requiresFitUpdate = true;
      %}
    end

    % Menu selected function: LoadDataMenu
    function ImportData(app, event)
      [b,a] = uigetfile( ...
        {'*.idata','IrisData File'}, ...
        'Select Data File', ...
        fullfile(app.InputRoot,'*.idata') ...
        );
      if all(~a)
        warning('RESPONSEFAMILIES:DATAFILENOTVALID','A valid IrisData file is requried.');
        return;
      end
      if app.hasWindow
        app.window.bringToFront();
      else
        try %#ok<TRYNC>
          figure(app.container);
        end
      end
      if app.hasData
        % save any changes during last data session before import the
        % next file.
        app.saveSettings();
      end
      theFile = fullfile(a,b);
      app.setData(theFile);
      % Once we finish parsing, let's force a call to import the led
      % calibration table if we don't already have one
      if ~app.hasLedCalibration
        app.ControlTabs.SelectedTab = app.CalibrationTab;
        drawnow();
        pause(0.01);
        app.ImportStimulus([]);
      end
    end

    % Menu selected function: PlotsMenu
    function ExportFigures(app, event)
      if app.requiresFitUpdate
        error('Update the changes made on "Fit" tab, then try again.');
      end
      import utilities.*;
      % gather the data
      pltD = app.data;
      if app.FilterCheckBox.Value
        pltD = pltD.Filter(app.filterParams{:});
      end
      
      % plot the aggregates
      aggs = pltD.Aggregate( ...
        app.aggParams{:}, ...
        app.baselineParams{:}, ...
        app.scaleParams{:} ...
        );
      
      
      % update axes labels
      xString = app.DataXString.Value;
      yString = app.DataYString.Value;
      
      % Set up the plot figure
      s0 = get(groot,'MonitorPositions');
      s0 = s0(s0(:,1) == 1,3:4);
      dims = [1200,550];
      
      f = figure( ...
        'Visible', 'off', ...
        'NumberTitle', 'off', ...
        'DockControls', 'off', ...
        'Color', [1 1 1] ...
        );
      
      set(f, ...
        'DefaultUicontrolFontName', 'Times New Roman', ...
        'DefaultAxesColor', [1,1,1], ...
        'DefaultAxesFontName', 'Times New Roman', ...
        'DefaultAxesFontSize', 14,...
        'DefaultTextFontName', 'Times New Roman' ...
        );
      
      f.Position = [(s0-dims)./2,dims];
      
      % create data axes
      ax1 = subplot(1,2,1);
      if app.SHOW_DATUMS
        % plot the data before aggregation
        pltD.plot( ...
          'axes', ax1, ...
          'colorized', false, ...
          'opacity', 0.1, ...
          app.baselineParams{:}, ...
          app.scaleParams{:}, ...
          'interactive',false, ...
          'axesLabels',false, ...
          'devices', app.selectedDevice ...
          );
        % transparancy is an issue when saving fig files, so let's convert
        % them to light gray.
        set(ax1.Children, 'color', 0.75+[0,0,0]);
      end
      % plot the aggregates
      aggs.plot( ...
        'axes', ax1, ...
        'colorized', false, ...
        'opacity', 1, ...
        'interactive', false, ...
        'axesLabels',false, ...
        'computeXLimitBy','Axes', ...
        'computeYLimitBy', 'Axes', ...
        'devices', app.selectedDevice ...
        );
      % determine time calibration
      timeCorrection = app.StimPropStartAfterEditField.Value;
      XValues = get(ax1.Children,'xdata');
      XValues = cellfun(@(x)x-timeCorrection,XValues,'UniformOutput',false);
      arrayfun(@(L,nx)set(L,'xdata',nx{:}),ax1.Children,XValues,'uniformoutput',false);
      ax1.XLim = ax1.XLim - timeCorrection;
      % plot the stim time arrow
      hasArrow = ~(isempty(app.stimArrowPatch) || ~app.stimArrowPatch.isvalid());
      if hasArrow
        verts = app.stimArrowPatch.Vertices;
        verts(:,1) = verts(:,1) - timeCorrection;
        patch( ...
          ax1, ...
          'Faces', [1,2,3], ...
          'Vertices', verts, ...
          'FaceColor', [0 0 0], ...
          'EdgeColor', [0 0 0], ...
          'FaceAlpha', 1 ...
          );
      end
      % update the labels
      ax1.XLabel.String = [xString,' ',app.xUnitsData];
      ax1.YLabel.String = [yString,' ',app.yUnitsData];
      
      % create fit axes
      ax2 = subplot(1,2,2);
      
      % plot from the IRData
      [xx,sIdx] = sort(app.intensityResponseData.Intensities);
      if app.FitNormalizedCheckBox.Value
        y = app.intensityResponseData.Responses.Normalized(sIdx,:);
      else
        y = app.intensityResponseData.Responses.Dots(sIdx,:);
      end
      flX = xx([1:end,end:-1:1]);
      flY = [y(:,2);y(end:-1:1,3)];
      hf = fill( ...
        ax2, ...
        flX, ...
        flY, ...
        [50,151,219]./255, ...
        'facealpha',0.25, ...
        'EdgeAlpha',0 ...
        );%#ok
      
      % plot the fit
      fxn = str2func(app.intensityResponseData.FitEquation);
      b = app.intensityResponseData.FitParameters.Estimate;
      xDom = domain(xx);
      yDom = domain(y(:));
      if strcmpi(app.IRAxes.XScale,'log')
        intX = logspace(log10(xDom(1)),log10(xDom(2)),200);
      else
        intX = linspace(xDom(1),xDom(2),200);
      end
      intY = fxn(b,intX);
      fl = line( ...
        ax2, ...
        'XData',intX, ...
        'YData',intY, ...
        'linewidth',3, ...
        'linestyle', '-', ...
        'color',[232,76,61]./255 ...
        );%#ok
      % plot the markers on top
      hl = line( ...
        ax2, ...
        'xdata',xx, ...
        'ydata',y(:,1), ...
        'linestyle','none', ...
        'marker','.', ...
        'markersize',28, ...
        'markerfacecolor', [6,6,6]./255, ...
        'markeredgecolor', [6,6,6]./255 ...
        );%#ok
      % update axes labels
      xString = app.FitXString.Value;
      yString = app.FitYString.Value;
      
      ax2.XLabel.String = [xString,' ',app.xUnitsFit];
      ax2.YLabel.String = [yString,' ',app.yUnitsFit];
      
      ax2.XScale = app.IRAxes.XScale;
      ax2.YScale = app.IRAxes.YScale;
      
      % reposition axes
      ax1.Units = 'normalized';
      InSet = ax1.TightInset;
      ax1.ActivePositionProperty = 'Position';
      
      ax1.Position = [...
        InSet(1:2)+[0.015,0.05], ...
        0.925/2*(1-InSet(1)-InSet(3))-0.025, ...
        1-InSet(2)-InSet(4)-0.2 ...
        ];


      ax2.Units = 'normalized';
      ax2.ActivePositionProperty = 'position';
      
      ax2.Position = [ ...
        InSet(1:2)+[0.505,0.05], ...
        0.925/2*(1-InSet(1)-InSet(3))-0.025, ...
        1-InSet(2)-InSet(4)-0.2 ...
        ];
      
      ax2.YLim = app.IRAxes.YLim;
      % parameter text
      irTable = app.intensityResponseData.FitParameters;
      %symStr = strjoin(["syms"; irTable.Name; "x"], ' ');
      
      eqStr = app.intensityResponseData.FitEquation;
      eqStr = regexprep(eqStr,'@\(b,x\)', '');
      for en = 1:numel(irTable.Name)
        eqStr = regexprep(eqStr,sprintf('b\\(%d\\)',en),irTable.Name(en));
      end
      % remove .* or ./ or .^
      eqStr = regexprep(eqStr,'\.(?=[\^/\\\*])','');
      %eval(symStr);
      eqSym = str2sym(eqStr);
      
      paramText = arrayfun( ...
        @(nm,es) ...
        sprintf('%s: %.2f', nm, es), ...
        irTable.Name, ...
        irTable.Estimate, ...
        'uniformoutput', false ...
        );
      paramText = [{['Eqn: $',latex(eqSym),'$']};paramText];
      coefText = text(ax2, ...
        xDom(2), ...
        diff(ax2.YLim)*0.025 + ax2.YLim(1), ...
        paramText, ...
        'FontSize', 16, ...
        'HorizontalAlignment', 'right', ...
        'VerticalAlignment', 'bottom', ...
        'interpreter', 'latex' ...
        );%#ok
      
      % Add Title Axes
      tAx = axes(f, 'Units', 'normalized', ...
        'Position', [...
        0.2,...
        sum(ax1.Position([2,4])),...
        0.6,...
        1-sum(ax1.Position([2,4])) ...
        ], ...
        'Visible', 'off');
      
      text(tAx, 0.5, 0.1, ...
        'Intensity Response Families', 'FontSize', 20, 'FontWeight', 'bold');
      tAx.XLim = [0,1.8];
      tAx.YLim = [0,0.2];
      
      f.Visible = 'on';
      
      fprintf('Figure created. Use gcf to modify it or save it in typical fashion.\n');
    end

    % Menu selected function: IrisDataMenu, OtherMenu, 
    % SessionMenu
    function ExportResults(app, event)
      if app.requiresFitUpdate
        error('Update the changes made on "Fit" tab, then try again.');
      end
      % propmt for location and type
      vf = iris.data.validFiles();
      fInfo = vf.getIDFromLabel('Session');
      filterText = [ ...
        { ...
        strjoin(strcat('*.',fInfo.exts),';'), fInfo.label ...
        }; ...
        { ...
        '*.idata', 'IrisData File' ...
        }; ...
        {
        '*.csv;*.txt;*.tsv;', 'Text File'
        } ...
        ];
      switch event.Source.Tag
        case 'IrisData'
          selectExt = 'idata';
          filterText = filterText([2,1,3],:);
        case 'Session'
          selectExt = fInfo.exts{1};
        otherwise
          selectExt = 'csv';
          filterText = filterText([3,1,2],:);
      end
      [sName,sPath,fltIdx] = uiputfile( ...
        filterText, ...
        'Save Response Family Data', ...
        fullfile(app.OutputRoot,[app.CurrentFileNameMenu.Text,'_IRData.',selectExt]) ...
        );
      if ~fltIdx
        fprintf('Operation cancelled.\n');
        return;
      end
      app.OutputRoot = sPath;
      import iris.modules.*;
      % bring the ui to the top
      if app.hasWindow
        app.window.bringToFront();
      else
        try %#ok<TRYNC>
          figure(app.container);
        end
      end
      
      waitBox = ResponseFamilies.notifyUser( ...
        sprintf('Saving %s',filterText{fltIdx,2}), ...
        'Export Results' ...
        );
      pause(0.01);
      cleanup = onCleanup(@()delete(waitBox));
      % fltIDx == 1: 'session', 2: 'iData'
      % Let's export an IrisData object where we add "devices"
      % first, let's setup some values
      % appended values
      addDevices = {'Responses', 'NormalizedResponses'};
      % units struct for normalized responses
      fUnits = struct();
      fUnits.x = strtrim(app.xUnitsFit);
      fUnits.y = '(norm)';
      if strcmpi(app.IntensityTypeDropDown.Value,'Photon Density')
        bgUnits = regexprep(fUnits.x,'[\(\)]','');
      else
        bgUnits = [regexprep(fUnits.x,'[\(\)]',''),'sec^{-1}'];
      end
      
      irdata = app.intensityResponseData;
      
      %iterators
      norms = irdata.Responses.Normalized(:,1);
      dots = irdata.Responses.Dots(:,1);
      bgs = irdata.BackgroundIntensities;
      X = irdata.Intensities;
      fitFs = mean(1/diff(X));
      
      % cell params of fit for display properties
      fitCell = [ ...
        [ ...
        cellstr(strcat('Fit_Parameter_',irdata.FitParameters.Param)); ...
        cellstr(strcat('Fit_Estimate_',irdata.FitParameters.Param)) ...
        ], ...
        [ ...
        {irdata.FitParameters.Name{:}}'; ...
        num2cell(round(irdata.FitParameters.Estimate,4)) ...
        ] ...
        ];%#ok
      fitCell(end+1,:) = {'Fit_Equation', irdata.FitEquation};
      
      % copy the input data and filter, if checked in ui
      aData = app.data; % a copy
      if app.FilterCheckBox.Value
        aData = aData.Filter(app.filterParams{:});
      end
      
      % aggregate, scale and baseline (if checked)
      % **Subsets to analyzed device
      aData = aData.Aggregate( ...
        app.aggParams{:}, ...
        app.baselineParams{:}, ...
        app.scaleParams{:}, ...
        'devices', app.selectedDevice ...
        );
      % Determine the time offset
      if strcmpi(app.StimPropStartAfterDropDown.Value,'none')
        TimeCorrection = 0;
      else
        TimeCorrection = app.StimPropStartAfterEditField.Value;
      end
      % get a copy of the data structs:
      d = aData.copyData(true(aData.nDatums,1));% all remaining data
      % loop through data structs and store the fit information
      for i = 1:numel(d)
        this = d(i);
        % append or update the fit information to the display properties
        thisFitCell = [fitCell; {'BackgroundIntensity',bgs(i);'BackgroundUnits',bgUnits}];
        thisdp = this.displayProperties;
        [~,isx,isy] = intersect(thisdp(:,1),thisFitCell(:,1));
        
        if ~isempty(isx)
          % update
          thisdp(isx,:) = thisFitCell(isy,:);
          thisFitCell(isy,:) = [];
        end
        % merge
        this.displayProperties = [thisdp;thisFitCell];
        % If we have fit to dot products, then the units of "Responses"
        % is the data units squared, otherwise it is the data units
        % (i.e. peak response value)
        dataUnits = this.units{1};
        if strcmp(app.MethodDropDown.Value,'Dot Product')
          dataUnits.y = sprintf('(%s^2)',regexprep(app.yUnitsData,'[\(\)]*',''));
        else
          dataUnits.y = app.yUnitsData;
        end
        dataUnits.x = fUnits.x;
        
        this.devices = [this.devices,addDevices];
        this.nDevices = numel(this.devices);
        this.x = [{this.x{1}-TimeCorrection},X(i),X(i)];
        this.y = [this.y,dots(i),norms(i)];
        % sample rates have to be extended too
        this.sampleRate = [this.sampleRate,fitFs,fitFs];
        % append the new units
        % response units, dot propducts, should be in |dataY^2|
        
        this.units = [this.units,dataUnits,fUnits];
        
        % reduce baseline values
        if isfield(this,'baselineValues')
          this.baselineValues = {mean(cell2mat([this.baselineValues{:}]),2)};
        end
        % reset index to this index
        this.index = i;
        % reassign
        d(i) = this;
      end
      
      % Create a new IrisData Object to save
      iData = aData.UpdateData(d);
      
      % Append the raw dot products
      iData = iData.AppendUserData('RawIntensityResponses',irdata.Raw);
      
      % save the data based on what type of file was selected
      switch event.Source.Tag
        case 'Session'
          fn = fullfile(sPath,sName);
          iData.saveAsIrisSession(fn);
        case 'IrisData'
          fn = fullfile(sPath,sName);
          fprintf('Saving...');
          try
            save(fn,'iData', '-mat','-v7.3');
          catch e
            % catch error so that the session struct can be saved by user.
            fprintf('Could not save the IrisData object:\n\n');
            fprintf('  "%s"\n',e.message);
            return
          end
          fprintf('  Success!\nFile saved at:\n"%s"\n',fn);
        otherwise
          % determine delimiter for export
          [~,fIn,eIn]=fileparts(sName);
          eIn = strrep(eIn,'.','');
          switch lower(eIn)
            case 'tsv'
              delim = '\\t';
            otherwise
              delim = ',';
          end
          % collect data matrix and find longest time code without
          % nans, insert into big matrix for csv output.
          mat = iData.getDataMatrix();
          % For selected device, insert a header row with x values
          % from Responses (intensities) and other devices will be
          % exported in sep files without headers.
          selDeviceIdx = ismember(mat.device,app.selectedDevice);
          selMatrix = mat.y{selDeviceIdx};
          selX = mat.x{selDeviceIdx};
          for colNum = 1:size(selX,2)
            if any(isnan(selX(:,colNum))),continue; end
            selX = selX(:,colNum);
            break;
          end
          % selMatri will have x in first col and data in rest
          selMatrix = [selX(:),selMatrix];
          
          % gather col headers for output
          headersIdx = ismember(mat.device,'Responses');
          headers = arrayfun(@(d)sprintf('%6.1f',d),mat.x{headersIdx},'uniformoutput',false);
          headers = strjoin([{'Time'},headers],delim);
          headers = [regexprep(headers,'\s*',''),'\n'];
          % create data file from device name and input name
          fid = fopen(fullfile(sPath,[fIn,'_',app.selectedDevice,'.',eIn]),'wt');
          if fid < 0, error("cannot open connection to desired file."); end
          fprintf(fid,headers);
          for rowNum = 1:size(selMatrix,1)
            fprintf(fid,[regexprep(num2str(selMatrix(rowNum,:)),'\s+',delim),'\n']);
          end
          fclose(fid);
          pause(0.1);
          % export other devices as is
          dataUnits = iData.Data(1).units;
          dataUnits(selDeviceIdx) = [];
          mat.device(selDeviceIdx) = [];
          mat.x(selDeviceIdx) = [];
          mat.y(selDeviceIdx) = [];
          
          nDevices = numel(mat.device);
          for d = 1:nDevices
            if contains(mat.device{d},'Responses')
              % need to reshape xs and ys
              thisMat = [mat.x{d}(:),mat.y{d}(:)];
              thisHead = [sprintf('Intensity %s',dataUnits{d}.x), delim, sprintf('Response %s',dataUnits{d}.y),'\n'];
            else
              thisMat = mat.y{d};
              selX = mat.x{d};
              for colNum = 1:size(selX,2)
                if any(isnan(selX(:,colNum))),continue; end
                selX = selX(:,colNum);
                break;
              end
              thisMat = [selX(:),thisMat]; %#ok
              thisHead = '';
            end
            fid = fopen(fullfile(sPath,[fIn,'_',mat.device{d},'.',eIn]),'wt');
            if fid < 0, error("cannot open connection to desired file."); end
            fprintf(fid,thisHead);
            for rowNum = 1:size(thisMat,1)
              fprintf(fid,[regexprep(num2str(thisMat(rowNum,:)),'\s+',delim),'\n']);
            end
            fclose(fid);
            pause(0.1);
          end
          
          % export metadata as another file.
          writetable(iData.Specs.Table,fullfile(sPath,[fIn,'_metadata.',eIn]),'Delimiter',delim);
          % report completions
          fprintf('Finished exporting "%s" files.\n',eIn);
          
      end
      pause(0.05);
    end

    % Button pushed function: DataAxesButton
    function ViewDataAxesProps(app, event)
      if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
        app.DisplayTabs.SelectedTab = app.ViewData;
        drawnow();
      end
      inspect(app.DataAxes);
    end

    % Button pushed function: FitAxesButton
    function ViewIrAxesProps(app, event)
      if ~isequal(app.DisplayTabs.SelectedTab,app.ViewResponseFamily)
        app.DisplayTabs.SelectedTab = app.ViewResponseFamily;
        drawnow();
      end
      inspect(app.IRAxes);
    end

    % Button pushed function: UpdateFitButton
    function onUpdateFit(app, event)
      if ~app.hasLedCalibration
        error('Cannot compute fit without stimulus calibration.');
      end
      % check that the parameter estimates are not empty
      paramEst = app.EstimateTable.Data.Estimate(:);
      if isempty(paramEst)
        error('Estimates must not be empty.');
      end
      
      app.DisplayTabs.SelectedTab = app.ViewResponseFamily;
      
      % delay to allow tab change
      pause(0.05);
      
      % notify
      waitBox = iris.modules.ResponseFamilies.notifyUser( ...
        'Updating Fit...', ...
        'Response Families' ...
        );
      pause(0.01);
      cleanup = onCleanup(@()delete(waitBox));
      
      app.computeIntensityResponse();
      
      app.requiresFitUpdate = false;
      
      app.tryPlotFits();
      drawnow('nocallbacks');
      
      % save the current settings
      app.saveSettings();
    end

    % Value changed function: FitEquation
    function validateFitEquation(app, event)
      value = lower(event.Value);
      % Validate the new string and revert if unfit (pun not intended)
      hasX = contains(value,'x');
      hasB = contains(value,'b');
      if ~hasX
        app.FitEquation.Value = event.PreviousValue;
        error('Fit equation must use "x".');
      end
      if ~hasB
        app.FitEquation.Value = event.PreviousValue;
        error('Fit equation must use parameter array "b(#)".');
      end
      % ok, lets check the function itself
      paramList = unique(regexp(regexprep(value,'[()]',''),'b\d*','match'));
      testFx = str2func(['@(x,b)',value]);
      try
        % it only needs to actually process some inputs, outputs may be nan
        testFx(1:3,ones(1,numel(paramList)));
      catch ME
        app.FitEquation.Value = event.PreviousValue;
        rethrow(ME);
      end
      % OK, parameterize
      app.parameterizeFitEqn();
      app.requiresFitUpdate = true;
    end

    % Callback function: DataXString, DataXString, DataYString, 
    % DataYString, FitXString, FitXString, FitYString, 
    % FitYString
    function onUpdateLabel(app, event)
      changingValue = event.Value;
      src = event.Source;
      tag = strsplit(src.Tag,':');
      unitTarget = sprintf('%sUnits%s',lower(tag{2}),tag{1});
      switch tag{1}
        case 'Data'
          if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
            app.DisplayTabs.SelectedTab = app.ViewData;
            drawnow();
          end
          target = app.DataAxes.([tag{2},'Label']);
        case 'Fit'
          if ~isequal(app.DisplayTabs.SelectedTab,app.ViewResponseFamily)
            app.DisplayTabs.SelectedTab = app.ViewResponseFamily;
            drawnow();
          end
          target = app.IRAxes.([tag{2},'Label']);
      end
      target.String = [changingValue,' ',app.(unitTarget)];
    end

    % Value changed function: StatisticDropDown
    function StatisticDropDownValueChanged(app, event)
      value = app.StatisticDropDown.Value;
      app.StatisticsFunctionString.Editable = 'off';
      switch value
        case 'Mean'
          app.StatisticsFunctionString.Value = "@(x)mean(x,1,'omitnan')";
        case 'Median'
          app.StatisticsFunctionString.Value = "@(x)median(x,1,'omitnan')";
        case 'Variance'
          app.StatisticsFunctionString.Value = "@(x)var(x,0,1,'omitnan')";
        case 'Sum'
          app.StatisticsFunctionString.Value = "@(x)sum(x,1,'omitnan')";
        case 'Custom'
          app.StatisticsFunctionString.Editable = 'on';
      end
      
      % call the catchall method
      app.onAggregateModified([]);
    end

    % Value changed function: StatisticsFunctionString
    function validateStatsFcn(app, event)
      value = app.StatisticsFunctionString.Value;
      try
        fxTest = str2func(value);
      catch ME
        app.StatisticsFunctionString.Value = event.PreviousValue;
        rethrow(ME);
      end
      % validate that it works on a matrix
      testMatrix = magic(5);
      try
        testResult = fxTest(testMatrix(1:3,:));
      catch ME
        app.StatisticsFunctionString.Value = event.PreviousValue;
        rethrow(ME);
      end
      % validate that the output is the same number of columns as testMatrix
      if numel(testResult) ~= 5
        app.StatisticsFunctionString.Value = event.PreviousValue;
        error('Output must operate on columns of a matrix.');
      end
      % call the catchall method
      app.plotData();
    end

    % Value changed function: SpectralTemplateDropDown
    function onSpectralTemplateChanged(app, event)
      value = app.SpectralTemplateDropDown.Value;
      d = cell(1,2);
      d{1} = sprintf('max \x03BB');
      switch value
        case 'Rhodopsin'
          d{2} = '503';
        case 'MS-Opsin'
          d{2} = '360,508';
        case 'M-Opsin'
          d{2} = '508';
        case 'S-Opsin'
          d{2} = '360';
        case 'Custom'
          dCurrent = app.SpectralTemplateTable.Data{1,2};
          % if is empty, use rhodopsin
          if isempty(dCurrent)
            d{2} = '503';
          else
            d{2} = dCurrent;
          end
      end
      app.SpectralTemplateTable.Data(1,:) = d;
      if strcmp(value,'Custom') || strcmp(app.CellTypeDropDown.Value,'Custom')
        app.SpectralTemplateTable.ColumnEditable = [false,true];
      else
        app.SpectralTemplateTable.ColumnEditable = [false,false];
      end
    end

    % Value changed function: BaselineCheckBox, DeviceDropDown, 
    % FilterCheckBox, GroupBySelect, ScaleEditField
    function onAggregateModified(app, event)
      % validate any changes, if needed, and plot
      app.plotData();
    end

    % Value changed function: FilterFrequencies, FilterOrder, 
    % FilterTypeDropDown
    function onFilterModified(app, event)
      if strcmp(event.Source.Tag,'Frequencies')
        % validate the frequencies
        freqs = str2num(event.Value);%#ok
        filterMode = app.FilterTypeDropDown.Value;
        if strcmp(filterMode,'BandPass')
          if length(freqs) ~= 2
            freqs = [0,100]+freqs;
          end
        else
          if length(freqs) ~= 1
            freqs = max(freqs);
          end
        end
        app.FilterFrequencies.Value = strjoin(string(num2str(freqs(:))),', ');
      end
      % now prevent redraw if we don't filter anyway
      if ~app.FilterCheckBox.Value, return; end
      app.plotData();
    end

    % Value changed function: BaselineFirstCheckBox, 
    % BaselineRefDropDown, BaselineTypeDropDown, 
    % OffsetEditField, PtsEditField
    function onBaselineModified(app, event)
      % if baseline is uncheck, do not do anything but update the modified
      % value
      if ~app.BaselineCheckBox.Value, return; end
      % Otherwise, validate if needed and plot the data
      app.plotData();
    end

    % Value changing function: GroupFilter
    function onFilterGroupBy(app, event)
      newVal = event.Value;
      if endsWith(newVal,','), return; end
      valCells = strsplit(newVal,','); % allow "," to indicate multiple matches
      itDat = app.GroupBySelect.ItemsData;
      inds = false(numel(itDat),numel(valCells));
      for v = 1:numel(valCells)
        changingValue = regexprep(valCells{v},'\W','');
        % Set the Items value of the groupby listbox to those that match.
        % if the changingValue is empty, set the groupby listbox Items to
        % ItemsData
        inds(:,v) = contains(itDat,changingValue);
      end
      inds = any(inds,2);
      newItems = itDat(inds);
      
      app.GroupBySelect.Items = newItems;
      %app.GroupBySelect.Value = newItems(1); % maybe we can check what the last selected item was?
    end

    % Cell edit callback: EstimateTable
    function onParameterEstimateChanged(app, event)
      app.requiresFitUpdate =  true;
    end

    % Value changed function: GetNDValueFromPropertyCheckBox
    function onGetNDFromPropChanged(app, event)
      value = app.GetNDValueFromPropertyCheckBox.Value;
      if value
        % enable property selection for ND, e.g. TotalND
        app.StimNDPropertyDropDown.Enable =  'on';
        app.BGNDPropertyDropDown.Enable = 'on';
        app.StimNDPropertyDropDownLabel.Enable = 'on';
        app.BGNDPropertyDropDownLabel.Enable = 'on';
      else
        % disableproperty selection for ND, e.g. TotalND
        app.StimNDPropertyDropDown.Enable =  'off';
        app.BGNDPropertyDropDown.Enable = 'off';
        app.StimNDPropertyDropDown.Value = {'none'};
        app.BGNDPropertyDropDown.Value = {'none'};
        app.StimNDPropertyDropDownLabel.Enable = 'off';
        app.BGNDPropertyDropDownLabel.Enable = 'off';
      end
    end

    % Value changed function: BGNDsTextArea, StimulusNDsTextArea
    function validateInputNdList(app, event)
      value = event.Value;
      % first determine correct syntax was supplied
      splitValues = cellfun(@(elem)strtrim(strsplit(elem,'=>')),value,'UniformOutput',false);
      % validate input strings
      % we expect that splitValues will be of the form:
      % [ { 'NAME', 'WAVELENGTH' }; {...}; ... ]
      ndId = app.NdCalIdentityDropDown.Value;
      if strcmp(ndId,""), return; end
      dropInds = false(numel(splitValues),1);
      for i = 1:numel(splitValues)
        % validate the input
        thisEntry = splitValues{i};
        % drop empty entries
        if isempty(thisEntry{1})
          dropInds(i) = true;
          continue;
        end
        % validate entry
        thisID = thisEntry(1);
        [tf,thisID] = IrisData.ValidStrings( ...
          thisID, ...
          unique(app.ndCalibration.(ndId)) ...
          );
        
        if ~tf
          dropInds(i) = true;
          warning('Could not find "%s" in the calibration table column: "%s".',thisEntry{1},ndId);
          continue;
        end
        % reassign corrected string
        thisEntry(1) = thisID;
        % we will assume 2 things:
        % 1) if no '=>' is present, then the ND ID is common for all
        %    wavelengths (app.totalND will use it for each wavelenght
        %    stimulus)
        % 2) if '=>' is present, it can be 1 or more wavelengths separated by
        %    commas
        if numel(thisEntry) > 1
          thisLambda = thisEntry{2}; % char array
          if isempty(thisLambda)
            thisEntry(2) = [];
            splitValues{i} = thisEntry;
            continue
          end
          lambdas = str2double(strtrim(strsplit(thisLambda,',')));
          lambdaTest = ismember(lambdas,app.ndCalibration.Wavelength);
          if ~all(lambdaTest)
            unfoundLambda = strjoin(sprintfc('%d',lambdas(~lambdaTest)),', ');
            warning('Could not find wavelength(s) (%s) in the calibration table column: "Wavelength".',unfoundLambda);
            dropInds(i) = true;
            continue
          end
        end
        % finally, reassign to the splitValues
        splitValues{i} = thisEntry;
      end
      % drop the ones that can't be found
      splitValues(dropInds) = [];
      correctValue = cellfun(@(elem)strjoin(elem,' => '),splitValues,'UniformOutput',false);
      if isempty(correctValue)
        return
      end
      event.Source.Value = correctValue;
      app.requiresFitUpdate = true;
    end

    % Value changed function: RebinCheckBox
    function onRebinToggled(app, event)
      % when IrisData allows customing grouping vectors to be input into
      % aggregate, I'll be able to reaggregate over the bins and then supply a
      % newly binned data with horizontal CI
      % the mechanism should be something like:
      % if this box is checked, plot red dots on the fit axes with a tag
      % when the slider changes or the spinner changes, use:
      % get(ax.children,'tag') to find the tag of the modified bins.
      % to modify the data,
      % if xaxis is log, then
      %{
            bins = discretize(log10(groupsIntensities),9)
            bId = unique(bins);
            newInts = zeros(length(bId),1);
            newResp = zeros(length(bId),1);
            for i = 1:length(bId)
                bGrp = bId(i);
                newInts(i) = mean(groupsIntensities(bins == bGrp));
                newResp(i) = mean(groupsDots(bins == bGrp,1));
            end
            hold(ax,'on')
            plot(ax,newInts,newResp/max(newResp),'r.','markersize',25);
      %}
      value = app.RebinCheckBox.Value;
      if value
        
      end
    end

    % Menu selected function: PropertiesMenu
    function onViewProperties(app, event)
      if ~app.hasData, return; end
      if ~isempty(app.propFigure)
        if app.propFigure.isvalid
          % bring to front
          figure(app.propFigure);
          return
        end
      end
      
      app.propFigure = uifigure('Name', 'Datum Properties','Visible', 'off');
      tabGrid = uigridlayout(app.propFigure,[1,1]);
      specs = app.data.Specs;
      propTab = uitable(tabGrid,'ColumnName',["Property","Values"]);
      propCell = utilities.collapseUnique(cat(1,specs.Datums{:}),1,true);
      propTab.Data = propCell;
      pause(0.01);
      lens = cellfun(@length,propCell(:,2),'UniformOutput',true);
      tWidth = propTab.Position(3)-150;
      propTab.ColumnWidth = {150, max([tWidth,max(lens)*6.56])};
      app.propFigure.Visible = 'on';
      %until we can create context menues, let's use the selection to save the
      %cell's contents to the clipboard.
      import iris.modules.ResponseFamilies;
      propTab.CellSelectionCallback = @ResponseFamilies.doCopyUITableCell;
    end

    % Menu selected function: PeakDetectionMenu
    function onEditPeakParameters(app, event)
      defaults = string([app.PEAK_THREASHOLD, app.PEAK_RANGE_WIDTH, app.SMOOTH_SPAN]);
      prompts = [ ...
        "Peak threashold percent", ...
        "Peak range (number of points to average over)", ...
        "Smoothing span in seconds" ...
        ];
      answer = string(inputdlg(prompts, 'Edit Peak Parameters', [1,40], defaults))';
      if isempty(answer)
        return;
      end
      if any(defaults ~= answer)
        app.requiresFitUpdate = true;
      end
      app.PEAK_THREASHOLD = str2double(answer{1});
      app.PEAK_RANGE_WIDTH = str2double(answer{2});
      app.SMOOTH_SPAN = str2double(answer{3});
      app.saveSettings();
    end

    % Menu selected function: NotesMenu
    function onViewNotes(app, event)
      if ~app.hasData, return; end
      if ~isempty(app.noteFigure)
        if app.noteFigure.isvalid
          %bring to front
          figure(app.noteFigure);
          return
        end
      end
      nCell = app.data.Notes;
      f = uifigure('Name', 'Notes', 'Visible', 'off');% is resizable
      tabGrid = uigridlayout(f,[1,1]);
      propTab = uitable(tabGrid,'ColumnName',["Timestamp","Comment"]);
      propTab.Data = nCell;
      pause(0.01);
      lens = cellfun(@length,nCell(:,2),'UniformOutput',true);
      tWidth = propTab.Position(3)-150;
      propTab.ColumnWidth = {150, max([tWidth,max(lens)*6.56])};
      f.Visible = 'on';
      app.noteFigure = f;
      %until we can create context menues, let's use the selection to save the
      %cell's contents to the clipboard.
      import iris.modules.ResponseFamilies;
      propTab.CellSelectionCallback = @ResponseFamilies.doCopyUITableCell;
    end

    % Menu selected function: FileInfoMenu
    function onViewInfo(app, event)
      if ~app.hasData, return; end
      if ~isempty(app.infoFigure)
        % prevent creating multiple figures
        if app.infoFigure.isvalid
          %bring to front
          figure(app.infoFigure);
          return
        end
      end
      m = app.data.Meta;
      w = 650;
      h = 400;
      fig = uifigure('Name', 'Info', 'Visible', 'off');% is resizable
      fig.Position = utilities.centerFigPos(w,h);
      % make a grid
      tGrid = uigridlayout(fig,[1,2]);
      tGrid.ColumnWidth = {250,'1x'};
      
      hTree = uitree(tGrid);
      hTree.FontName = 'Times New Roman';
      hTree.FontSize = 16;
      hTree.Multiselect = 'off';
      hTree.SelectionChangedFcn = @getSelectedInfo;
      
      pTab = uitable(tGrid);
      pTab.ColumnName = {'Property'; 'Value'};
      pTab.ColumnWidth = {125, 'auto'};
      pTab.RowName = {};
      
      % Populate ui tree
      pause(0.5);
      recurseInfo(m,'File',hTree);
      hTree.expand();
      firstNode = struct('SelectedNodes',hTree.Children(1));
      hTree.SelectedNodes = firstNode.SelectedNodes;
      getSelectedInfo(hTree,firstNode);
      pause(0.1);
      
      fig.Visible = 'on';
      app.infoFigure = fig;
      %until we can create context menues, let's use the selection to save the
      %cell's contents to the clipboard.
      import iris.modules.ResponseFamilies;
      pTab.CellSelectionCallback = @ResponseFamilies.doCopyUITableCell;
      %%% helper functions
      function getSelectedInfo(src,evt)
        f = ancestor(src,'figure');
        if ~isempty(evt.SelectedNodes)
          d = evt.SelectedNodes.NodeData;
        else
          d = {[],[]};
        end
        % process data for display
        d(:,2) = arrayfun(@utilities.unknownCell2Str,d(:,2),'UniformOutput',false);
        % set into the table
        tab = f.Children(1).Children( ...
          arrayfun(@(c)isa(c,'matlab.ui.control.Table'), f.Children.Children) ...
          );
        tab.Data = d;
        % correct column widths for data
        l = cellfun(@length,d(:,2),'UniformOutput',true);
        tw = tab.Position(3)-127;
        tab.ColumnWidth = {150, max([tw,max(l)*6.55])};
        drawnow();
      end
      %recursion
      function recurseInfo(S, name, parentNode)
        for f = 1:length(S)
          if iscell(S)
            this = S{f};
          else
            this = S(f);
          end
          props = fieldnames(this);
          vals = struct2cell(this);
          %find nests
          notNested = cellfun(@(v) ~isstruct(v),vals,'unif',1);
          if ~isfield(this,'File')
            hasName = contains(lower(props),'name');
            if any(hasName)
              nodeName = sprintf('%s (%s)',vals{hasName},name);
            else
              nodeName = sprintf('%s %d', name, f);
            end
          else
            nodeName = this.File;
          end
          thisNode = uitreenode(parentNode, ...
            'Text', nodeName );
          if any(notNested)
            thisNode.NodeData = [props(notNested),vals(notNested)];
          else
            thisNode.NodeData = [{},{}];
          end
          %gen nodes
          if ~any(~notNested), continue; end
          isNested = find(~notNested);
          for n = 1:length(isNested)
            nestedVals = vals{isNested(n)};
            % if the nested values is an empty struct, don't create a node.
            areAllEmpty = all( ...
              arrayfun( ...
              @(sss)all( ...
              cellfun( ...
              @isempty, ...
              struct2cell(sss), ...
              'UniformOutput', 1 ...
              ) ...
              ), ...
              nestedVals, ...
              'UniformOutput', true ...
              ) ...
              );
            if areAllEmpty, continue; end
            recurseInfo(nestedVals,props{isNested(n)},thisNode);
          end
        end
      end
    end

    % Value changed function: FitNormalizedCheckBox, 
    % FitStimulusAmplitudeDropDown, FitStimulusTimeIdDropDown, 
    % MethodDropDown, RebinSpinner
    function onFitParamChanged(app, event)
      
      app.requiresFitUpdate = true;
      
    end

    % Value changed function: CellTypeDropDown
    function onSetCellType(app, event)
      value = app.CellTypeDropDown.Value;
      d = cell(1,2);
      d{1} = 'Collecting Area';
      switch value
        case 'Mouse Rod'
          d{2} = '0.5';
        case 'Mouse Cone'
          d{2} = '0.2';
        case 'Custom'
          dCurrent = app.SpectralTemplateTable.Data{2,2};
          % if is empty, use mosue rod
          if isempty(dCurrent)
            d{2} = '0.5';
          else
            d{2} = dCurrent;
          end
      end
      app.SpectralTemplateTable.Data(2,:) = d;
      if strcmp(value,'Custom') || strcmp(app.SpectralTemplateDropDown.Value,'Custom')
        app.SpectralTemplateTable.ColumnEditable = [false,true];
      else
        app.SpectralTemplateTable.ColumnEditable = [false,false];
      end
      
    end

    % Cell edit callback: SpectralTemplateTable
    function onSpectralParamCellChanged(app, event)
      % This method is only accessed when either app.cellTypeDropdown is
      % Custom or when SpectralTemplateDropdown is set to Custom
      
      % Determine which cell is being edited and if it is allowable. If not,
      % return the value to previous
      
      % also, validate the value. For CA, just amke sure the value is scalar
      % and numeric (castable)
      % For lmax it must not return nans for lmax = str2num(paramTable{1,2})
      % where paramTable = app.SpectralTemplateTable.Data
      
      indices = event.Indices;
      
      switch indices(1)
        case 1
          % lambda max
          if ~strcmp(app.SpectralTemplateDropDown.Value,'Custom')
            % not allowed
            event.Source.Data{indices(1),indices(2)} = event.PreviousData;
            return
          end
          if any(isnan(str2double(event.NewData)))
            event.Source.Data{indices(1),indices(2)} = event.PreviousData;
            return
          end
        case 2
          % collecting area
          if ~strcmp(app.CellTypeDropDown.Value,'Custom')
            % not allowed
            event.Source.Data{indices(1),indices(2)} = event.PreviousData;
            return
          end
          newVal = str2double(event.NewData);
          if isempty(newVal) || isnan(newVal) || ~isnumeric(newVal) || ~newVal
            event.Source.Data{indices(1),indices(2)} = event.PreviousData;
            return
          end
        otherwise
          % not ok
          event.Source.Data{indices(1),indices(2)} = event.PreviousData;
      end
      
    end

    % Close request function: container
    function closeReqFcn(app, event)
      app.saveSettings();
      delete(app);
    end

    % Menu selected function: ResetMenu
    function onResetSettings(app, event)
      response = questdlg('Are you sure you want to reset settings?');
      if ~strcmpi(response,'Yes'), return; end
      className = class(app);
      keyName = matlab.lang.makeValidName(className);
      app.Settings = containers.Map();
      setpref('iris',keyName,app.Settings);
      pause(0.01);
      app.loadSettings();
    end

    % Menu selected function: DatumsMenu
    function onViewDatums(app, event)
      fprintf('Close properties window to update any changes.\n');
      D = app.data.EditDatumProperties();
      if isequal(D,app.data)
        return;
      end
      app.data = D;
      app.parse();
    end

    % Value changed function: StimPropDirectionDropDown
    function onStimArrowOrientationChanged(app, event)
      if ~app.hasData, return; end
      if strcmp(app.StimPropStartAfterDropDown.Value,'none'), return; end
      app.drawStimulusArrow();
    end

    % Menu selected function: PreferencesMenu
    function onEditPreferences(app, event)
      import iris.modules.ResponseFamilies;
      prefsVals= struct('ci',app.COMPUTE_CI,'showDatums',app.SHOW_DATUMS,'fitInt',app.PHOTON_DENSITY_UNITS);
      
      s0 = get(groot,'MonitorPositions');
      s0 = s0(s0(:,1) == 1,3:4);
      dims = [300,120];
      
      % Create pcontainer and hide until all components are created
      pcontainer = uifigure('Visible', 'off');
      pcontainer.Position = [(s0-dims)./2,dims];
      pcontainer.Name = 'Preferences';
      
      % Create pLayout
      pLayout = uigridlayout(pcontainer);
      pLayout.ColumnWidth = {'2x', '1x', '2x', 15, 20};
      pLayout.RowHeight = {20, 20, 20, 20,'1x'};
      pLayout.ColumnSpacing = 5;
      pLayout.RowSpacing = 5;
      
      % Create ExportSettingsLabel
      ExportSettingsLabel = uilabel(pLayout);
      ExportSettingsLabel.HorizontalAlignment = 'center';
      ExportSettingsLabel.VerticalAlignment = 'bottom';
      ExportSettingsLabel.FontName = 'Times New Roman';
      ExportSettingsLabel.FontWeight = 'bold';
      ExportSettingsLabel.Layout.Row = 1;
      ExportSettingsLabel.Layout.Column = [1 5];
      ExportSettingsLabel.Text = 'Export Settings';
      
      % Create AllDatumsCheckBox
      AllDatumsCheckBox = uicheckbox(pLayout);
      AllDatumsCheckBox.Text = '';
      AllDatumsCheckBox.FontName = 'Times New Roman';
      AllDatumsCheckBox.Layout.Row = 2;
      AllDatumsCheckBox.Layout.Column = 5;
      AllDatumsCheckBox.Value = prefsVals.showDatums;
      
      % Create DisplayalldatumswithaggreagtesLabel
      DisplayalldatumswithaggreagtesLabel = uilabel(pLayout);
      DisplayalldatumswithaggreagtesLabel.HorizontalAlignment = 'right';
      DisplayalldatumswithaggreagtesLabel.FontName = 'Times New Roman';
      DisplayalldatumswithaggreagtesLabel.Layout.Row = 2;
      DisplayalldatumswithaggreagtesLabel.Layout.Column = [1 3];
      DisplayalldatumswithaggreagtesLabel.Text = 'Display all datums with aggreagtes:';
      
      % Create CIAggregatesCheckBox
      CIAggregatesCheckBox = uicheckbox(pLayout);
      CIAggregatesCheckBox.Text = '';
      CIAggregatesCheckBox.FontName = 'Times New Roman';
      CIAggregatesCheckBox.Layout.Row = 3;
      CIAggregatesCheckBox.Layout.Column = 5;
      CIAggregatesCheckBox.Value = prefsVals.ci;
      
      % Create Display95CIwithfitLabel
      Display95CIwithfitLabel = uilabel(pLayout);
      Display95CIwithfitLabel.HorizontalAlignment = 'right';
      Display95CIwithfitLabel.FontName = 'Times New Roman';
      Display95CIwithfitLabel.Layout.Row = 3;
      Display95CIwithfitLabel.Layout.Column = [1 3];
      Display95CIwithfitLabel.Text = 'Display 95% CI with fit:';
      
      % Fit Intensity edit box
      FitIntensityUnitsEdit = uieditfield(pLayout, 'text');
      FitIntensityUnitsEdit.FontName = 'Times New Roman';
      FitIntensityUnitsEdit.Tooltip = {'Enter a string with units for Photon Density option on fit tab.'};
      FitIntensityUnitsEdit.Layout.Row = 4;
      FitIntensityUnitsEdit.Layout.Column = [2 5];
      FitIntensityUnitsEdit.Value = prefsVals.fitInt;
      
      %Fit intensity label
      FitIntensityLabel = uilabel(pLayout);
      FitIntensityLabel.HorizontalAlignment = 'right';
      FitIntensityLabel.FontName = 'Times New Roman';
      FitIntensityLabel.Layout.Row = 4;
      FitIntensityLabel.Layout.Column = 1;
      FitIntensityLabel.Text = 'Intensity Units:';
      
      % Show the figure after all components are created
      pcontainer.CloseRequestFcn = @onClosePrefs;
      pcontainer.Visible = 'on';
      uiwait(pcontainer);
      
      app.SHOW_DATUMS = prefsVals.showDatums;
      app.PHOTON_DENSITY_UNITS = prefsVals.fitInt;
      if app.COMPUTE_CI ~= prefsVals.ci
        app.COMPUTE_CI = prefsVals.ci;
        app.requiresFitUpdate = true;
      end
      % save the current settings
      app.saveSettings();
      
      function onClosePrefs(~,~)
        prefsVals.ci = CIAggregatesCheckBox.Value;
        prefsVals.showDatums = AllDatumsCheckBox.Value;
        prefsVals.fitInt = FitIntensityUnitsEdit.Value;
        uiresume(pcontainer);
        delete(pcontainer);
      end
    end

    % Button pushed function: EditGroupsButton, GroupsButton
    function onEditGrouping(app, event)
      if ~app.hasData, return; end
      n = app.data.nDatums;
      
      specs = app.data.Specs;
      
      [existingInclusions,newInclusions] = deal(app.data.InclusionList);
      
      % get grouping information for groupBy
      groups = app.data.determineGroups(specs.Table(:,app.groupIds),true(n,1));
      groupingTable = specs.Table;
      
      nGroups = height(groups.Table);
      maxCount = max(groups.Table.Counts);
      
      % create a cell to convert to a table for displaying the checkboxes
      logicCell = cell(nGroups,maxCount+3);
      % fill with empty logicals
      [logicCell{:}] = deal(true(0));
      
      for g = 1:nGroups
        gNum = groups.Table.SingularMap(g);
        gIdx = find(groups.Singular == gNum);
        for d = 1:numel(gIdx)
          logicCell{g,d+2} = existingInclusions(gIdx(d));
        end
        logicCell{g,2} = any(existingInclusions(gIdx));
        logicCell{g,1} = sprintf('Group %d',gNum);
        logicCell{g,end} = strjoin(groupingTable.id(gIdx),';');
      end
      
      
      logicTable = cell2table( ...
        logicCell, ...
        'VariableNames', [{'Group','All'},sprintfc('Member_%d',1:maxCount),{'Identifiers'}] ...
        );
      % make the ui
      % Create fig and hide until all components are created
      fig = uifigure('Visible', 'off');
      fig.Position = utilities.centerFigPos(700,300);
      fig.Name = 'Edit Groupings';
      fig.CloseRequestFcn = @onCloseRequested;
      
      % Create gLayout
      gLayout = uigridlayout(fig);
      gLayout.ColumnWidth = {'1x', 60, 60};
      gLayout.RowHeight = {'1x', 25};
      gLayout.ColumnSpacing = 5;
      gLayout.RowSpacing = 5;
      
      % Create gTable
      gTable = uitable(gLayout);
      gTable.Data = logicTable;
      gTable.RowName = {};
      gTable.FontName = 'Times New Roman';
      gTable.Layout.Row = 1;
      gTable.Layout.Column = [1 3];
      gTable.ColumnWidth = [ ...
        {'auto',30}, ...
        utilities.rep({60},maxCount,1,'dims',{1,[]}), ...
        {'auto'} ...
        ];
      gTable.ColumnEditable = [false,true(1,maxCount+1),false];
      gTable.CellEditCallback = @onChangedInclusion;
      
      % Create CancelButton
      CancelButton = uibutton(gLayout, 'push');
      CancelButton.FontName = 'Times New Roman';
      CancelButton.Layout.Row = 2;
      CancelButton.Layout.Column = 2;
      CancelButton.Text = 'Cancel';
      CancelButton.ButtonPushedFcn = @onCancelRequested;
      
      % Create SaveButton
      SaveButton = uibutton(gLayout, 'push');
      SaveButton.FontName = 'Times New Roman';
      SaveButton.FontWeight = 'bold';
      SaveButton.Layout.Row = 2;
      SaveButton.Layout.Column = 3;
      SaveButton.Text = 'Save';
      SaveButton.ButtonPushedFcn = @onSaveRequested;
      
      % Show the figure after all components are created
      pause(0.05);
      fig.Visible = 'on';
      
      % wait for user to finish editing
      uiwait(fig);
      
      % decode the logictable and convert inclusions back to a vector
      newLogic = table2cell(logicTable(:,(1:maxCount)+2));
      for g = 1:nGroups
        gNum = groups.Table.SingularMap(g);
        gIdx = find(groups.Singular == gNum);
        for d = 1:numel(gIdx)
          newInclusions(gIdx(d)) = newLogic{g,d};
        end
      end
      
      % set the new data
      app.data.InclusionList = newInclusions;
      app.requiresFitUpdate = true;
      app.plotData(true);
      return
      
      %% Interactive functions
      function onChangedInclusion(src,evt)
        thisRow = src.Data(evt.Indices(1),3:(end-1));
        if evt.Indices(2) == 2
          % All was pressed
          for m = 1:width(thisRow)
            if isempty(thisRow{1,m}),continue; end
            thisRow{1,m} = evt.NewData;
          end
          % reassign
          src.Data(evt.Indices(1),3:(end-1)) = thisRow;
        else
          % another was pressed, if all are on or off, set the All col
          % accordingly
          isOn = all(thisRow{1,:});
          isOff = all(~thisRow{1,:});
          if isOn
              src.Data{evt.Indices(1),2} = true;
          elseif isOff
              src.Data{evt.Indices(1),2} = false;
          end
        end
        
      end
      
      function onCancelRequested(src,~)
        f = ancestor(src,'figure');
        close(f);
      end
      
      function onSaveRequested(src,~)
        f = ancestor(src,'figure');
        gL = f.Children(1);
        uitab = gL.Children( ...
          arrayfun(@(c)isa(c,'matlab.ui.control.Table'), gL.Children) ...
          );
        if ~isequal(logicTable,uitab.Data)
          logicTable = uitab.Data;
        end
        close(f);
      end
      
      function onCloseRequested(src,~)
        uiresume(src);
        delete(src);
      end
      
    end

    % Value changed function: CommonPathODEditField, 
    % ExtraBackgroundODEditField, ExtraStimulusODEditField, 
    % PathNDPropertiesListBox
    function onPathNDSelectionChanged(app, event)
      app.requiresFitUpdate = true;
    end

    % Cell selection callback: NdCalibrationTable, 
    % ParameterCalculation, StimulusCalibrationTable
    function onCellSelection(app, event)
      %until we can create context menues, let's use the selection to save the
      %cell's contents to the clipboard.
      import iris.modules.ResponseFamilies;
      ResponseFamilies.doCopyUITableCell(event.Source,event);
      
    end
  end

  % Component initialization
  methods (Access = private)

    % Create UIFigure and components
    function createComponents(app)
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      app.container = uifigure('Visible', 'off');
      app.container.Name = 'Response Families';
      app.container.CloseRequestFcn = createCallbackFcn(app, @closeReqFcn, true);
      app.container.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
      app.ImportMenu = uimenu(app.container);
      app.ImportMenu.Tooltip = {''};
      app.ImportMenu.Text = 'Import';
      app.LoadDataMenu = uimenu(app.ImportMenu);
      app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportData, true);
      app.LoadDataMenu.Tooltip = {'Import an IrisData object'};
      app.LoadDataMenu.Accelerator = 'O';
      app.LoadDataMenu.Text = 'Data';
      app.CalibrationMenu = uimenu(app.ImportMenu);
      app.CalibrationMenu.Text = 'Calibration';
      app.StimulusMenu = uimenu(app.CalibrationMenu);
      app.StimulusMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportStimulus, true);
      app.StimulusMenu.Text = 'Stimulus';
      app.NeutralDensityMenu = uimenu(app.CalibrationMenu);
      app.NeutralDensityMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportND, true);
      app.NeutralDensityMenu.Text = 'Neutral Density';
      app.ExportMenu = uimenu(app.container);
      app.ExportMenu.Text = 'Export';
      app.PlotsMenu = uimenu(app.ExportMenu);
      app.PlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFigures, true);
      app.PlotsMenu.Accelerator = 'P';
      app.PlotsMenu.Text = 'Plots';
      app.DataMenu = uimenu(app.ExportMenu);
      app.DataMenu.Text = 'Data';
      app.IrisDataMenu = uimenu(app.DataMenu);
      app.IrisDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResults, true);
      app.IrisDataMenu.Tooltip = {'Export an irisData file.'};
      app.IrisDataMenu.Accelerator = 'E';
      app.IrisDataMenu.Text = 'IrisData';
      app.SessionMenu = uimenu(app.DataMenu);
      app.SessionMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResults, true);
      app.SessionMenu.Text = 'Session';
      app.PackageMenu = uimenu(app.DataMenu);
      app.PackageMenu.Tooltip = {'Export contents as IrisData and CSV.'};
      app.PackageMenu.Enable = 'off';
      app.PackageMenu.Text = 'Package';
      app.OtherMenu = uimenu(app.DataMenu);
      app.OtherMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResults, true);
      app.OtherMenu.Text = 'Other...';
      app.View = uimenu(app.container);
      app.View.Text = 'View';
      app.PropertiesMenu = uimenu(app.View);
      app.PropertiesMenu.MenuSelectedFcn = createCallbackFcn(app, @onViewProperties, true);
      app.PropertiesMenu.Text = 'Properties';
      app.NotesMenu = uimenu(app.View);
      app.NotesMenu.MenuSelectedFcn = createCallbackFcn(app, @onViewNotes, true);
      app.NotesMenu.Text = 'Notes';
      app.FileInfoMenu = uimenu(app.View);
      app.FileInfoMenu.MenuSelectedFcn = createCallbackFcn(app, @onViewInfo, true);
      app.FileInfoMenu.Text = 'File Info';
      app.DatumsMenu = uimenu(app.View);
      app.DatumsMenu.MenuSelectedFcn = createCallbackFcn(app, @onViewDatums, true);
      app.DatumsMenu.Text = 'Datums';
      app.ResetMenu = uimenu(app.View);
      app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @onResetSettings, true);
      app.ResetMenu.Separator = 'on';
      app.ResetMenu.Text = 'Reset';
      app.EditMenu = uimenu(app.container);
      app.EditMenu.Text = 'Edit';
      app.PreferencesMenu = uimenu(app.EditMenu);
      app.PreferencesMenu.MenuSelectedFcn = createCallbackFcn(app, @onEditPreferences, true);
      app.PreferencesMenu.Text = 'Preferences';
      app.PeakDetectionMenu = uimenu(app.EditMenu);
      app.PeakDetectionMenu.MenuSelectedFcn = createCallbackFcn(app, @onEditPeakParameters, true);
      app.PeakDetectionMenu.Tooltip = {'Enter parameters for peak detection, if that method is chosen.'};
      app.PeakDetectionMenu.Text = 'Peak Detection';
      app.ForceDrawMenu = uimenu(app.EditMenu);
      app.ForceDrawMenu.Separator = 'on';
      app.ForceDrawMenu.Accelerator = 'R';
      app.ForceDrawMenu.Text = 'Force Redraw';
      app.GridLayout = uigridlayout(app.container);
      app.ControlPanel = uipanel(app.GridLayout);
      app.ControlTabs = uitabgroup(app.ControlPanel);
      app.AggregatesTab = uitab(app.ControlTabs);
      app.AggregatesTab.Title = 'Aggregates';
      app.AggregatesGrids = uigridlayout(app.AggregatesTab);
      app.GroupBySelect = uilistbox(app.AggregatesGrids);
      app.GroupBySelect.Items = {'none'};
      app.GroupBySelect.Multiselect = 'on';
      app.GroupBySelect.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
      app.GroupBySelect.Tooltip = {'Select the Grouping parameters'};
      app.GroupBySelect.Value = {'none'};
      app.GroupFilter = uieditfield(app.AggregatesGrids, 'text');
      app.GroupFilter.ValueChangingFcn = createCallbackFcn(app, @onFilterGroupBy, true);
      app.GroupFilter.Tooltip = {'Filter aggregate grouping options. Use commas to match multiple.'};
      app.BaselineCheckBox = uicheckbox(app.AggregatesGrids);
      app.BaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
      app.BaselineCheckBox.Text = 'Baseline';
      app.BaselineCheckBox.Value = true;
      app.PtsLabel = uilabel(app.AggregatesGrids);
      app.PtsLabel.Text = 'Pts';
      app.PtsEditField = uieditfield(app.AggregatesGrids, 'numeric');
      app.PtsEditField.Limits = [0 Inf];
      app.PtsEditField.RoundFractionalValues = 'on';
      app.PtsEditField.ValueDisplayFormat = '%.0f';
      app.PtsEditField.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
      app.PtsEditField.Value = 1000;
      app.OffsetLabel = uilabel(app.AggregatesGrids);
      app.OffsetLabel.Text = 'Offset:';
      app.OffsetEditField = uieditfield(app.AggregatesGrids, 'numeric');
      app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
      app.BaselineTypeLabel = uilabel(app.AggregatesGrids);
      app.BaselineTypeLabel.Text = 'Baseline Type: ';
      app.BaselineTypeDropDown = uidropdown(app.AggregatesGrids);
      app.BaselineTypeDropDown.Items = {'Start', 'End', 'Fit (Sym)', 'Fit (Asym)'};
      app.BaselineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
      app.BaselineTypeDropDown.Value = 'Start';
      app.FilterCheckBox = uicheckbox(app.AggregatesGrids);
      app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
      app.FilterCheckBox.Text = 'Filter';
      app.FilterCheckBox.Value = true;
      app.FilterOrderLabel = uilabel(app.AggregatesGrids);
      app.FilterOrderLabel.Text = 'Order';
      app.FilterOrder = uieditfield(app.AggregatesGrids, 'numeric');
      app.FilterOrder.Limits = [1 11];
      app.FilterOrder.RoundFractionalValues = 'on';
      app.FilterOrder.ValueDisplayFormat = '%.0f';
      app.FilterOrder.ValueChangedFcn = createCallbackFcn(app, @onFilterModified, true);
      app.FilterOrder.Value = 4;
      app.FilterTypeDropDownLabel = uilabel(app.AggregatesGrids);
      app.FilterTypeDropDownLabel.Text = 'Filter Type:';
      app.FilterTypeDropDown = uidropdown(app.AggregatesGrids);
      app.FilterTypeDropDown.Items = {'Lowpass', 'Highpass', 'BandPass'};
      app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @onFilterModified, true);
      app.FilterTypeDropDown.Value = 'Lowpass';
      app.HzLabel = uilabel(app.AggregatesGrids);
      app.HzLabel.Text = 'Hz';
      app.FilterFrequencies = uieditfield(app.AggregatesGrids, 'text');
      app.FilterFrequencies.ValueChangedFcn = createCallbackFcn(app, @onFilterModified, true);
      app.FilterFrequencies.Value = '30';
      app.StatisticDropDownLabel = uilabel(app.AggregatesGrids);
      app.StatisticDropDownLabel.Text = 'Statistic:';
      app.StatisticDropDown = uidropdown(app.AggregatesGrids);
      app.StatisticDropDown.Items = {'Mean', 'Median', 'Variance', 'Sum', 'Custom'};
      app.StatisticDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticDropDownValueChanged, true);
      app.StatisticDropDown.Value = 'Mean';
      app.StatisticsFunctionString = uieditfield(app.AggregatesGrids, 'text');
      app.StatisticsFunctionString.ValueChangedFcn = createCallbackFcn(app, @validateStatsFcn, true);
      app.StatisticsFunctionString.Editable = 'off';
      app.StatisticsFunctionString.Tooltip = {'Function string for selected statistic. Must operate on columns of grouped row data.'};
      app.StatisticsFunctionString.Value = '@(x)mean(x,1,''omitnan'')';
      app.ScaleEditFieldLabel = uilabel(app.AggregatesGrids);
      app.ScaleEditFieldLabel.Text = 'Scale:';
      app.ScaleEditField = uieditfield(app.AggregatesGrids, 'numeric');
      app.ScaleEditField.LowerLimitInclusive = 'off';
      app.ScaleEditField.UpperLimitInclusive = 'off';
      app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
      app.ScaleEditField.Tooltip = {'Enter a scalar value to scale the data by (mostly for converting to corrent units).'};
      app.ScaleEditField.Value = 1;
      app.DeviceDropDown = uidropdown(app.AggregatesGrids);
      app.DeviceDropDown.Items = {'Device'};
      app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
      app.DeviceDropDown.Tooltip = {'Select the divice you wish to work with.'};
      app.DeviceDropDown.Value = 'Device';
      app.BaselineFirstCheckBox = uicheckbox(app.AggregatesGrids);
      app.BaselineFirstCheckBox.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
      app.BaselineFirstCheckBox.Tooltip = {'When to perform baseline subtraction. Before aggregation (checked) or after (unchecked).'};
      app.BaselineFirstCheckBox.Text = 'First';
      app.BaselineFirstCheckBox.Value = true;
      app.BaselineRefDropDown = uidropdown(app.AggregatesGrids);
      app.BaselineRefDropDown.Items = {'Separate'};
      app.BaselineRefDropDown.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
      app.BaselineRefDropDown.Value = 'Separate';
      app.RefTraceLabel = uilabel(app.AggregatesGrids);
      app.RefTraceLabel.Text = 'Ref. Trace:';
      app.GroupsButton = uibutton(app.AggregatesGrids, 'push');
      app.GroupsButton.ButtonPushedFcn = createCallbackFcn(app, @onEditGrouping, true);
      app.GroupsButton.Text = 'Groups';
      app.CalibrationTab = uitab(app.ControlTabs);
      app.CalibrationTab.Title = 'Calibration';
      app.CalibrationGrids = uigridlayout(app.CalibrationTab);
      app.StimPropLabel = uilabel(app.CalibrationGrids);
      app.StimPropLabel.Text = 'Stimulus Property: ';
      app.StimPropDropDown = uidropdown(app.CalibrationGrids);
      app.StimPropDropDown.Items = {'Select'};
      app.StimPropDropDown.Value = 'Select';
      app.BackgroundPropertyLabel = uilabel(app.CalibrationGrids);
      app.BackgroundPropertyLabel.Text = 'Background Property: ';
      app.BGPropDropDown = uidropdown(app.CalibrationGrids);
      app.BGPropDropDown.Items = {'Select'};
      app.BGPropDropDown.Value = 'Select';
      app.StimulusCalibrationTable = uitable(app.CalibrationGrids);
      app.StimulusCalibrationTable.ColumnName = {''};
      app.StimulusCalibrationTable.RowName = {};
      app.StimulusCalibrationTable.CellSelectionCallback = createCallbackFcn(app, @onCellSelection, true);
      app.StimulusCalibrationTable.Tooltip = {'Led Calibration Table'; ''};
      app.StimulusCalibrationTable.Enable = 'off';
      app.StimTableLabel = uilabel(app.CalibrationGrids);
      app.StimTableLabel.Text = 'Stimulus Calibration';
      app.StimCalIntensityDropDown = uidropdown(app.CalibrationGrids);
      app.StimCalIntensityDropDown.Items = {'Intensity'};
      app.StimCalIntensityDropDown.Enable = 'off';
      app.StimCalIntensityDropDown.Tooltip = {'Select the intensity column name. Typically StimVoltage.'};
      app.StimCalIntensityDropDown.Value = 'Intensity';
      app.StimCalConversionDropDown = uidropdown(app.CalibrationGrids);
      app.StimCalConversionDropDown.Items = {'Calibration'};
      app.StimCalConversionDropDown.Enable = 'off';
      app.StimCalConversionDropDown.Tooltip = {'Select the conversion value column'; ' typically PhotonDensity.'};
      app.StimCalConversionDropDown.Value = 'Calibration';
      app.CellTypeLabel = uilabel(app.CalibrationGrids);
      app.CellTypeLabel.Text = 'Cell Type: ';
      app.CellTypeDropDown = uidropdown(app.CalibrationGrids);
      app.CellTypeDropDown.Items = {'Mouse Rod', 'Mouse Cone', 'Custom'};
      app.CellTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @onSetCellType, true);
      app.CellTypeDropDown.Value = 'Mouse Rod';
      app.SpectralTemplateLabel = uilabel(app.CalibrationGrids);
      app.SpectralTemplateLabel.Text = 'Spectral Template: ';
      app.SpectralTemplateDropDown = uidropdown(app.CalibrationGrids);
      app.SpectralTemplateDropDown.Items = {'Rhodopsin', 'MS-Opsin', 'M-Opsin', 'S-Opsin', 'Custom'};
      app.SpectralTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @onSpectralTemplateChanged, true);
      app.SpectralTemplateDropDown.Value = 'Rhodopsin';
      app.SpectralTemplateTable = uitable(app.CalibrationGrids);
      app.SpectralTemplateTable.ColumnName = {'Parameter'; 'Value'};
      app.SpectralTemplateTable.RowName = {};
      app.SpectralTemplateTable.CellEditCallback = createCallbackFcn(app, @onSpectralParamCellChanged, true);
      app.NDTab = uitab(app.ControlTabs);
      app.NDTab.Title = 'ND';
      app.NDLayoutGrids = uigridlayout(app.NDTab);
      app.ExtraStimulusODEditFieldLabel = uilabel(app.NDLayoutGrids);
      app.ExtraStimulusODEditFieldLabel.Text = 'Extra Stimulus OD:';
      app.ExtraStimulusODEditField = uieditfield(app.NDLayoutGrids, 'numeric');
      app.ExtraStimulusODEditField.UpperLimitInclusive = 'off';
      app.ExtraStimulusODEditField.ValueChangedFcn = createCallbackFcn(app, @onPathNDSelectionChanged, true);
      app.NdCalibrationTable = uitable(app.NDLayoutGrids);
      app.NdCalibrationTable.ColumnName = {''};
      app.NdCalibrationTable.RowName = {};
      app.NdCalibrationTable.CellSelectionCallback = createCallbackFcn(app, @onCellSelection, true);
      app.NdCalIdentityDropDown = uidropdown(app.NDLayoutGrids);
      app.NdCalIdentityDropDown.Items = {'ND Identity'};
      app.NdCalIdentityDropDown.Enable = 'off';
      app.NdCalIdentityDropDown.Tooltip = {'Select the ID column.'};
      app.NdCalIdentityDropDown.Value = 'ND Identity';
      app.NdCalConversionDropDown = uidropdown(app.NDLayoutGrids);
      app.NdCalConversionDropDown.Items = {'ND Values'};
      app.NdCalConversionDropDown.Enable = 'off';
      app.NdCalConversionDropDown.Tooltip = {'Select the measured value column.'};
      app.NdCalConversionDropDown.Value = 'ND Values';
      app.GetNDValueFromPropertyCheckBox = uicheckbox(app.NDLayoutGrids);
      app.GetNDValueFromPropertyCheckBox.ValueChangedFcn = createCallbackFcn(app, @onGetNDFromPropChanged, true);
      app.GetNDValueFromPropertyCheckBox.Text = 'Get ND Value From Property';
      app.StimNDPropertyDropDownLabel = uilabel(app.NDLayoutGrids);
      app.StimNDPropertyDropDownLabel.Enable = 'off';
      app.StimNDPropertyDropDownLabel.Text = 'Stim ND Property: ';
      app.StimNDPropertyDropDown = uidropdown(app.NDLayoutGrids);
      app.StimNDPropertyDropDown.Items = {'Select'};
      app.StimNDPropertyDropDown.Enable = 'off';
      app.StimNDPropertyDropDown.Value = 'Select';
      app.StimulusNDsTextAreaLabel = uilabel(app.NDLayoutGrids);
      app.StimulusNDsTextAreaLabel.Enable = 'off';
      app.StimulusNDsTextAreaLabel.Text = {'Stimulus'; 'NDs:'};
      app.StimulusNDsTextArea = uitextarea(app.NDLayoutGrids);
      app.StimulusNDsTextArea.ValueChangedFcn = createCallbackFcn(app, @validateInputNdList, true);
      app.StimulusNDsTextArea.Enable = 'off';
      app.StimulusNDsTextArea.Tooltip = {'Enter mapping (USE SYNTAX: ID=>WAVELENGTH e.g. BlackND4.0=>505) as found by ND Identity and Wavelength column of ND table for the STIMULUS. '; 'This can be empty and can have repeated values (e.g. stacking two identical NDs).'};
      app.BGNDsTextAreaLabel = uilabel(app.NDLayoutGrids);
      app.BGNDsTextAreaLabel.Enable = 'off';
      app.BGNDsTextAreaLabel.Text = {'BG'; 'NDs:'};
      app.BGNDsTextArea = uitextarea(app.NDLayoutGrids);
      app.BGNDsTextArea.ValueChangedFcn = createCallbackFcn(app, @validateInputNdList, true);
      app.BGNDsTextArea.Enable = 'off';
      app.BGNDsTextArea.Tooltip = {'Enter mapping (USE SYNTAX: ID=>WAVELENGTH e.g. BlackND4.0=>505) as found by ND Identity and Wavelength column of ND table for the BACKGROUND. '; 'This can be empty and can have repeated values (e.g. stacking two identical NDs).'};
      app.BGNDPropertyDropDownLabel = uilabel(app.NDLayoutGrids);
      app.BGNDPropertyDropDownLabel.Enable = 'off';
      app.BGNDPropertyDropDownLabel.Text = 'BG ND Property: ';
      app.BGNDPropertyDropDown = uidropdown(app.NDLayoutGrids);
      app.BGNDPropertyDropDown.Items = {'Select'};
      app.BGNDPropertyDropDown.Enable = 'off';
      app.BGNDPropertyDropDown.Value = 'Select';
      app.ExtraBackgroundODEditFieldLabel = uilabel(app.NDLayoutGrids);
      app.ExtraBackgroundODEditFieldLabel.Text = 'Extra Background OD:';
      app.ExtraBackgroundODEditField = uieditfield(app.NDLayoutGrids, 'numeric');
      app.ExtraBackgroundODEditField.UpperLimitInclusive = 'off';
      app.ExtraBackgroundODEditField.ValueChangedFcn = createCallbackFcn(app, @onPathNDSelectionChanged, true);
      app.CommonPathODEditFieldLabel = uilabel(app.NDLayoutGrids);
      app.CommonPathODEditFieldLabel.Text = 'Common Path OD:';
      app.CommonPathODEditField = uieditfield(app.NDLayoutGrids, 'numeric');
      app.CommonPathODEditField.UpperLimitInclusive = 'off';
      app.CommonPathODEditField.ValueChangedFcn = createCallbackFcn(app, @onPathNDSelectionChanged, true);
      app.PathNDPropertiesLabel = uilabel(app.NDLayoutGrids);
      app.PathNDPropertiesLabel.Enable = 'off';
      app.PathNDPropertiesLabel.Tooltip = {'Feature coming soon.'};
      app.PathNDPropertiesLabel.Text = 'Path ND Properties';
      app.PathNDPropertiesListBox = uilistbox(app.NDLayoutGrids);
      app.PathNDPropertiesListBox.Items = {'none'};
      app.PathNDPropertiesListBox.Multiselect = 'on';
      app.PathNDPropertiesListBox.ValueChangedFcn = createCallbackFcn(app, @onPathNDSelectionChanged, true);
      app.PathNDPropertiesListBox.Enable = 'off';
      app.PathNDPropertiesListBox.Tooltip = {'Feature coming soon.'};
      app.PathNDPropertiesListBox.Value = {'none'};
      app.FitTab = uitab(app.ControlTabs);
      app.FitTab.Title = 'Fit';
      app.FitGrids = uigridlayout(app.FitTab);
      app.EquationbxLabel = uilabel(app.FitGrids);
      app.EquationbxLabel.Text = {'Equation:'; '@(b x)'; ''};
      app.FitEquation = uieditfield(app.FitGrids, 'text');
      app.FitEquation.ValueChangedFcn = createCallbackFcn(app, @validateFitEquation, true);
      app.FitEquation.Tooltip = {'Enter the character as a valid MATLAB function.'; 'The equation assumes solving for response Y given the call signature:'; '"@(x b) ..." == f( x | b(1:n) ) '; 'Where [b(1) b(2) ... b(n)] are the fit parameters to be estimated.'};
      app.FitEquation.Value = '1./(1+(b(1)./x).^b(2))';
      app.ParameterEstimatesLabel = uilabel(app.FitGrids);
      app.ParameterEstimatesLabel.Text = 'Parameter Estimates';
      app.EstimateTable = uitable(app.FitGrids);
      app.EstimateTable.ColumnName = {'Param'; 'Name'; 'Estimate'};
      app.EstimateTable.RowName = {};
      app.EstimateTable.ColumnEditable = [false true true];
      app.EstimateTable.CellEditCallback = createCallbackFcn(app, @onParameterEstimateChanged, true);
      app.EstimateTable.Tooltip = {'Enter parameter estimates.'};
      app.FitCoefficientsLabel = uilabel(app.FitGrids);
      app.FitCoefficientsLabel.Text = 'Fit Coefficients';
      app.ParameterCalculation = uitable(app.FitGrids);
      app.ParameterCalculation.ColumnName = {'Param'; 'Result'};
      app.ParameterCalculation.RowName = {};
      app.ParameterCalculation.CellSelectionCallback = createCallbackFcn(app, @onCellSelection, true);
      app.ParameterCalculation.Tooltip = {'Enter parameter estimates.'};
      app.UpdateFitButton = uibutton(app.FitGrids, 'push');
      app.UpdateFitButton.ButtonPushedFcn = createCallbackFcn(app, @onUpdateFit, true);
      app.UpdateFitButton.Text = 'Update Fit';
      app.TimeDropDownLabel = uilabel(app.FitGrids);
      app.TimeDropDownLabel.Text = 'Time: ';
      app.FitStimulusTimeIdDropDown = uidropdown(app.FitGrids);
      app.FitStimulusTimeIdDropDown.ValueChangedFcn = createCallbackFcn(app, @onFitParamChanged, true);
      app.AmplitudeDropDownLabel = uilabel(app.FitGrids);
      app.AmplitudeDropDownLabel.Text = 'Amplitude:';
      app.FitStimulusAmplitudeDropDown = uidropdown(app.FitGrids);
      app.FitStimulusAmplitudeDropDown.ValueChangedFcn = createCallbackFcn(app, @onFitParamChanged, true);
      app.RebinSpinner = uispinner(app.FitGrids);
      app.RebinSpinner.Limits = [1 Inf];
      app.RebinSpinner.RoundFractionalValues = 'on';
      app.RebinSpinner.ValueDisplayFormat = '%.0f';
      app.RebinSpinner.ValueChangedFcn = createCallbackFcn(app, @onFitParamChanged, true);
      app.RebinSpinner.Tooltip = {'Number of unique X values.'};
      app.RebinSpinner.Value = 1;
      app.RebinCheckBox = uicheckbox(app.FitGrids);
      app.RebinCheckBox.ValueChangedFcn = createCallbackFcn(app, @onRebinToggled, true);
      app.RebinCheckBox.Enable = 'off';
      app.RebinCheckBox.Visible = 'off';
      app.RebinCheckBox.Tooltip = {'Feature coming soon.'};
      app.RebinCheckBox.Text = '';
      app.MethodDropDownLabel = uilabel(app.FitGrids);
      app.MethodDropDownLabel.Text = 'Method:';
      app.MethodDropDown = uidropdown(app.FitGrids);
      app.MethodDropDown.Items = {'Dot Product', 'Peak Detection'};
      app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @onFitParamChanged, true);
      app.MethodDropDown.Value = 'Dot Product';
      app.NResponsesLabel = uilabel(app.FitGrids);
      app.NResponsesLabel.Text = 'N Responses:';
      app.FitNormalizedCheckBox = uicheckbox(app.FitGrids);
      app.FitNormalizedCheckBox.ValueChangedFcn = createCallbackFcn(app, @onFitParamChanged, true);
      app.FitNormalizedCheckBox.Tooltip = {'Check this to compute the fit to the normalized data. Uncheck to use the un-scaled peaks or dot products.'};
      app.FitNormalizedCheckBox.Text = 'Fit Normalized';
      app.FitNormalizedCheckBox.Value = true;
      app.IntensityTypeDropDownLabel = uilabel(app.FitGrids);
      app.IntensityTypeDropDownLabel.Text = 'Intensity Type:';
      app.IntensityTypeDropDown = uidropdown(app.FitGrids);
      app.IntensityTypeDropDown.Items = {'Isomerizations', 'Photon Density'};
      app.IntensityTypeDropDown.Value = 'Isomerizations';
      app.EditGroupsButton = uibutton(app.FitGrids, 'push');
      app.EditGroupsButton.ButtonPushedFcn = createCallbackFcn(app, @onEditGrouping, true);
      app.EditGroupsButton.Text = 'Edit Groups';
      app.DisplayTab = uitab(app.ControlTabs);
      app.DisplayTab.Title = 'Display';
      app.DisplayGrids = uigridlayout(app.DisplayTab);
      app.DataAxesButton = uibutton(app.DisplayGrids, 'push');
      app.DataAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataAxesProps, true);
      app.DataAxesButton.Tooltip = {'Click to modify Data Axes visual aspects.'; ''};
      app.DataAxesButton.Text = 'Data Axes';
      app.FitAxesButton = uibutton(app.DisplayGrids, 'push');
      app.FitAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewIrAxesProps, true);
      app.FitAxesButton.Tooltip = {'Click to modify Fit Axes properties.'};
      app.FitAxesButton.Text = 'Fit Axes';
      app.AdjustWindowPanel = uipanel(app.DisplayGrids);
      app.AdjustWindowGrids = uigridlayout(app.AdjustWindowPanel);
      app.AnalysisWindowStartSliderLabel = uilabel(app.AdjustWindowGrids);
      app.AnalysisWindowStartSliderLabel.Text = 'Analysis Window Start';
      app.AnalysisWindowStartSlider = uislider(app.AdjustWindowGrids);
      app.AnalysisWindowStartSlider.MajorTicks = [0 50 100];
      app.AnalysisWindowStartSlider.MajorTickLabels = {'0%', '50%', '100%'};
      app.AnalysisWindowEndSliderLabel = uilabel(app.AdjustWindowGrids);
      app.AnalysisWindowEndSliderLabel.Text = 'Analysis Window End';
      app.AnalysisWindowEndSlider = uislider(app.AdjustWindowGrids);
      app.AnalysisWindowEndSlider.MajorTicks = [0 50 100];
      app.AnalysisWindowEndSlider.MajorTickLabels = {'0%', '50%', '100%'};
      app.AnalysisWindowEndSlider.Value = 100;
      app.AxesLabelsPanel = uipanel(app.DisplayGrids);
      app.AxesLabelsPanel.Title = 'Axes Labels';
      app.AxesLabelsLayout = uigridlayout(app.AxesLabelsPanel);
      app.DataAxisPanel = uipanel(app.AxesLabelsLayout);
      app.DataAxisPanel.Title = 'Data Axis';
      app.DataAxisGrids = uigridlayout(app.DataAxisPanel);
      app.DataXStringLabel = uilabel(app.DataAxisGrids);
      app.DataXStringLabel.Text = 'X-Label:';
      app.DataXString = uieditfield(app.DataAxisGrids, 'text');
      app.DataXString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.DataXString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.DataXString.Value = 'X';
      app.DataYStringLabel = uilabel(app.DataAxisGrids);
      app.DataYStringLabel.Text = 'Y-Label:';
      app.DataYString = uieditfield(app.DataAxisGrids, 'text');
      app.DataYString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.DataYString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.DataYString.Value = 'Y';
      app.FitAxisPanel = uipanel(app.AxesLabelsLayout);
      app.FitAxisPanel.Title = 'Fit Axis';
      app.FitAxisGrids = uigridlayout(app.FitAxisPanel);
      app.FitXStringLabel = uilabel(app.FitAxisGrids);
      app.FitXStringLabel.Text = 'X-Label:';
      app.FitXString = uieditfield(app.FitAxisGrids, 'text');
      app.FitXString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.FitXString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.FitXString.Value = 'Intensity';
      app.FitYStringLabel = uilabel(app.FitAxisGrids);
      app.FitYStringLabel.Text = 'Y-Label:';
      app.FitYString = uieditfield(app.FitAxisGrids, 'text');
      app.FitYString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.FitYString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
      app.FitYString.Value = 'Response';
      app.StimulusPropertiesPanel = uipanel(app.DisplayGrids);
      app.StimulusPropertiesPanel.Title = 'Stimulus Properties';
      app.StimPropLayoutGrid = uigridlayout(app.StimulusPropertiesPanel);
      app.StimulusStartPropertyLabel = uilabel(app.StimPropLayoutGrid);
      app.StimulusStartPropertyLabel.Text = 'Stimulus Start Property';
      app.StimPropStartAfterDropDown = uidropdown(app.StimPropLayoutGrid);
      app.StimPropStartAfterDropDown.Items = {'none', 'custom'};
      app.StimPropStartAfterDropDown.Value = 'none';
      app.secLabel = uilabel(app.StimPropLayoutGrid);
      app.secLabel.Text = 'sec';
      app.StimPropStartAfterEditField = uieditfield(app.StimPropLayoutGrid, 'numeric');
      app.StimPropStartAfterEditField.Limits = [0 Inf];
      app.StimPropStartAfterEditField.Editable = 'off';
      app.DirectionDropDownLabel = uilabel(app.StimPropLayoutGrid);
      app.DirectionDropDownLabel.Text = 'Direction:';
      app.StimPropDirectionDropDown = uidropdown(app.StimPropLayoutGrid);
      app.StimPropDirectionDropDown.Items = {'Auto', 'Up', 'Down'};
      app.StimPropDirectionDropDown.ValueChangedFcn = createCallbackFcn(app, @onStimArrowOrientationChanged, true);
      app.StimPropDirectionDropDown.Value = 'Auto';
      app.DisplayPanel = uipanel(app.GridLayout);
      app.DisplayTabs = uitabgroup(app.DisplayPanel);
      app.ViewData = uitab(app.DisplayTabs);
      app.ViewData.Title = 'Data';
      app.ViewResponseFamily = uitab(app.DisplayTabs);
      app.ViewResponseFamily.Title = 'Fit';
      app.IRAxes = uiaxes(app.ViewResponseFamily);
      title(app.IRAxes, {'Intensity Response Relationship'; ''})
      xlabel(app.IRAxes, 'Intensity')
      ylabel(app.IRAxes, 'Response')
      app.IRAxes.XScale = 'log';
      app.CurrentFileNameMenu = uimenu(app.container);
      app.CurrentFileNameMenu.Visible = 'off';
      app.CurrentFileNameMenu.Enable = 'off';
      % Show the figure after all components are created
      app.container.Visible = 'on';
    end
  end

  % App creation and deletion
  methods (Access = public)

    % Construct app
    function app = ResponseFamilies(varargin)

      % Create UIFigure and components
      createComponents(app)

      % Register the app with App Designer
      registerApp(app, app.container)

      % Execute the startup function
      runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

      if nargout == 0
        clear app
      end
    end

    % Code that executes before app deletion
    function delete(app)

      % Delete UIFigure when app is deleted
      delete(app.container)
    end
  end
end