classdef ResponseFamilies < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        container                       matlab.ui.Figure
        ImportMenu                      matlab.ui.container.Menu
        LoadDataMenu                    matlab.ui.container.Menu
        CalibrationMenu                 matlab.ui.container.Menu
        StimulusMenu                    matlab.ui.container.Menu
        NeutralDensityMenu              matlab.ui.container.Menu
        ExportMenu                      matlab.ui.container.Menu
        PlotsMenu                       matlab.ui.container.Menu
        DataMenu                        matlab.ui.container.Menu
        CurrentFileNameMenu             matlab.ui.container.Menu
        GridLayout                      matlab.ui.container.GridLayout
        ControlPanel                    matlab.ui.container.Panel
        ControlTabs                     matlab.ui.container.TabGroup
        AggregatesTab                   matlab.ui.container.Tab
        AggregatesGrids                 matlab.ui.container.GridLayout
        GroupByLabel                    matlab.ui.control.Label
        GroupBySelect                   matlab.ui.control.ListBox
        GroupFilter                     matlab.ui.control.EditField
        BaselineCheckBox                matlab.ui.control.CheckBox
        PtsLabel                        matlab.ui.control.Label
        PtsEditField                    matlab.ui.control.NumericEditField
        OffsetLabel                     matlab.ui.control.Label
        OffsetEditField                 matlab.ui.control.NumericEditField
        BaselineTypeLabel               matlab.ui.control.Label
        BaselineTypeDropDown            matlab.ui.control.DropDown
        FilterCheckBox                  matlab.ui.control.CheckBox
        FilterOrderLabel                matlab.ui.control.Label
        FilterOrder                     matlab.ui.control.NumericEditField
        FilterTypeDropDownLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        HzLabel                         matlab.ui.control.Label
        FilterFrequencies               matlab.ui.control.EditField
        StatisticDropDownLabel          matlab.ui.control.Label
        StatisticDropDown               matlab.ui.control.DropDown
        StatisticsFunctionString        matlab.ui.control.EditField
        ScaleEditFieldLabel             matlab.ui.control.Label
        ScaleEditField                  matlab.ui.control.NumericEditField
        DeviceDropDown                  matlab.ui.control.DropDown
        CalibrationTab                  matlab.ui.container.Tab
        CalibrationGrids                matlab.ui.container.GridLayout
        StimPropLabel                   matlab.ui.control.Label
        StimPropDropDown                matlab.ui.control.DropDown
        BackgroundPropertyLabel         matlab.ui.control.Label
        BGPropDropDown                  matlab.ui.control.DropDown
        StimulusCalibrationTable        matlab.ui.control.Table
        StimTableLabel                  matlab.ui.control.Label
        StimCalIntensityDropDown        matlab.ui.control.DropDown
        StimCalConversionDropDown       matlab.ui.control.DropDown
        CellTypeLabel                   matlab.ui.control.Label
        CellTypeDropDown                matlab.ui.control.DropDown
        SpectralTemplateLabel           matlab.ui.control.Label
        SpectralTemplateDropDown        matlab.ui.control.DropDown
        SpectralTemplateTable           matlab.ui.control.Table
        NDTab                           matlab.ui.container.Tab
        NDLayoutGrids                   matlab.ui.container.GridLayout
        ExtraStimulusODEditFieldLabel   matlab.ui.control.Label
        ExtraStimulusODEditField        matlab.ui.control.NumericEditField
        NdCalibrationTable              matlab.ui.control.Table
        NdTableLabel                    matlab.ui.control.Label
        NdCalIdentityDropDown           matlab.ui.control.DropDown
        NdCalConversionDropDown         matlab.ui.control.DropDown
        GetNDValueFromPropertyCheckBox  matlab.ui.control.CheckBox
        StimulusNDPropertyDropDownLabel  matlab.ui.control.Label
        StimulusNDPropertyDropDown      matlab.ui.control.DropDown
        StimulusNDsTextAreaLabel        matlab.ui.control.Label
        StimulusNDsTextArea             matlab.ui.control.TextArea
        BGNDsTextAreaLabel              matlab.ui.control.Label
        BGNDsTextArea                   matlab.ui.control.TextArea
        BGNDPropertyDropDownLabel       matlab.ui.control.Label
        BGNDPropertyDropDown            matlab.ui.control.DropDown
        ExtraBackgroundODEditFieldLabel  matlab.ui.control.Label
        ExtraBackgroundODEditField      matlab.ui.control.NumericEditField
        FitTab                          matlab.ui.container.Tab
        FitGrids                        matlab.ui.container.GridLayout
        EquationbxLabel                 matlab.ui.control.Label
        FitEquation                     matlab.ui.control.EditField
        ParameterEstimatesLabel         matlab.ui.control.Label
        EstimateTable                   matlab.ui.control.Table
        FitCoefficientsLabel            matlab.ui.control.Label
        ParameterCalculation            matlab.ui.control.Table
        UpdateButton                    matlab.ui.control.Button
        TimeDropDownLabel               matlab.ui.control.Label
        FitStimulusTimeIdDropDown       matlab.ui.control.DropDown
        AmplitudeDropDownLabel          matlab.ui.control.Label
        FitStimulusAmplitudeDropDown    matlab.ui.control.DropDown
        RebinSlider                     matlab.ui.control.Slider
        RebinSpinner                    matlab.ui.control.Spinner
        RebinCheckBox                   matlab.ui.control.CheckBox
        DisplayTab                      matlab.ui.container.Tab
        DisplayGrids                    matlab.ui.container.GridLayout
        DataAxesButton                  matlab.ui.control.Button
        FitAxesButton                   matlab.ui.control.Button
        DataAxisPanel                   matlab.ui.container.Panel
        DataAxisGrids                   matlab.ui.container.GridLayout
        DataXStringLabel                matlab.ui.control.Label
        DataXString                     matlab.ui.control.EditField
        DataYStringLabel                matlab.ui.control.Label
        DataYString                     matlab.ui.control.EditField
        FitAxisPanel                    matlab.ui.container.Panel
        FitAxisGrids                    matlab.ui.container.GridLayout
        FitXStringLabel                 matlab.ui.control.Label
        FitXString                      matlab.ui.control.EditField
        FitYStringLabel                 matlab.ui.control.Label
        FitYString                      matlab.ui.control.EditField
        DisplayPanel                    matlab.ui.container.Panel
        DisplayTabs                     matlab.ui.container.TabGroup
        ViewData                        matlab.ui.container.Tab
        ViewResponseFamily              matlab.ui.container.Tab
        IRAxes                          matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    properties (Access = public)
        DataAxes % using graphics axes rather than uiaxes... it just looks better
    end
    
    properties (Access = private)
        data IrisData % IrisData, set during startup
        ledCalibration table
        ndCalibration table
        intensityResponseData struct
        requiresFitUpdate = true
        window
    end
    
    properties (Dependent = true)
        hasWindow % determine if web window exists
        hasData % Determine if data is set
        hasLedCalibration % Determines if calibration is available for stimulus
        hasNdCalibration % Determines if calibration is available for ND
        hasIRData % determines if intensity response data has been computed
        collectingArea % Get the collecting area depending on the cell type
        wavelength % Get the wavelength of the current stimulus
        absorptionTemplate % get the template function based on the lmax
        ndMap
        selectedDevice
        lightDeviceNames
        timeID
        amplitudeID
        fitEstimates
        fitEqn
        totalOD
        stimulusCalibrationCurve
        groupIds
        baselineParams
        scaleParams
        aggParams
        filterParams
        xUnitsData
        yUnitsData
        xUnitsFit
        yUnitsFit
    end
    
    methods
        
        function tf = get.hasWindow(app)
            tf = ~isempty(app.window);
        end
    
        function tf = get.hasData(app)
            tf = ~isempty(app.data);
        end

        function tf = get.hasLedCalibration(app)
            tf = ~isempty(app.ledCalibration);
        end
    
        function tf = get.hasNdCalibration(app)
            tf = ~isempty(app.ndCalibration);
        end
    
        function tf = get.hasIRData(app)
            tf = false;
            if ~app.hasData, return; end
            if ~app.hasLedCalibration, return; end
            tf = ~isempty(fieldnames(app.intensityResponseData)); 
        end
        
        function ca = get.collectingArea(app)
            switch app.CellTypeDropDown.Value
                case 'Rod'
                    ca = 0.5;
                case 'Cone'
                    ca = 0.2;
            end
        end
    
        function L = get.wavelength(app)
            % wavelength property returns array nDatums x 2 where column 1 is stimulus
            % and column 2 is background
            if ~app.hasData, return; end
            specsTable = app.data.Specs.Table;
            % start with stimulus, then choose background
            stimID = app.StimPropDropDown.Value;
            stimLabels = matlab.lang.makeValidName( ...
                strcat({'stimulus_'},specsTable.(stimID),{'_Wavelength'}) ...
                );
            
                %bg
            bgID = app.BGPropDropDown.Value;
            bgLabels = matlab.lang.makeValidName( ...
                strcat({'device_'},specsTable.(bgID),{'_Wavelength'}) ...
                );
            
            % for now we will assume every datum may have a different wavelength
            L = zeros(app.data.nDatums,2);
            for d = 1:app.data.nDatums
                thisStim = stimLabels{d};
                thisBG = bgLabels{d};
                L(d,1) = str2double(specsTable.(thisStim)(d));
                L(d,2) = str2double(specsTable.(thisBG)(d));
            end
            
        end
    
        function map = get.ndMap(app)
            % map the stimulus and background nd to measured values based on
            % wavelength
            % the resulting map will have 2 keys, 'stimulus', and 'background', each
            % of thse will be a map that has keys for wavelength (numeric). The
            % values of the inner maps will be the sum total of any associated NDs
            % Measure ID
            map = containers.Map();
            if ~app.hasNdCalibration, return; end
            map('stimulus') = app.createWavelengthMapFromTextArea(app.StimulusNDsTextArea);
            map('background') = app.createWavelengthMapFromTextArea(app.BGNDsTextArea);
            
        end
        
        function dev = get.selectedDevice(app)
            if ~app.hasData, return; end
            dev = app.DeviceDropDown.Value;
        end
    
        function devs = get.lightDeviceNames(app)
            %LIGHTDEVICES returns a string array of device names for each datum [stim,bg]
            if ~app.hasData, return; end
            stimProp = app.StimPropDropDown.Value;
            bgProp = app.BGPropDropDown.Value;
            specs = app.data.Specs.Table;
            devs = [string(specs.(stimProp)),string(specs.(bgProp))];
        end
    
        function id = get.timeID(app)
            if ~app.hasData, return; end
            id = app.FitStimulusTimeIdDropDown.Value;
        end
    
        function id = get.amplitudeID(app)
            if ~app.hasData, return; end
            id = app.FitStimulusAmplitudeDropDown.Value;
        end
    
        function eTab = get.fitEstimates(app)
            eTab = app.EstimateTable.Data;
            eTab.Properties.VariableNames = app.EstimateTable.ColumnName; %just in case?
            eTab.Estimate = str2double(eTab.Estimate);
            eTab = sortrows(eTab,'Param');
        end
        
        function eqn = get.fitEqn(app)
            eqn = str2func(['@(b,x)',app.FitEquation.Value]);
        end
    
        function datumODs = get.totalOD(app)
            % optical density attenuation is calculated from the sum of all the
            % optical densities. These become the negative exponent of base 10, which
            % will be used in creating the calibration curve. These also depend on
            % wavelength, etc. Here, we calculate the total OD for each the stimulus
            % and the background, assuming that we have these two on at one time.
            % Should a third value be needed, this approach will likely not work.
            if ~app.hasData, return; end
            % spec table for gathering from the property values
            specs = app.data.Specs;
            fromProp = app.GetNDValueFromPropertyCheckBox.Value;
            stimPropVal = app.StimulusNDPropertyDropDown.Value;
            bgPropVal = app.BGNDPropertyDropDown.Value;
            
            % nd map, if present
            map = app.ndMap;
            if ~isempty(map)
                stimMap = app.ndMap('stimulus');
                bgMap = app.ndMap('background');
            end
            
            
            datumLambdas = app.wavelength;
            datumODs = zeros(size(datumLambdas));
            for d = 1:size(datumLambdas,1)
                thisL = datumLambdas(d,:);
                thisPropCell = specs.Datums{d};
                % for each datum, collect from:
                %   ndMap (if ~isempty)
                if ~isempty(map)
                    datumODs(d,1) = datumODs(d,1)+stimMap(thisL(1));
                    datumODs(d,2) = datumODs(d,2)+bgMap(thisL(2));
                end
                %   StimulusNDPropertyDropDown (if GetNDValueFromPropertyCheckBox)
                if fromProp
                    if ~strcmpi(stimPropVal,'none')
                        stimPropEntry = thisPropCell{ismember(thisPropCell(:,1),stimPropVal),2};
                        if ~isnumeric(stimPropEntry)
                            strRepr = regexprep(stimPropEntry,'[^\.0-9,]','');
                            stimPropEntry = sum(str2num(strRepr));%#ok
                        end
                        datumODs(d,1) = sum([datumODs(d,1);stimPropEntry(:)]);
                    end
                    if ~strcmpi(bgPropVal,'none')
                        bgPropEntry = thisPropCell{ismember(thisPropCell(:,1),bgPropVal),2};
                        if ~isnumeric(bgPropEntry)
                            strRepr = regexprep(bgPropEntry,'[^\.0-9,]','');
                            bgPropEntry = sum(str2num(strRepr));%#ok
                        end
                        datumODs(d,2) = sum([datumODs(d,2);bgPropEntry(:)]);
                    end
                end
                %   ExtraODAttenuationEditField
                datumODs(d,1) = datumODs(d,1) + app.ExtraStimulusODEditField.Value;
                datumODs(d,2) = datumODs(d,2) + app.ExtraBackgroundODEditField.Value;
            end
        end
    
        function calFcnMap = get.stimulusCalibrationCurve(app)
            %STIMULUSCALIBRATIONCURVE returns map with wavelengths as keys and
            %functions as values. Function will have the form @(x,t,OD) where t is
            %the stimulus duration and OD is optical density. Here, collecting are
            %and lambda attenuation are already factored in.
            if ~app.hasLedCalibration, return; end
            lambdas = unique(app.ledCalibration.Wavelength);
            calFcnMap = containers.Map(lambdas,repmat({@(x)x},numel(lambdas),1));
            absSpect = app.absorptionTemplate;
            cArea = app.collectingArea;
            for lm = lambdas'
                lmFact  = absSpect(lm);
                X = app.ledCalibration.(app.StimCalIntensityDropDown.Value)(app.ledCalibration.Wavelength == lm);
                % we expect that y is photons s^-1 um^-2
                y = app.ledCalibration.(app.StimCalConversionDropDown.Value)(app.ledCalibration.Wavelength == lm);
                b1 = X\y; %so b1x+0 = y
                lmFcnStr = sprintf('@(x,t,OD)x.*(%.4f*%.3f*%.4f).*t.*10^(-3).*10.^(-OD)',b1,cArea,lmFact(2));
                calFcnMap(lm) = str2func(lmFcnStr);
            end
        end
    
        function idList = get.groupIds(app)
            selectedInds = find(ismember(app.GroupBySelect.ItemsData,app.GroupBySelect.Value));
            idList = app.GroupBySelect.Items(selectedInds); %#ok
        end
    
    
        function tmp = get.absorptionTemplate(app)
            import iris.modules.*;
            paramTable = app.SpectralTemplateTable.Data;
            lmax = str2num(paramTable{1,2}); %#ok hard coded for now?
            templateFx = ResponseFamilies.templateFactory(lmax);
            % generates a template function that accepts stimulus wavelengths sL
            tmp = @(sL) templateFx(sL);
        end
    
        function params = get.baselineParams(app)
            
            if app.BaselineCheckBox.Value
                if contains(app.BaselineTypeDropDown.Value,'Fit')
                    regionString = char(regexp(app.BaselineTypeDropDown.Value, '(?<=\()\w+(?=\))','match','once'));
                else
                    regionString = app.BaselineTypeDropDown.Value;
                end
            else
                regionString = 'None';
            end
            params = { ...
                'baselineRegion', regionString, ...
                'numBaselinePoints', app.PtsEditField.Value, ...
                'baselineOffsetPoints', app.OffsetEditField.Value ...
                };
        end
    
        function params = get.scaleParams(app)
            params = { ...
                'scaleFactor', app.ScaleEditField.Value ...
                };
        end
    
        function params = get.aggParams(app)
            params = { ...
                'groupBy', app.groupIds, ...
                'devices', app.selectedDevice, ...
                'statistic', str2func(app.StatisticsFunctionString.Value) ...
                };
        end
    
        function params = get.filterParams(app)
            params = { ...
                'type', lower(app.FilterTypeDropDown.Value), ...
                'frequencies', str2num(app.FilterFrequencies.Value), ... 
                'order', app.FilterOrder.Value, ...
                'devices', app.selectedDevice ...
            };%#ok
        end
    
        function str = get.yUnitsData(app)
            str = '';
            if ~app.hasData, return; end
            units = uniqueContents(app.data.Data.units);
            if ~isscalar(units)
                str = ['(',strjoin({units.y},' | '),')'];
            else
                str = ['(',units.y,')'];
            end
        end

        function str = get.xUnitsData(app)
            str = '';
            if ~app.hasData, return; end
            units = uniqueContents(app.data.Data.units);
            if ~isscalar(units)
                str = ['(',strjoin({units.x},' | '),')'];
            else
                str = ['(',units.x,')'];
            end
        end
    
        function str = get.yUnitsFit(app)
            str = '(norm)';
        end
    
        function str = get.xUnitsFit(app)
            switch app.CellTypeDropDown.Value
                case 'Rod'
                    str= '(R^*rod^{-1})';
                case 'Cone'
                    str = '(P^*cone^{-1})';
            end
        end
    end
    
    methods (Access = private)
        
        function parse(app)
            % parse the loaded data file
            specs = app.data.Specs;
            % set aggregation properties/options
            app.GroupBySelect.Items = ['none',specs.Table.Properties.VariableNames];
            app.GroupBySelect.ItemsData = ['none',specs.Table.Properties.VariableNames];
            
            app.DeviceDropDown.Items = app.data.AvailableDevices;
            
            app.StimPropDropDown.Items = specs.Table.Properties.VariableNames;
            hasLed1 = ismember(lower(specs.Table.Properties.VariableNames),'led1');
            if any(hasLed1)
                app.StimPropDropDown.Value = specs.Table.Properties.VariableNames{hasLed1};
            end
            
            app.BGPropDropDown.Items = specs.Table.Properties.VariableNames;
            hasLed2 = ismember(lower(specs.Table.Properties.VariableNames),'led2');
            if any(hasLed2)
                app.BGPropDropDown.Value = specs.Table.Properties.VariableNames(hasLed2);
            end
            
            % parameterize the default equation
            app.parameterizeFitEqn();
            
            % fill in the items for other prop selectors
            
            app.FitStimulusTimeIdDropDown.Items = specs.Table.Properties.VariableNames;
            app.FitStimulusAmplitudeDropDown.Items = specs.Table.Properties.VariableNames;
            app.StimulusNDPropertyDropDown.Items = ['none',specs.Table.Properties.VariableNames];
            app.BGNDPropertyDropDown.Items = ['none',specs.Table.Properties.VariableNames];
            
            app.requiresFitUpdate = true;
            
            % update the file name
            [~,app.CurrentFileNameMenu.Text] = fileparts(app.data.Files(1));
            app.CurrentFileNameMenu.Visible = 'on';
            
            % plot the data
            app.plotData();
        end
        
        function parameterizeFitEqn(app)
            eqStr = app.FitEquation.Value;
            % find parameters:
            paramList = unique(regexp(regexprep(eqStr,'[()]',''),'b\d+','match'));
            % populate the parameter table
            paramTable = array2table(strings(numel(paramList),3),'VariableNames',["Param","Name","Estimate"]);
            paramTable.Param = paramList(:);
            app.EstimateTable.Data = paramTable;
            % populate the fit results table
            calcTable = table(paramTable.Param, nan(numel(paramList),1,"double"),'variablenames',["Param","Result"]);
            app.ParameterCalculation.Data = calcTable;
        end
    
        function plotData(app)
            % don't plot if not visible
            selectedTab = app.DisplayTabs.SelectedTab;
            if ~isequal(selectedTab,app.ViewData), return; end
            delete(app.DataAxes.Children);
            pltD = app.data;
            if app.FilterCheckBox.Value
                pltD = pltD.Filter(app.filterParams{:});
            end
            % plot the data before aggregation
            plot(pltD,'axes',app.DataAxes,'colorized',false,'opacity',0.2,app.baselineParams{:},app.scaleParams{:},'interactive',false,'axesLabels',false);
            % plot the aggregates
            aggs = pltD.Aggregate( ...
                app.aggParams{:}, ...
                app.baselineParams{:}, ...
                app.scaleParams{:}, ...
                'noFitWarning', true ...
                ); 
            plot(aggs,'axes',app.DataAxes,'colorized',true,'opacity',0.95,'interactive',false,'axesLabels',false);
            
            % update axes labels
            xString = app.DataXString.Value;
            yString = app.DataYString.Value;
            
            app.DataAxes.XLabel.String = [xString,' ',app.xUnitsData];
            app.DataAxes.YLabel.String = [yString,' ',app.yUnitsData];
            
            % set the value for the rebinning spinner
            app.RebinSpinner.Value = aggs.nDatums;
        end
    
        function tryPlotFits(app)
            
            % don't plot if not visible
            selectedTab = app.DisplayTabs.SelectedTab;
            if isequal(selectedTab,app.ViewData), return; end
            if ~app.hasIRData, return; end
            if app.requiresFitUpdate, return; end
            delete(app.IRAxes.Children);
            % plot from the IRData
            [x,sIdx] = sort(app.intensityResponseData.Intensities); 
            y = app.intensityResponseData.Responses.Normalized(sIdx,:);
            flX = x([1:end,end:-1:1]);
            flY = [y(:,2);y(end:-1:1,3)];
            hf = fill( ...
                app.IRAxes, ...
                flX, ...
                flY, ...
                [50,151,219]./255, ...
                'facealpha',0.25, ...
                'EdgeAlpha',0 ...
                );
            
            % plot the fit
            fxn = str2func(app.intensityResponseData.FitEquation);
            b = app.intensityResponseData.FitParameters.Estimate;
            xDom = quantile(x,[0,1]);
            intX = linspace(xDom(1),xDom(2),200);
            intY = fxn(b,intX);
            fl = line( ...
                app.IRAxes, ...
                'XData',intX, ...
                'YData',intY, ...
                'linewidth',3, ...
                'linestyle', '-', ...
                'color',[232,76,61]./255 ...
                );
            % plot the markers on top
            hl = line( ...
                app.IRAxes, ...
                'xdata',x, ...
                'ydata',y(:,1), ...
                'linestyle','none', ...
                'marker','.', ...
                'markersize',25, ...
                'markerfacecolor', [6,6,6]./255, ...
                'markeredgecolor', [6,6,6]./255 ...
                );
            % update axes labels
            xString = app.FitXString.Value;
            yString = app.FitYString.Value;
            
            app.IRAxes.XLabel.String = [xString,' ',app.xUnitsFit];
            app.IRAxes.YLabel.String = [yString,' ',app.yUnitsFit];
            % colors for later
            %carmine pink: (232,76,61)
            %tufts blue: (50,151,219)
            %brightgrey: (236,240,241)
            %cola: 56, 49, 39
            %middle green yellow: (167, 194, 98)
            %floral white: (251, 250, 241)
            %Rajah: (248, 185, 96)
            %Carmine: (161, 4, 18)
        end
    
        function computeIntensityResponse(app)
            % init
            import iris.modules.*;
            cal = app.stimulusCalibrationCurve;
            odArray = app.totalOD;
            wv = app.wavelength;
            
            % get the data matrix
            dataMatrix = app.data.Filter( ...
                    app.filterParams{:} ...
                ).Baseline( ...
                    app.baselineParams{:} ...
                ).getDataMatrix( ...
                    'device' ,app.selectedDevice ...
                );
            specs = app.data.Specs;
            % calculate template for dots
            % excludes excluded epochs
            dotTemplate = nanmean(dataMatrix.y{1},2);
            dotTemplate(isnan(dotTemplate)) = 0;
            % compute dot products for all epochs
            dotFx = @(x)x.*dotTemplate';
            dotsObj= app.data.Filter( ...
                    app.filterParams{:} ...
                ).Aggregate( ...
                    'groupby','epochStartTime', ...
                    app.baselineParams{:}, ...
                    'statistic',dotFx, ...
                    'noFitWarning', true ...
                );
            
            % get grouping information for groupBy
            groups = dotsObj.determineGroups(specs.Table(:,app.groupIds),dotsObj.InclusionList);
            
            groupingTable = specs.Table(dotsObj.InclusionList,:);
            
            ledNames = app.lightDeviceNames;
            
            % compute averaged dot products (with 95% ci)
            dotsMat = dotsObj.getDataMatrix('device',app.selectedDevice);
            dots = abs(sum(dotsMat.y{1},1,'omitnan'));
            
            groupsDots = zeros(height(groups.Table),3); % mean, 2.5, 97.5
            groupsIntensities = zeros(height(groups.Table),1);
            groupsBackgrounds = zeros(height(groups.Table),1);
            for g = 1:height(groups.Table)
                thisG = groups.Table.SingularMap(g);
                thisGroupIdx = groups.Singular == thisG;
                thisGroupLocs = find(thisGroupIdx);
                thisN = sum(thisGroupIdx);
                thisDotBar = mean(dots(thisGroupIdx));
                thisDotCI = ResponseFamilies.bootstrapCI(dots(thisGroupIdx));
                groupsDots(g,1) = thisDotBar;
                groupsDots(g,2:3) = thisDotCI;
                % get time and amplitudes
                thisGTime = str2double(groupingTable.(app.timeID)(thisGroupIdx));
                thisGAmps = str2double(groupingTable.(app.amplitudeID)(thisGroupIdx));
                thisLightName = ledNames(thisGroupIdx,:);
                thisGLam = wv(thisGroupIdx,:);
                thisGOD = odArray(thisGroupIdx,:);
                intensities = zeros(thisN,1); % e.g. R*/rod
                backgrounds = zeros(thisN,1); %e.g. R* rod^-1 s^-1
                for n = 1:thisN
                    thisCal = cal(thisGLam(n,1));
                    intensities(n) = thisCal(thisGAmps(n),thisGTime(n),thisGOD(n,1));
                    try
                        thisBGCal = cal(thisGLam(n,2));
                        % create the backgorund name
                        thisBGName = matlab.lang.makeValidName(sprintf('device_%s_value',thisLightName(n,2)));
                        bgVal = str2double(groupingTable.(thisBGName)(thisGroupLocs(n)));
                        backgrounds(n) = thisBGCal(bgVal,1e3,thisGOD(n,2));
                    catch
                        fprintf(2,'No Background propterty available. Use Iris v2.0.30+.\n');
                    end
                end
                groupsIntensities(g) = mean(intensities);
                groupsBackgrounds(g) = mean(backgrounds);
            end
            normDots = groupsDots/max(groupsDots(:,1));
            % calculate fit:
            b0 = app.fitEstimates.Estimate;
            fitcoefs = lsqcurvefit( ...
                app.fitEqn, ...
                b0, ...
                groupsIntensities, ...
                normDots(:,1), ...
                [],[], ... %leave lower/upper bounds empty
                optimoptions('lsqcurvefit','Display', 'off') ...
                );
            
            calTable = app.ParameterCalculation.Data;
            calTable.Result = fitcoefs(:);
            app.ParameterCalculation.Data = calTable;
            parTable = app.EstimateTable.Data;
            parTable.Estimate = fitcoefs(:);
            Sir = struct();
            Sir.FitParameters = parTable;
            Sir.Intensities = groupsIntensities;
            Sir.Responses = struct();
            Sir.Responses.Dots = groupsDots;
            Sir.Responses.Normalized = normDots;
            Sir.FitEquation = char(app.fitEqn);
            Sir.BackgroundIntensities = groupsBackgrounds;
            app.intensityResponseData = Sir;
            % allow plotting
            app.requiresFitUpdate = false;
            app.tryPlotFits();
        end
    
        function map = createWavelengthMapFromTextArea(app,src)
            % nd table relationships
            measureVar = app.NdCalConversionDropDown.Value;
            idVar = app.NdCalIdentityDropDown.Value;
            calWavelengths = unique(app.ndCalibration.Wavelength);
            
            map = containers.Map(calWavelengths,zeros(numel(calWavelengths),1));
            % stimulus
            value = src.Value;
            splitValues = cellfun(@(elem)strtrim(strsplit(elem,'=>')),value,'UniformOutput',false);
            for i = 1:numel(splitValues)
                thisEntry = splitValues{i};
                thisID = thisEntry{1};
                % id locations
                idTest = ismember(app.ndCalibration.(idVar), thisID);
                % lambda locations
                if numel(thisEntry) > 1
                    thisLambda = thisEntry{2}; % char array
                    lambdas = str2double(strtrim(strsplit(thisLambda,',')));
                else
                    lambdas = calWavelengths;
                end
                lambdaTest = ismember(app.ndCalibration.Wavelength,lambdas);
                % subset the table
                thisTable = app.ndCalibration(all([idTest(:),lambdaTest(:)],2),:);
                for h = 1:height(thisTable)
                    map(thisTable.Wavelength(h)) = map(thisTable.Wavelength(h)) + thisTable.(measureVar)(h);
                end
            end
        end
    
    end
    
    methods (Static)
        
        function tFx = templateFactory(maxLambda)
            % generate a function handle to allow a wavelength array to be entered in
            % order to produce the absorption fraction of a a given Lambda_max. 
            tFx = @(WL) templateFunction(WL,maxLambda);
            
            function template = templateFunction(L,lmax)
                A = 69.7;
                B = 28;
                b = 0.922;
                C = -14.9;
                c = 1.104;
                D = 0.674;
                
                OD = 1; % scale of max peak
                
                y = zeros(length(L),length(lmax));
                
                for i = 1:length(lmax)
                    lambdamax = lmax(i);
                    
                    x = lambdamax./L;
                    a = 0.8795+0.0459.*exp(-((lambdamax-300).^2)./11940);
                    
                    A_expr = exp(A.*(a-x));
                    B_expr = exp(B.*(b-x));
                    C_expr = exp(C.*(c-x));
                    
                    Salpha = OD./(A_expr+B_expr+C_expr+D);
                    
                    bbeta = -40.5+0.195.*lambdamax;
                    Lmbeta = 189+0.315.*lambdamax;
                    Abeta = 0.26.*OD;
                    
                    Sbeta = Abeta.*exp(-((L-Lmbeta)./bbeta).^2);
                    
                    y(:,i) = Salpha(:) + Sbeta(:);
                end
                resultMax = max(y,[],2);
                template = [L(:),resultMax(:)];
            end
        end
    
        function ci = bootstrapCI(data,stat,level,B)
            if nargin < 4, B=10000; end
            if nargin < 3, level = 0.95; end
            if nargin < 2, stat = 'mean'; end
            N = length(data);
            samps = data(randi(N,N,B));
            if ~isa(stat,'function_handle')
                stat = str2func(stat);
            end
            boots = stat(samps);
            probs = sort((1-level)/2 + [level,0]);
            ci = quantile(boots,probs);
        end
    
        function h = notifyUser(inputText,name)
            if nargin < 2, name = 'Notification'; end
            s0 = get(groot,'MonitorPositions');
            s0 = s0(s0(:,1) == 1,3:4);
            % start with a big figure and resize based on text
            dims = [800,800];
            dPos = [(s0-dims)./2,dims];
            % create the figure
            h = figure( ...
                'units','pixels', ...
                'position',dPos, ...
                'Visible','off', ...
                'Color', [1,1,1], ...
                'NumberTitle', 'off', ...
                'MenuBar', 'none', ...
                'Toolbar', 'none', ...
                'DockControls', 'off', ...
                'Name', name, ...
                'Interruptible', 'off', ...
                'CloseRequestFcn', @(s,e)fprintf('Please wait.\n'), ...
                'windowstyle','modal' ...
                );
            ax = axes(h, ...
                'Units','pixels', ...
                'Position',[0,0,dims], ...
                'Visible','off' ...
                );
            tx = text(ax, ...
                0.5,0.5, ...
                inputText, ...
                'FontName','Times New Roman', ...
                'FontWeight',"bold", ...
                'HorizontalAlignment',"center",...
                "VerticalAlignment","middle", ...
                "FontSize",16 ...
                );
            
            %determine the size of the text box
            tx.Units = 'pixels';
            pause(0.001);
            exPx = tx.Extent(3:4);
            % create new dims
            dims = round(exPx,0) + [180,60];
            dPos = [(s0-dims)./2,dims];
            %reposition the figure
            h.Position = dPos;
            % reposition the text
            txPos = [dims./2,0];
            tx.Position = txPos;
            % make the figure visible
            h.Visible = "on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Data)
            if nargin < 2, Data = []; end
            % move the figure to the correct location
            s0 = get(groot,'MonitorPositions');
            s0 = s0(s0(:,1) == 1,3:4);
            dims = [1002,501];
            drawnow();
            pause(0.05);
            app.container.Position(1:2) = (s0-dims)./2;
                        
            % set some tags for elements that appdesigner does not allow setting in
            % the designer
            app.DataXString.Tag = 'Data:X';
            app.DataYString.Tag = 'Data:Y';
            app.FitXString.Tag = 'Fit:X';
            app.FitYString.Tag = 'Fit:Y';
            app.FilterFrequencies.Tag = 'Frequencies';
            
            % Set the spectral table to rhodopsin
            app.SpectralTemplateDropDown.Value = 'Rhodopsin';
            app.SpectralTemplateTable.Data = {sprintf('max \x03BB'),'498'};
            
            % Set some table features for the Parameters
            app.SpectralTemplateTable.ColumnFormat = {'char','char'};
            
            % Create DataAxes
            app.DataAxes = axes(app.ViewData,'units', 'pixels');
            app.DataAxes.XLabel.String = 'X';
            app.DataAxes.YLabel.String = 'Y';
            app.DataAxes.FontName = 'Times New Roman';
            app.DataAxes.BusyAction = 'cancel';
            app.DataAxes.Interruptible = 'off';
            app.DataAxes.ActivePositionProperty = 'outerposition';
            app.DataAxes.OuterPosition = app.ViewData.InnerPosition;
            app.DataAxes.OuterPosition = app.ViewData.InnerPosition - app.DataAxes.TightInset.*[0.75,0,-1,0.25] + [0,0,app.DataAxes.TightInset(1),0];
            
            % parse the data file for relevant properties and ui elements
            if isempty(Data)
                app.ImportData([]); %import will parse
            else
                if ~isa(Data,'IrisData')
                    Data = load(Data,'-mat');
                    fn = fieldnames(Data);
                    Data = Data.(fn{1});
                end
                app.data = Data;
                app.parse();
                pause(0.01);
            end
            
            % load the web window if mlapptools is present
            loc = which('mlapptools');
            if isempty(loc)
                app.window = [];
                return
            end
            iter = 0;
            ITER_MAX = 10;
            delay = 0.2; %200ms
            while true
                try
                    app.window = mlapptools.getWebWindow(app.container);
                catch
                    if iter > ITER_MAX
                      app.window = [];
                      return
                    end
                    iter = iter+1;
                    pause(delay);
                    continue
                end
                break
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.container.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {501, 501};
                app.GridLayout.ColumnWidth = {'1x'};
                app.DisplayPanel.Layout.Row = 2;
                app.DisplayPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {264, '1x'};
                app.DisplayPanel.Layout.Row = 1;
                app.DisplayPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: StimulusMenu
        function ImportStimulus(app, event)
            [b,a] = uigetfile({'*.mat','MATLAB Table';'*.csv;*.tsv;*.txt;*.xls;*.xslx;','Delimited File'}, 'Select Stimulus Calibration File');
            if all(~a)
                warning('RESPONSEFAMILIES:CALIBRATIONFILENOTVALID','A valid IrisData file is requried.');
                return;
            end
            if app.hasWindow
                app.window.bringToFront();
            else
                try %#ok<TRYNC>
                    figure(app.container);
                end
            end
            fileName = fullfile(a,b);
            [~,~,ext] = fileparts(b);
            switch lower(ext)
                case '.mat'
                    % import as a mat table
                    S = load(fileName,'-mat');
                    fn = fieldnames(S);
                    app.ledCalibration = S.(fn{1});
                otherwise
                    % readtable
                    error('Cannot read Delimited files just yet. Best to save your calibration table as a MATLAB table object.');
            end
            % set the table
            app.StimulusCalibrationTable.Data = app.ledCalibration;
            app.StimulusCalibrationTable.ColumnName = app.ledCalibration.Properties.VariableNames;
            % enable associated props
            app.StimulusCalibrationTable.Enable = 'on';
            app.StimCalIntensityDropDown.Enable = 'on';
            app.StimCalConversionDropDown.Enable = 'on';
            
            app.StimCalIntensityDropDown.Items = app.StimulusCalibrationTable.ColumnName;
            app.StimCalIntensityDropDown.Value = app.StimCalIntensityDropDown.Items(2);
            app.StimCalConversionDropDown.Items = app.StimulusCalibrationTable.ColumnName;
            app.StimCalConversionDropDown.Value = app.StimCalConversionDropDown.Items(3);
            
            app.requiresFitUpdate = true;
        end

        % Menu selected function: NeutralDensityMenu
        function ImportND(app, event)
            [b,a] = uigetfile({'*.mat','MATLAB Table';'*.csv;*.tsv;*.txt;*.xls;*.xslx;','Delimited File'}, 'Select ND Calibration File');
            if all(~a)
                warning('RESPONSEFAMILIES:CALIBRATIONFILENOTVALID','A valid IrisData file is requried.');
                return;
            end
            if app.hasWindow
                app.window.bringToFront();
            else
                try %#ok<TRYNC>
                    figure(app.container);
                end
            end
            fileName = fullfile(a,b);
            [~,~,ext] = fileparts(b);
            switch lower(ext)
                case '.mat'
                    % import as a mat table
                    S = load(fileName,'-mat');
                    fn = fieldnames(S);
                    app.NdCalibrationTable.Data = S.(fn{1});
                    app.NdCalibrationTable.ColumnName = S.(fn{1}).Properties.VariableNames;
                    app.ndCalibration = S.(fn{1});
                otherwise
                    % readtable
                    error('Cannot read Delimited files just yet. Best to save your calibration table as a MATLAB table object.');
            end
            % set the calibration table
            app.NdCalibrationTable.Data = app.ndCalibration;
            app.NdCalibrationTable.ColumnName = app.ndCalibration.Properties.VariableNames;
            % enable the ND options
            app.NdCalibrationTable.Enable = 'on';
            app.NdCalIdentityDropDown.Enable = 'on';
            app.NdCalConversionDropDown.Enable = 'on';
            app.StimulusNDsTextArea.Enable = 'on';
            app.BGNDsTextArea.Enable = 'on';
            app.StimulusNDsTextAreaLabel.Enable = 'on';
            app.BGNDsTextAreaLabel.Enable = 'on';
            % fill in best guesses
            app.NdCalIdentityDropDown.Items = app.NdCalibrationTable.ColumnName;
            app.NdCalIdentityDropDown.Value = app.NdCalIdentityDropDown.Items(2);
            app.NdCalConversionDropDown.Items = app.NdCalibrationTable.ColumnName;
            app.NdCalConversionDropDown.Value = app.NdCalConversionDropDown.Items(1);
            
            app.requiresFitUpdate = true;
        end

        % Menu selected function: LoadDataMenu
        function ImportData(app, event)
            [b,a] = uigetfile({'*.idata','IrisData File'}, 'Select Data File');
            if all(~a)
                warning('RESPONSEFAMILIES:DATAFILENOTVALID','A valid IrisData file is requried.');
                return;
            end
            if app.hasWindow
                app.window.bringToFront();
            else
                try %#ok<TRYNC>
                    figure(app.container);
                end
            end
            theFile = fullfile(a,b);
            % load the data. If the loaded object is not or cannot be parsed to an
            % IrisData class, then this should throw the appropriate error.
            Data = load(theFile,'-mat');
            fn = fieldnames(Data);
            app.data = Data.(fn{1});
            % If that completes, we should be able to parse
            app.parse();
            % Once we finish parsing, let's force a call to import the led
            % calibration table if we don't already have one
            if ~app.hasLedCalibration
                app.ControlTabs.SelectedTab = app.CalibrationTab;
                drawnow();
                pause(0.05);
                app.ImportStimulus([]);
            end
        end

        % Menu selected function: PlotsMenu
        function ExportFigures(app, event)
            if app.requiresFitUpdate
                error('Update the changes made on "Fit" tab, then try again.');
            end
            % gather the data
            pltD = app.data;
            if app.FilterCheckBox.Value
                pltD = pltD.Filter(app.filterParams{:});
            end
            
            % plot the aggregates
            aggs = pltD.Aggregate( ...
                app.aggParams{:}, ...
                app.baselineParams{:}, ...
                app.scaleParams{:}, ...
                'noFitWarning', true ...
                ); 
            
            
            % update axes labels
            xString = app.DataXString.Value;
            yString = app.DataYString.Value;
                        
            % Set up the plot figure
            s0 = get(groot,'MonitorPositions');
            s0 = s0(s0(:,1) == 1,3:4);
            dims = [1200,550];
            
            f = figure( ...
              'Visible', 'off', ...
              'NumberTitle', 'off', ...
              'DockControls', 'off', ...
              'Color', [1 1 1] ...
              );
            
            set(f, ...
              'DefaultUicontrolFontName', 'Times New Roman', ...
              'DefaultAxesColor', [1,1,1], ...
              'DefaultAxesFontName', 'Times New Roman', ...
              'DefaultAxesFontSize', 14,...
              'DefaultTextFontName', 'Times New Roman');
            
            f.Position = [(s0-dims)./2,dims];
            
              % create data axes
            ax1 = subplot(1,2,1);
            % plot the data before aggregation
            plot(pltD,'axes',ax1,'colorized',false,'opacity',0.1,app.baselineParams{:},app.scaleParams{:},'interactive',false,'axesLabels',false);
            % plot the aggregates
            plot(aggs,'axes',ax1,'colorized',false,'opacity',1,'interactive',false,'axesLabels',false);
            
            % update the labels
            ax1.XLabel.String = [xString,' ',app.xUnitsData];
            ax1.YLabel.String = [yString,' ',app.yUnitsData];
            
            % create fit axes
            ax2 = subplot(1,2,2);
            
            % plot from the IRData
            [xx,sIdx] = sort(app.intensityResponseData.Intensities); 
            y = app.intensityResponseData.Responses.Normalized(sIdx,:);
            flX = xx([1:end,end:-1:1]);
            flY = [y(:,2);y(end:-1:1,3)];
            hf = fill( ...
                ax2, ...
                flX, ...
                flY, ...
                [50,151,219]./255, ...
                'facealpha',0.25, ...
                'EdgeAlpha',0 ...
                );
            
            % plot the fit
            fxn = str2func(app.intensityResponseData.FitEquation);
            b = app.intensityResponseData.FitParameters.Estimate;
            xDom = domain(xx);
            yDom = domain(y(:));
            intX = linspace(xDom(1),xDom(2),200);
            intY = fxn(b,intX);
            fl = line( ...
                ax2, ...
                'XData',intX, ...
                'YData',intY, ...
                'linewidth',3, ...
                'linestyle', '-', ...
                'color',[232,76,61]./255 ...
                );
            % plot the markers on top
            hl = line( ...
                ax2, ...
                'xdata',xx, ...
                'ydata',y(:,1), ...
                'linestyle','none', ...
                'marker','.', ...
                'markersize',28, ...
                'markerfacecolor', [6,6,6]./255, ...
                'markeredgecolor', [6,6,6]./255 ...
                );
            % update axes labels
            xString = app.FitXString.Value;
            yString = app.FitYString.Value;
            
            ax2.XLabel.String = [xString,' ',app.xUnitsFit];
            ax2.YLabel.String = [yString,' ',app.yUnitsFit];
            
            ax2.XScale = 'log';
            ax2.YScale = 'log';
            
            % reposition axes
            ax1.Units = 'normalized';
            InSet = ax1.TightInset;
            ax1.ActivePositionProperty = 'Position';
            
            ax1.Position = [...
              InSet(1:2)+[0.015,0.05], ...
              0.925/2*(1-InSet(1)-InSet(3))-0.025, ...
              1-InSet(2)-InSet(4)-0.2 ...
              ];
            
            ax2.Units = 'normalized';
            ax2.ActivePositionProperty = 'position';
            
            ax2.Position = [ ...
              InSet(1:2)+[0.505,0.05], ...
              0.925/2*(1-InSet(1)-InSet(3))-0.025, ...
              1-InSet(2)-InSet(4)-0.2 ...
              ];
            
            ax2.YLim = yDom+[-1,0.98].*0.2;
            ax2.YLim(ax2.YLim<0) = 0;
            
            ax2.XLim = 10.^(log10(xDom)+[-1,1].*0.25); %0.25 log unit expansion
            
            % parameter text
            irTable = app.intensityResponseData.FitParameters;
            %symStr = strjoin(["syms"; irTable.Name; "x"], ' ');
            
            eqStr = app.intensityResponseData.FitEquation;
            eqStr = regexprep(eqStr,'@\(b,x\)', '');
            for en = 1:numel(irTable.Name)
                eqStr = regexprep(eqStr,sprintf('b\\(%d\\)',en),irTable.Name(en));
            end
            % remove .* or ./ or .^
            eqStr = regexprep(eqStr,'\.(?=[\^/\\\*])','');
            %eval(symStr);
            eqSym = str2sym(eqStr);
            
            paramText = arrayfun( ...
                @(nm,es) ...
                    sprintf('%s: %.2f', nm, es), ...
                irTable.Name, ...
                irTable.Estimate, ...
                'uniformoutput', false ...
                );
            paramText = [{['Eqn: $',latex(eqSym),'$']};paramText];
            coefText = text(ax2, ...
              xDom(2), ...
              diff(ax2.YLim)*0.025 + ax2.YLim(1), ...
              paramText, ...
              'FontSize', 16, ...
              'HorizontalAlignment', 'right', ...
              'VerticalAlignment', 'bottom', ...
              'interpreter', 'latex' ...
              );
            
            % Add Title Axes
            tAx = axes(f, 'Units', 'normalized', ...
              'Position', [...
                0.2,...
                sum(ax1.Position([2,4])),...
                0.6,...
                1-sum(ax1.Position([2,4])) ...
                ], ...
              'Visible', 'off');
            
            text(tAx, 0.5, 0.1, ...
              'Intensity Response Families', 'FontSize', 20, 'FontWeight', 'bold');
            tAx.XLim = [0,1.8];
            tAx.YLim = [0,0.2];
            
            f.Visible = 'on';
            
            fprintf('Figure created. Use gcf to modify it or save it in typical fashion.\n');
        end

        % Menu selected function: DataMenu
        function ExportResults(app, event)
            if app.requiresFitUpdate
                error('Update the changes made on "Fit" tab, then try again.');
            end
            % propmt for location and type
            vf = iris.data.validFiles();
            fInfo = vf.getIDFromLabel('Session');
            filterText = [ ...
                { ...
                    strjoin(strcat('*.',fInfo.exts),';'), fInfo.label ...
                }; ...
                { ...
                    '*.idata', 'IrisData File' ...
                } ...
                ];
            [sName,sPath,fltIdx] = uiputfile( ...
                filterText, ...
                'Save Response Family Data', ...
                [app.CurrentFileNameMenu.Text,'_IRData.isf'] ...
                );
            if ~fltIdx
                fprintf('Operation cancelled.\n');
                return;
            end
            import iris.modules.*;
            % bring the ui to the top
            if app.hasWindow
                app.window.bringToFront();
            else
                try %#ok<TRYNC>
                    figure(app.container);
                end
            end
            
            waitBox = ResponseFamilies.notifyUser( ...
                sprintf('Saving %s',filterText{fltIdx,2}), ...
                'Export Results' ...
                );
            pause(0.01);
            cleanup = onCleanup(@()delete(waitBox));
            % fltIDx == 1: 'session', 2: 'iData'
            % Let's export an IrisData object where we add "devices"
            % first, let's setup some values
            % appended values
            addDevices = {'Responses', 'NormalizedResponses'};
            % units struct
            fUnits = struct();
            fUnits.x = strtrim(app.xUnitsFit);
            fUnits.y = strtrim(app.yUnitsFit);
            bgUnits = [regexprep(fUnits.x,'[\(\)]',''),'sec^{-1}'];
            irdata = app.intensityResponseData;
            
            %iterators
            norms = irdata.Responses.Normalized(:,1);
            dots = irdata.Responses.Dots(:,1);
            bgs = irdata.BackgroundIntensities;
            X = irdata.Intensities;
            fitFs = mean(1/diff(X));
            
            % cell params of fit for display properties
            fitCell = [ ...
                [ ...
                    cellstr(strcat('Fit_Parameter_',irdata.FitParameters.Param)); ...
                    cellstr(strcat('Fit_Estimate_',irdata.FitParameters.Param)) ...
                ], ...
                [ ...
                    {irdata.FitParameters.Name{:}}'; ...
                    num2cell(round(irdata.FitParameters.Estimate,4)) ...
                ] ...
                ];
            fitCell(end+1,:) = {'Fit_Equation', irdata.FitEquation};
            
            % copy the input data and filter, if checked in ui
            aData = app.data; % a copy
            if app.FilterCheckBox.Value
                aData = aData.Filter(app.filterParams{:});
            end
            
            % aggregate, scale and baseline (if checked)
            % **Subsets to analyzed device
            aData = aData.Aggregate( ...
                app.aggParams{:}, ...
                app.baselineParams{:}, ...
                app.scaleParams{:}, ...
                'noFitWarning', true ...
                ); 
            
            % get a copy of the data structs:
            d = aData.copyData(true(aData.nDatums,1));% all remaining data
            % loop through data structs and store the fit information
            for i = 1:numel(d)
                this = d(i);
                % append or update the fit information to the display properties
                thisFitCell = [fitCell; {'BackgroundIntensity',bgs(i);'BackgroundUnits',bgUnits}];
                thisdp = this.displayProperties;
                [~,isx] = intersect(thisdp(:,1),thisFitCell(:,1));
                if isempty(isx)
                    this.displayProperties = [thisdp;thisFitCell];
                else
                    this.displayProperties(isx,:) = thisFitCell;
                end
                % we expect to have only 1 entry for all of these next items due to
                % the processing step in Aggregate
                % make a copy of the first device units
                dataUnits = this.units{1};
                dataUnits.y = sprintf('|%s^2|',dataUnits.y);
                dataUnits.x = fUnits.x;
                
                this.devices = [this.devices,addDevices];
                this.nDevices = numel(this.devices);
                this.x = [this.x,X(i),X(i)];
                this.y = [this.y,dots(i),norms(i)];
                % sample rates have to be extended too
                this.sampleRate = [this.sampleRate,fitFs,fitFs];
                % append the new units
                % response units, dot propducts, should be in |dataY^2|
                
                this.units = [this.units,dataUnits,fUnits];
                
                
                % reduce baseline values
                this.baselineValues = {mean(cell2mat([this.baselineValues{:}]),2)};
                % reset index to this index
                this.index = i;
                % reassign
                d(i) = this;
            end
            
            % Create a new IrisData Object to save
            iData = aData.UpdateData(d);
            
            % save the data based on what type of file waas selected
            fn = fullfile(sPath,sName);
            if fltIdx == 1 %session
                iData.saveAsIrisSession(fn);
            else
                fprintf('Saving...');
                try
                    save(fn,'iData', '-mat','-v7.3');
                catch e
                    % catch error so that the session struct can be saved by user.
                    fprintf('Could not save the IrisData object:\n\n');
                    fprintf('  "%s"\n',e.message);
                    return
                end
                fprintf('  Success!\nFile saved at:\n"%s"\n',fn);
            end
            pause(0.05);
        end

        % Button pushed function: DataAxesButton
        function ViewDataAxesProps(app, event)
            if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
                app.DisplayTabs.SelectedTab = app.ViewData;
                drawnow();
            end
            inspect(app.DataAxes);
        end

        % Button pushed function: FitAxesButton
        function ViewIrAxesProps(app, event)
            if ~isequal(app.DisplayTabs.SelectedTab,app.ViewResponseFamily)
                app.DisplayTabs.SelectedTab = app.ViewResponseFamily;
                drawnow();
            end
            inspect(app.IRAxes);
        end

        % Button pushed function: UpdateButton
        function onUpdateFit(app, event)
            if ~app.hasLedCalibration
                error('Cannot compute fit without stimulus calibration.');
            end
            % check that the parameter estimates are not empty
            paramEst = str2num(strjoin([app.EstimateTable.Data{:,3}],',')); %#ok
            if isempty(paramEst)
                error('Estimates must not be empty.');
            end
            
            app.DisplayTabs.SelectedTab = app.ViewResponseFamily;
            
            app.computeIntensityResponse();
            
            app.requiresFitUpdate = false;
            
            app.tryPlotFits();
        end

        % Value changed function: FitEquation
        function validateFitEquation(app, event)
            value = lower(event.Value);
            % Validate the new string and revert if unfit (pun not intended)
            hasX = contains(value,'x');
            hasB = contains(value,'b');
            if ~hasX
                app.FitEquation.Value = event.PreviousValue;
                error('Fit equation must use "x".');
            end
            if ~hasB
                app.FitEquation.Value = event.PreviousValue;
                error('Fit equation must use parameter array "b(#)".');
            end
            % ok, lets check the function itself
            paramList = unique(regexp(regexprep(value,'[()]',''),'b\d*','match'));
            testFx = str2func(['@(x,b)',value]);
            try
                % it only needs to actually process some inputs, outputs may be nan
                testFx(1:3,ones(1,numel(paramList)));
            catch ME
                app.FitEquation.Value = event.PreviousValue;
                rethrow(ME);
            end
            % OK, parameterize
            app.parameterizeFitEqn();
            app.requiresFitUpdate = true;
        end

        % Callback function: DataXString, DataXString, DataYString, 
        % DataYString, FitXString, FitXString, FitYString, 
        % FitYString
        function onUpdateLabel(app, event)
            changingValue = event.Value;
            src = event.Source;
            tag = strsplit(src.Tag,':');
            unitTarget = sprintf('%sUnits%s',lower(tag{2}),tag{1});
            switch tag{1}
                case 'Data'
                    if ~isequal(app.DisplayTabs.SelectedTab,app.ViewData)
                        app.DisplayTabs.SelectedTab = app.ViewData;
                        drawnow();
                    end
                    target = app.DataAxes.([tag{2},'Label']);
                case 'Fit'
                    if ~isequal(app.DisplayTabs.SelectedTab,app.ViewResponseFamily)
                        app.DisplayTabs.SelectedTab = app.ViewResponseFamily;
                        drawnow();
                    end
                    target = app.IRAxes.([tag{2},'Label']);
            end
            target.String = [changingValue,' ',app.(unitTarget)];
        end

        % Value changed function: StatisticDropDown
        function StatisticDropDownValueChanged(app, event)
            value = app.StatisticDropDown.Value;
            switch value
                case 'Mean'
                    app.StatisticsFunctionString.Value = "@(x)mean(x,1,'omitnan')";
                case 'Median'
                    app.StatisticsFunctionString.Value = "@(x)median(x,1,'omitnan')";
                case 'Variance'
                    app.StatisticsFunctionString.Value = "@(x)var(x,0,1,'omitnan')";
                case 'Sum'
                    app.StatisticsFunctionString.Value = "@(x)sum(x,1,'omitnan')";
                case 'Custom'
                    app.StatisticsFunctionString.Value = "@(x)mean(x,1,'omitnan')";
                    app.StatisticsFunctionString.Editable = 'on';
                    return
            end
            app.StatisticsFunctionString.Editable = 'off';
            % call the catchall method
            app.onAggregateModified([]);
        end

        % Value changed function: StatisticsFunctionString
        function validateStatsFcn(app, event)
            value = app.StatisticsFunctionString.Value;
            try
                fxTest = str2func(value);
            catch ME
                app.StatisticsFunctionString.Value = event.PreviousValue;
                rethrow(ME);
            end
            % validate that it works on a matrix
            testMatrix = magic(5);
            try
                testResult = fxTest(testMatrix(1:3,:));
            catch ME
                app.StatisticsFunctionString.Value = event.PreviousValue;
                rethrow(ME);
            end
            % validate that the output is the same number of columns as testMatrix
            if numel(testResult) ~= 5
                app.StatisticsFunctionString.Value = event.PreviousValue;
                error('Output must operate on columns of a matrix.');
            end
            % call the catchall method
            app.plotData();
        end

        % Value changed function: SpectralTemplateDropDown
        function onSpectralTemplateChanged(app, event)
            value = app.SpectralTemplateDropDown.Value;
            d = cell(1,2);
            d{1} = sprintf('max \x03BB');
            switch value
                case 'Rhodopsin'
                    d{2} = '498';
                case 'MS-Opsin'
                    d{2} = '360,508';
                case 'M-Opsin'
                    d{2} = '508';
                case 'S-Opsin'
                    d{2} = '360';
                case 'Custom'
                    dCurrent = app.SpectralTemplateTable.Data{1,2};
                    % if is empty, use rhodopsin
                    if isempty(dCurrent)
                        d{2} = '498'; 
                    else
                        d{2} = dCurrent{1,2};
                    end
            end
            app.SpectralTemplateTable.Data = d;
        end

        % Value changed function: BaselineCheckBox, DeviceDropDown, 
        % FilterCheckBox, GroupBySelect, ScaleEditField
        function onAggregateModified(app, event)
            % validate any changes, if needed, and plot
            app.plotData();            
        end

        % Value changed function: FilterFrequencies, FilterOrder, 
        % FilterTypeDropDown
        function onFilterModified(app, event)
            if strcmp(event.Source.Tag,'Frequencies')
                % validate the frequencies
                freqs = str2num(event.Value);%#ok
                filterMode = app.FilterTypeDropDown.Value;
                if strcmp(filterMode,'BandPass')
                    if length(freqs) ~= 2
                        freqs = [0,100]+freqs;
                    end
                else
                    if length(freqs) ~= 1
                        freqs = max(freqs);
                    end
                end
                app.FilterFrequencies.Value = strjoin(string(num2str(freqs(:))),', ');
            end
            % now prevent redraw if we don't filter anyway
            if ~app.FilterCheckBox.Value, return; end
            app.plotData();
        end

        % Value changed function: BaselineTypeDropDown, 
        % OffsetEditField, PtsEditField
        function onBaselineModified(app, event)
            % if baseline is uncheck, do not do anything but update the modified
            % value
            if ~app.BaselineCheckBox.Value, return; end
            % Otherwise, validate if needed and plot the data
            app.plotData();
        end

        % Value changing function: GroupFilter
        function onFilterGroupBy(app, event)
            newVal = event.Value;
            if endsWith(newVal,','), return; end
            valCells = strsplit(newVal,','); % allow "," to indicate multiple matches
            itDat = app.GroupBySelect.ItemsData;
            inds = false(numel(itDat),numel(valCells));
            for v = 1:numel(valCells)
                changingValue = regexprep(valCells{v},'\W','');
                % Set the Items value of the groupby listbox to those that match.
                % if the changingValue is empty, set the groupby listbox Items to
                % ItemsData
                inds(:,v) = contains(itDat,changingValue);
            end
            inds = any(inds,2);
            newItems = itDat(inds);
            
            app.GroupBySelect.Items = newItems;
            %app.GroupBySelect.Value = newItems(1); % maybe we can check what the last selected item was?            
        end

        % Selection change function: DisplayTabs
        function onGraphTabSelected(app, event)
            selectedTab = app.DisplayTabs.SelectedTab;
            if isequal(selectedTab,app.ViewData)
                app.plotData();
            else
                app.tryPlotFits();
            end
        end

        % Cell edit callback: EstimateTable
        function onParameterEstimateChanged(app, event)
            app.requiresFitUpdate =  true;
        end

        % Value changed function: GetNDValueFromPropertyCheckBox
        function onGetNDFromPropChanged(app, event)
            value = app.GetNDValueFromPropertyCheckBox.Value;
            if value
                % enable property selection for ND, e.g. TotalND
                app.StimulusNDPropertyDropDown.Enable =  'on';
            else
                % disableproperty selection for ND, e.g. TotalND
                app.StimulusNDPropertyDropDown.Enable =  'off';
            end
        end

        % Value changed function: BGNDsTextArea, StimulusNDsTextArea
        function validateInputNdList(app, event)
            value = event.Value;
            % first determine correct syntax was supplied
            splitValues = cellfun(@(elem)strtrim(strsplit(elem,'=>')),value,'UniformOutput',false);
            % validate input strings
            % we expect that splitValues will be of the form:
            % [ { 'NAME', 'WAVELENGTH' }; {...}; ... ]
            ndId = app.NdCalIdentityDropDown.Value;
            dropInds = false(numel(splitValues),1);
            for i = 1:numel(splitValues)
                % validate the input
                thisEntry = splitValues{i};
                thisID = thisEntry(1);
                [tf,thisID] = IrisData.ValidStrings( ...
                    thisID, ...
                    unique(app.ndCalibration.(ndId)) ...
                    );
                
                if ~tf
                    dropInds(i) = true;
                    warning('Could not find "%s" in the calibration table column: "%s".',thisEntry{1},ndId);
                    continue;
                end
                % reassign corrected string
                thisEntry(1) = thisID;
                % we will assume 2 things:
                % 1) if no '=>' is present, then the ND ID is common for all
                %    wavelengths (app.totalND will use it for each wavelenght
                %    stimulus)
                % 2) if '=>' is present, it can be 1 or more wavelengths separated by
                %    commas
                if numel(thisEntry) > 1
                    thisLambda = thisEntry{2}; % char array
                    if isempty(thisLambda)
                        thisEntry(2) = [];
                        splitValues{i} = thisEntry;
                        continue
                    end
                    lambdas = str2double(strtrim(strsplit(thisLambda,',')));
                    lambdaTest = ismember(lambdas,app.ndCalibration.Wavelength);
                    if ~all(lambdaTest)
                        unfoundLambda = strjoin(sprintfc('%d',lambdas(~lambdaTest)),', ');
                        warning('Could not find wavelength(s) (%s) in the calibration table column: "Wavelength".',unfoundLambda);
                        dropInds(i) = true;
                        continue
                    end
                end
                % finally, reassign to the splitValues
                splitValues{i} = thisEntry;
            end
            % drop the ones that can't be found
            splitValues(dropInds) = [];
            correctValue = cellfun(@(elem)strjoin(elem,' => '),splitValues,'UniformOutput',false);
            if isempty(correctValue)
                return
            end
            event.Source.Value = correctValue;
        end

        % Value changed function: RebinCheckBox
        function onRebinToggled(app, event)
            % when IrisData allows customing grouping vectors to be input into
            % aggregate, I'll be able to reaggregate over the bins and then supply a
            % newly binned data with horizontal CI
            % the mechanism should be something like:
            % if this box is checked, plot red dots on the fit axes with a tag
            % when the slider changes or the spinner changes, use:
            % get(ax.children,'tag') to find the tag of the modified bins.
            % to modify the data, 
            % if xaxis is log, then
            %{
            bins = discretize(log10(groupsIntensities),9)
            bId = unique(bins);
            newInts = zeros(length(bId),1);
            newResp = zeros(length(bId),1);
            for i = 1:length(bId)
                bGrp = bId(i);
                newInts(i) = mean(groupsIntensities(bins == bGrp));
                newResp(i) = mean(groupsDots(bins == bGrp,1));
            end
            hold(ax,'on')
            plot(ax,newInts,newResp/max(newResp),'r.','markersize',25);
            %}
            value = app.RebinCheckBox.Value;
            if value
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.container = uifigure('Visible', 'off');
            app.container.Name = 'Response Families';
            app.container.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.ImportMenu = uimenu(app.container);
            app.ImportMenu.Tooltip = {''};
            app.ImportMenu.Text = 'Import';
            app.LoadDataMenu = uimenu(app.ImportMenu);
            app.LoadDataMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportData, true);
            app.LoadDataMenu.Tooltip = {'Import an IrisData object'};
            app.LoadDataMenu.Text = 'Data';
            app.CalibrationMenu = uimenu(app.ImportMenu);
            app.CalibrationMenu.Text = 'Calibration';
            app.StimulusMenu = uimenu(app.CalibrationMenu);
            app.StimulusMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportStimulus, true);
            app.StimulusMenu.Text = 'Stimulus';
            app.NeutralDensityMenu = uimenu(app.CalibrationMenu);
            app.NeutralDensityMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportND, true);
            app.NeutralDensityMenu.Text = 'Neutral Density';
            app.ExportMenu = uimenu(app.container);
            app.ExportMenu.Text = 'Export';
            app.PlotsMenu = uimenu(app.ExportMenu);
            app.PlotsMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportFigures, true);
            app.PlotsMenu.Text = 'Plots';
            app.DataMenu = uimenu(app.ExportMenu);
            app.DataMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportResults, true);
            app.DataMenu.Text = 'Data';
            app.CurrentFileNameMenu = uimenu(app.container);
            app.CurrentFileNameMenu.Visible = 'off';
            app.CurrentFileNameMenu.Enable = 'off';
            app.GridLayout = uigridlayout(app.container);
            app.ControlPanel = uipanel(app.GridLayout);
            app.ControlTabs = uitabgroup(app.ControlPanel);
            app.AggregatesTab = uitab(app.ControlTabs);
            app.AggregatesTab.Title = 'Aggregates';
            app.AggregatesGrids = uigridlayout(app.AggregatesTab);
            app.GroupByLabel = uilabel(app.AggregatesGrids);
            app.GroupByLabel.Text = 'Group By:';
            app.GroupBySelect = uilistbox(app.AggregatesGrids);
            app.GroupBySelect.Items = {'none'};
            app.GroupBySelect.Multiselect = 'on';
            app.GroupBySelect.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
            app.GroupBySelect.Tooltip = {'Select the Grouping parameters'};
            app.GroupBySelect.Value = {'none'};
            app.GroupFilter = uieditfield(app.AggregatesGrids, 'text');
            app.GroupFilter.ValueChangingFcn = createCallbackFcn(app, @onFilterGroupBy, true);
            app.GroupFilter.Tooltip = {'Type to filter aggregate grouping options. Use commas to match multiple.'};
            app.BaselineCheckBox = uicheckbox(app.AggregatesGrids);
            app.BaselineCheckBox.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
            app.BaselineCheckBox.Text = 'Baseline';
            app.BaselineCheckBox.Value = true;
            app.PtsLabel = uilabel(app.AggregatesGrids);
            app.PtsLabel.Text = 'Pts';
            app.PtsEditField = uieditfield(app.AggregatesGrids, 'numeric');
            app.PtsEditField.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
            app.PtsEditField.Value = 1000;
            app.OffsetLabel = uilabel(app.AggregatesGrids);
            app.OffsetLabel.Text = 'Offset:';
            app.OffsetEditField = uieditfield(app.AggregatesGrids, 'numeric');
            app.OffsetEditField.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
            app.BaselineTypeLabel = uilabel(app.AggregatesGrids);
            app.BaselineTypeLabel.Text = 'Baseline Type: ';
            app.BaselineTypeDropDown = uidropdown(app.AggregatesGrids);
            app.BaselineTypeDropDown.Items = {'Start', 'End', 'Fit (Sym)', 'Fit (Asym)'};
            app.BaselineTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @onBaselineModified, true);
            app.BaselineTypeDropDown.Value = 'Start';
            app.FilterCheckBox = uicheckbox(app.AggregatesGrids);
            app.FilterCheckBox.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
            app.FilterCheckBox.Text = 'Filter';
            app.FilterCheckBox.Value = true;
            app.FilterOrderLabel = uilabel(app.AggregatesGrids);
            app.FilterOrderLabel.Text = 'Order';
            app.FilterOrder = uieditfield(app.AggregatesGrids, 'numeric');
            app.FilterOrder.Limits = [1 11];
            app.FilterOrder.RoundFractionalValues = 'on';
            app.FilterOrder.ValueDisplayFormat = '%.0f';
            app.FilterOrder.ValueChangedFcn = createCallbackFcn(app, @onFilterModified, true);
            app.FilterOrder.Value = 4;
            app.FilterTypeDropDownLabel = uilabel(app.AggregatesGrids);
            app.FilterTypeDropDownLabel.Text = 'Filter Type:';
            app.FilterTypeDropDown = uidropdown(app.AggregatesGrids);
            app.FilterTypeDropDown.Items = {'Lowpass', 'Highpass', 'BandPass'};
            app.FilterTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @onFilterModified, true);
            app.FilterTypeDropDown.Value = 'Lowpass';
            app.HzLabel = uilabel(app.AggregatesGrids);
            app.HzLabel.Text = 'Hz';
            app.FilterFrequencies = uieditfield(app.AggregatesGrids, 'text');
            app.FilterFrequencies.ValueChangedFcn = createCallbackFcn(app, @onFilterModified, true);
            app.FilterFrequencies.Value = '30';
            app.StatisticDropDownLabel = uilabel(app.AggregatesGrids);
            app.StatisticDropDownLabel.Text = 'Statistic:';
            app.StatisticDropDown = uidropdown(app.AggregatesGrids);
            app.StatisticDropDown.Items = {'Mean', 'Median', 'Variance', 'Sum', 'Custom'};
            app.StatisticDropDown.ValueChangedFcn = createCallbackFcn(app, @StatisticDropDownValueChanged, true);
            app.StatisticDropDown.Value = 'Mean';
            app.StatisticsFunctionString = uieditfield(app.AggregatesGrids, 'text');
            app.StatisticsFunctionString.ValueChangedFcn = createCallbackFcn(app, @validateStatsFcn, true);
            app.StatisticsFunctionString.Editable = 'off';
            app.StatisticsFunctionString.Tooltip = {'Function string for selected statistic. Must operate on columns of goruped row data.'};
            app.StatisticsFunctionString.Value = '@(x)mean(x,1,''omitnan'')';
            app.ScaleEditFieldLabel = uilabel(app.AggregatesGrids);
            app.ScaleEditFieldLabel.Text = 'Scale:';
            app.ScaleEditField = uieditfield(app.AggregatesGrids, 'numeric');
            app.ScaleEditField.LowerLimitInclusive = 'off';
            app.ScaleEditField.UpperLimitInclusive = 'off';
            app.ScaleEditField.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
            app.ScaleEditField.Tooltip = {'Enter a scalar value to scale the data by (mostly for converting to corrent units).'};
            app.ScaleEditField.Value = 1;
            app.DeviceDropDown = uidropdown(app.AggregatesGrids);
            app.DeviceDropDown.Items = {'Device'};
            app.DeviceDropDown.ValueChangedFcn = createCallbackFcn(app, @onAggregateModified, true);
            app.DeviceDropDown.Tooltip = {'Select the divice you wish to work with.'};
            app.DeviceDropDown.Value = 'Device';
            app.CalibrationTab = uitab(app.ControlTabs);
            app.CalibrationTab.Title = 'Calibration';
            app.CalibrationGrids = uigridlayout(app.CalibrationTab);
            app.StimPropLabel = uilabel(app.CalibrationGrids);
            app.StimPropLabel.Text = 'Stimulus Property: ';
            app.StimPropDropDown = uidropdown(app.CalibrationGrids);
            app.StimPropDropDown.Items = {'Select'};
            app.StimPropDropDown.Value = 'Select';
            app.BackgroundPropertyLabel = uilabel(app.CalibrationGrids);
            app.BackgroundPropertyLabel.Text = 'Background Property: ';
            app.BGPropDropDown = uidropdown(app.CalibrationGrids);
            app.BGPropDropDown.Items = {'Select'};
            app.BGPropDropDown.Value = 'Select';
            app.StimulusCalibrationTable = uitable(app.CalibrationGrids);
            app.StimulusCalibrationTable.ColumnName = {''};
            app.StimulusCalibrationTable.RowName = {};
            app.StimulusCalibrationTable.Tooltip = {'Led Calibration Table'; ''};
            app.StimulusCalibrationTable.Enable = 'off';
            app.StimTableLabel = uilabel(app.CalibrationGrids);
            app.StimTableLabel.Text = 'Stimulus Calibration';
            app.StimCalIntensityDropDown = uidropdown(app.CalibrationGrids);
            app.StimCalIntensityDropDown.Items = {'Intensity'};
            app.StimCalIntensityDropDown.Enable = 'off';
            app.StimCalIntensityDropDown.Tooltip = {'Select the intensity column name. Typically StimVoltage.'};
            app.StimCalIntensityDropDown.Value = 'Intensity';
            app.StimCalConversionDropDown = uidropdown(app.CalibrationGrids);
            app.StimCalConversionDropDown.Items = {'Calibration'};
            app.StimCalConversionDropDown.Enable = 'off';
            app.StimCalConversionDropDown.Tooltip = {'Select the conversion value column'; ' typically PhotonDensity.'};
            app.StimCalConversionDropDown.Value = 'Calibration';
            app.CellTypeLabel = uilabel(app.CalibrationGrids);
            app.CellTypeLabel.Text = 'Cell Type: ';
            app.CellTypeDropDown = uidropdown(app.CalibrationGrids);
            app.CellTypeDropDown.Items = {'Rod', 'Cone'};
            app.CellTypeDropDown.Value = 'Rod';
            app.SpectralTemplateLabel = uilabel(app.CalibrationGrids);
            app.SpectralTemplateLabel.Text = 'Spectral Template: ';
            app.SpectralTemplateDropDown = uidropdown(app.CalibrationGrids);
            app.SpectralTemplateDropDown.Items = {'Rhodopsin', 'MS-Opsin', 'M-Opsin', 'S-Opsin', 'Custom'};
            app.SpectralTemplateDropDown.ValueChangedFcn = createCallbackFcn(app, @onSpectralTemplateChanged, true);
            app.SpectralTemplateDropDown.Value = 'Rhodopsin';
            app.SpectralTemplateTable = uitable(app.CalibrationGrids);
            app.SpectralTemplateTable.ColumnName = {'Parameter'; 'Value'};
            app.SpectralTemplateTable.RowName = {};
            app.NDTab = uitab(app.ControlTabs);
            app.NDTab.Title = 'ND';
            app.NDLayoutGrids = uigridlayout(app.NDTab);
            app.ExtraStimulusODEditFieldLabel = uilabel(app.NDLayoutGrids);
            app.ExtraStimulusODEditFieldLabel.Text = 'Extra Stimulus OD:';
            app.ExtraStimulusODEditField = uieditfield(app.NDLayoutGrids, 'numeric');
            app.ExtraStimulusODEditField.UpperLimitInclusive = 'off';
            app.ExtraStimulusODEditField.Limits = [0 Inf];
            app.NdCalibrationTable = uitable(app.NDLayoutGrids);
            app.NdCalibrationTable.ColumnName = {''};
            app.NdCalibrationTable.RowName = {};
            app.NdTableLabel = uilabel(app.NDLayoutGrids);
            app.NdTableLabel.Text = 'Neutral Density Calibration';
            app.NdCalIdentityDropDown = uidropdown(app.NDLayoutGrids);
            app.NdCalIdentityDropDown.Items = {'ND Identity'};
            app.NdCalIdentityDropDown.Enable = 'off';
            app.NdCalIdentityDropDown.Tooltip = {'Select the ID column.'};
            app.NdCalIdentityDropDown.Value = 'ND Identity';
            app.NdCalConversionDropDown = uidropdown(app.NDLayoutGrids);
            app.NdCalConversionDropDown.Items = {'ND Values'};
            app.NdCalConversionDropDown.Enable = 'off';
            app.NdCalConversionDropDown.Tooltip = {'Select the measured value column.'};
            app.NdCalConversionDropDown.Value = 'ND Values';
            app.GetNDValueFromPropertyCheckBox = uicheckbox(app.NDLayoutGrids);
            app.GetNDValueFromPropertyCheckBox.ValueChangedFcn = createCallbackFcn(app, @onGetNDFromPropChanged, true);
            app.GetNDValueFromPropertyCheckBox.Text = 'Get ND Value From Property';
            app.StimulusNDPropertyDropDownLabel = uilabel(app.NDLayoutGrids);
            app.StimulusNDPropertyDropDownLabel.Enable = 'off';
            app.StimulusNDPropertyDropDownLabel.Text = 'Stimulus ND Property: ';
            app.StimulusNDPropertyDropDown = uidropdown(app.NDLayoutGrids);
            app.StimulusNDPropertyDropDown.Items = {'Select'};
            app.StimulusNDPropertyDropDown.Enable = 'off';
            app.StimulusNDPropertyDropDown.Value = 'Select';
            app.StimulusNDsTextAreaLabel = uilabel(app.NDLayoutGrids);
            app.StimulusNDsTextAreaLabel.Enable = 'off';
            app.StimulusNDsTextAreaLabel.Text = {'Stimulus'; 'NDs:'};
            app.StimulusNDsTextArea = uitextarea(app.NDLayoutGrids);
            app.StimulusNDsTextArea.ValueChangedFcn = createCallbackFcn(app, @validateInputNdList, true);
            app.StimulusNDsTextArea.Enable = 'off';
            app.StimulusNDsTextArea.Tooltip = {'Enter mapping (USE SYNTAX: ID=>WAVELENGTH e.g. BlackND4.0=>505) as found by ND Identity and Wavelength column of ND table for the STIMULUS. '; 'This can be empty and can have repeated values (e.g. stacking two identical NDs).'};
            app.BGNDsTextAreaLabel = uilabel(app.NDLayoutGrids);
            app.BGNDsTextAreaLabel.Enable = 'off';
            app.BGNDsTextAreaLabel.Text = {'BG'; 'NDs:'};
            app.BGNDsTextArea = uitextarea(app.NDLayoutGrids);
            app.BGNDsTextArea.ValueChangedFcn = createCallbackFcn(app, @validateInputNdList, true);
            app.BGNDsTextArea.Enable = 'off';
            app.BGNDsTextArea.Tooltip = {'Enter mapping (USE SYNTAX: ID=>WAVELENGTH e.g. BlackND4.0=>505) as found by ND Identity and Wavelength column of ND table for the BACKGROUND. '; 'This can be empty and can have repeated values (e.g. stacking two identical NDs).'};
            app.BGNDPropertyDropDownLabel = uilabel(app.NDLayoutGrids);
            app.BGNDPropertyDropDownLabel.Enable = 'off';
            app.BGNDPropertyDropDownLabel.Text = 'BG ND Property: ';
            app.BGNDPropertyDropDown = uidropdown(app.NDLayoutGrids);
            app.BGNDPropertyDropDown.Items = {'Select'};
            app.BGNDPropertyDropDown.Enable = 'off';
            app.BGNDPropertyDropDown.Value = 'Select';
            app.ExtraBackgroundODEditFieldLabel = uilabel(app.NDLayoutGrids);
            app.ExtraBackgroundODEditFieldLabel.Text = 'Extra Background OD:';
            app.ExtraBackgroundODEditField = uieditfield(app.NDLayoutGrids, 'numeric');
            app.ExtraBackgroundODEditField.UpperLimitInclusive = 'off';
            app.ExtraBackgroundODEditField.Limits = [0 Inf];
            app.FitTab = uitab(app.ControlTabs);
            app.FitTab.Title = 'Fit';
            app.FitGrids = uigridlayout(app.FitTab);
            app.EquationbxLabel = uilabel(app.FitGrids);
            app.EquationbxLabel.Text = {'Equation:'; '@(b x)'; ''};
            app.FitEquation = uieditfield(app.FitGrids, 'text');
            app.FitEquation.ValueChangedFcn = createCallbackFcn(app, @validateFitEquation, true);
            app.FitEquation.Tooltip = {'Enter the character as a valid MATLAB function.'; 'The equation assumes solving for response Y given the call signature:'; '"@(x b) ..." == f( x | b(1:n) ) '; 'Where [b(1) b(2) ... b(n)] are the fit parameters to be estimated.'};
            app.FitEquation.Value = '1./(1+(b(1)./x).^b(2))';
            app.ParameterEstimatesLabel = uilabel(app.FitGrids);
            app.ParameterEstimatesLabel.Text = 'Parameter Estimates';
            app.EstimateTable = uitable(app.FitGrids);
            app.EstimateTable.ColumnName = {'Param'; 'Name'; 'Estimate'};
            app.EstimateTable.RowName = {};
            app.EstimateTable.ColumnSortable = false;
            app.EstimateTable.ColumnEditable = [false true true];
            app.EstimateTable.CellEditCallback = createCallbackFcn(app, @onParameterEstimateChanged, true);
            app.EstimateTable.Tooltip = {'Enter parameter estimates.'};
            app.FitCoefficientsLabel = uilabel(app.FitGrids);
            app.FitCoefficientsLabel.Text = 'Fit Coefficients';
            app.ParameterCalculation = uitable(app.FitGrids);
            app.ParameterCalculation.ColumnName = {'Param'; 'Result'};
            app.ParameterCalculation.RowName = {};
            app.ParameterCalculation.ColumnSortable = false;
            app.ParameterCalculation.ColumnEditable = false;
            app.ParameterCalculation.Tooltip = {'Enter parameter estimates.'};
            app.UpdateButton = uibutton(app.FitGrids, 'push');
            app.UpdateButton.ButtonPushedFcn = createCallbackFcn(app, @onUpdateFit, true);
            app.UpdateButton.Text = 'Update';
            app.TimeDropDownLabel = uilabel(app.FitGrids);
            app.TimeDropDownLabel.Text = 'Time: ';
            app.FitStimulusTimeIdDropDown = uidropdown(app.FitGrids);
            app.AmplitudeDropDownLabel = uilabel(app.FitGrids);
            app.AmplitudeDropDownLabel.Text = 'Amplitude:';
            app.FitStimulusAmplitudeDropDown = uidropdown(app.FitGrids);
            app.RebinSlider = uislider(app.FitGrids);
            app.RebinSlider.Limits = [2 100];
            app.RebinSlider.MinorTicks = [];
            app.RebinSlider.Enable = 'off';
            app.RebinSlider.Visible = 'off';
            app.RebinSlider.Value = 2;
            app.RebinSpinner = uispinner(app.FitGrids);
            app.RebinSpinner.Limits = [1 Inf];
            app.RebinSpinner.RoundFractionalValues = 'on';
            app.RebinSpinner.ValueDisplayFormat = '%.0f';
            app.RebinSpinner.Editable = 'off';
            app.RebinSpinner.Enable = 'off';
            app.RebinSpinner.Tooltip = {'Number of unique X values.'};
            app.RebinSpinner.Value = 1;
            app.RebinCheckBox = uicheckbox(app.FitGrids);
            app.RebinCheckBox.ValueChangedFcn = createCallbackFcn(app, @onRebinToggled, true);
            app.RebinCheckBox.Enable = 'off';
            app.RebinCheckBox.Visible = 'off';
            app.RebinCheckBox.Tooltip = {'Feature coming soon.'};
            app.RebinCheckBox.Text = '';
            app.DisplayTab = uitab(app.ControlTabs);
            app.DisplayTab.Title = 'Display';
            app.DisplayGrids = uigridlayout(app.DisplayTab);
            app.DataAxesButton = uibutton(app.DisplayGrids, 'push');
            app.DataAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewDataAxesProps, true);
            app.DataAxesButton.Tooltip = {'Click to modify Data Axes visual aspects.'; ''};
            app.DataAxesButton.Text = 'Data Axes';
            app.FitAxesButton = uibutton(app.DisplayGrids, 'push');
            app.FitAxesButton.ButtonPushedFcn = createCallbackFcn(app, @ViewIrAxesProps, true);
            app.FitAxesButton.Tooltip = {'Click to modify Fit Axes properties.'};
            app.FitAxesButton.Text = 'Fit Axes';
            app.DataAxisPanel = uipanel(app.DisplayGrids);
            app.DataAxisPanel.Title = 'Data Axis';
            app.DataAxisGrids = uigridlayout(app.DataAxisPanel);
            app.DataXStringLabel = uilabel(app.DataAxisGrids);
            app.DataXStringLabel.Text = 'X-Label:';
            app.DataXString = uieditfield(app.DataAxisGrids, 'text');
            app.DataXString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.DataXString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.DataXString.Value = 'X';
            app.DataYStringLabel = uilabel(app.DataAxisGrids);
            app.DataYStringLabel.Text = 'Y-Label:';
            app.DataYString = uieditfield(app.DataAxisGrids, 'text');
            app.DataYString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.DataYString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.DataYString.Value = 'Y';
            app.FitAxisPanel = uipanel(app.DisplayGrids);
            app.FitAxisPanel.Title = 'Fit Axis';
            app.FitAxisGrids = uigridlayout(app.FitAxisPanel);
            app.FitXStringLabel = uilabel(app.FitAxisGrids);
            app.FitXStringLabel.Text = 'X-Label:';
            app.FitXString = uieditfield(app.FitAxisGrids, 'text');
            app.FitXString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.FitXString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.FitXString.Value = 'Intensity';
            app.FitYStringLabel = uilabel(app.FitAxisGrids);
            app.FitYStringLabel.Text = 'Y-Label:';
            app.FitYString = uieditfield(app.FitAxisGrids, 'text');
            app.FitYString.ValueChangedFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.FitYString.ValueChangingFcn = createCallbackFcn(app, @onUpdateLabel, true);
            app.FitYString.Value = 'Response';
            app.DisplayPanel = uipanel(app.GridLayout);
            app.DisplayTabs = uitabgroup(app.DisplayPanel);
            app.DisplayTabs.SelectionChangedFcn = createCallbackFcn(app, @onGraphTabSelected, true);
            app.ViewData = uitab(app.DisplayTabs);
            app.ViewData.Title = 'Data';
            app.ViewResponseFamily = uitab(app.DisplayTabs);
            app.ViewResponseFamily.Title = 'Fit';
            app.IRAxes = uiaxes(app.ViewResponseFamily);
            title(app.IRAxes, {'Intensity Response Relationship'; ''})
            xlabel(app.IRAxes, 'Intensity')
            ylabel(app.IRAxes, 'Response')
            app.IRAxes.XScale = 'log';
            app.IRAxes.YScale = 'log';
            % Show the figure after all components are created
            app.container.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ResponseFamilies(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.container)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.container)
        end
    end
end